classdef PanOMICs_app_web_Matlab2023b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure_Main                   matlab.ui.Figure
        GridLayout_Main                 matlab.ui.container.GridLayout
        TabGroup_Main                   matlab.ui.container.TabGroup
        MainTab_PanOMICsApp             matlab.ui.container.Tab
        GridLayout_PanOMICsApp          matlab.ui.container.GridLayout
        Panel_1_Input                   matlab.ui.container.Panel
        GridLayout_Input                matlab.ui.container.GridLayout
        Panel_1_1_Loading_data          matlab.ui.container.Panel
        GridLayout_Panel_1_1_Loading_data  matlab.ui.container.GridLayout
        Button_Load_Genomics_Info       matlab.ui.control.Button
        Button_Example_data_Genomics    matlab.ui.control.Button
        UITable_Load_GenomicsInfo       matlab.ui.control.Table
        Button_See_in_detail_GenomicsInput  matlab.ui.control.Button
        Button_Load_Metabolomics_Info   matlab.ui.control.Button
        Button_Example_data_Metabolomics  matlab.ui.control.Button
        UITable_Load_MetabolomicsInfo   matlab.ui.control.Table
        Button_See_in_detail_MetabolomicsInfo  matlab.ui.control.Button
        Button_Load_Phenotype_Info      matlab.ui.control.Button
        Button_Example_data_Phenotype   matlab.ui.control.Button
        UITable_Load_PhenotypeInfo      matlab.ui.control.Table
        Button_See_in_detail_PhenotypeInfo  matlab.ui.control.Button
        Button_Load_OthersOMICs         matlab.ui.control.Button
        Button_Example_data_OthersOMICs  matlab.ui.control.Button
        UITable_Load_OtherOMICsInfo     matlab.ui.control.Table
        Button_See_in_detail_OtherOMICs  matlab.ui.control.Button
        HelpButton_1_1_Loading_data     matlab.ui.control.Button
        Panel_1_1_Loading_data_preprocessed  matlab.ui.container.Panel
        GridLayout_Panel_1_1_Loading_data_preprocessed  matlab.ui.container.GridLayout
        CheckBox_Automatic_preprocessing  matlab.ui.control.CheckBox
        CheckBox_Uploaded_preprocessed_data  matlab.ui.control.CheckBox
        UITable_Load_GenomicsInfo_preprocessed  matlab.ui.control.Table
        Button_See_in_detail_GenomicsInput_preprocessed  matlab.ui.control.Button
        UITable_Load_MetabolomicsInfo_preprocessed  matlab.ui.control.Table
        Button_See_in_detail_MetabolomicsInfo_preprocessed  matlab.ui.control.Button
        UITable_Load_PhenotypeInfo_preprocessed  matlab.ui.control.Table
        Button_See_in_detail_PhenotypeInfo_preprocessed  matlab.ui.control.Button
        UITable_Load_OtherOMICsInfo_preprocessed  matlab.ui.control.Table
        Button_See_in_detail_OtherOMICs_preprocessed  matlab.ui.control.Button
        HelpButton_1_1_Loading_data_preprocessed  matlab.ui.control.Button
        Label_1_Input                   matlab.ui.control.Label
        Panel_2_Visualization           matlab.ui.container.Panel
        GridLayout_Panel_2_Visualization_of_input_data  matlab.ui.container.GridLayout
        Label_2_Visualization_of_input_data  matlab.ui.control.Label
        ChoosedataButtonGroup           matlab.ui.container.ButtonGroup
        HelpButton_Choose_data          matlab.ui.control.Button
        ButtonGenome                    matlab.ui.control.RadioButton
        ButtonMetabolome                matlab.ui.control.RadioButton
        ButtonPhenotype                 matlab.ui.control.RadioButton
        ButtonOtherOMICs                matlab.ui.control.RadioButton
        GWAS_Button                     matlab.ui.control.Button
        Panel_Insights_into_metabolites  matlab.ui.container.Panel
        GridLayout_Panel_Insights_into_metabolites  matlab.ui.container.GridLayout
        Spinner_Metabolites_index       matlab.ui.control.Spinner
        EditField_Metabolite_name       matlab.ui.control.EditField
        Button_Histogram                matlab.ui.control.Button
        MetaboliteLabel                 matlab.ui.control.Label
        MetabolitesindexLabel           matlab.ui.control.Label
        HelpButton_Insight_into_metabolites  matlab.ui.control.Button
        Button_See_in_detail_Histogram  matlab.ui.control.Button
        UIAxes_Histogram                matlab.ui.control.UIAxes
        Panel_PCA_BoxPlots              matlab.ui.container.Panel
        GridLayout_Panel_2_3_PCA_BoxPlots  matlab.ui.container.GridLayout
        Button_PCA                      matlab.ui.control.Button
        Button_See_in_detail_PCA        matlab.ui.control.Button
        Button_BoxPlots                 matlab.ui.control.Button
        Button_See_in_detail_BoxPlots   matlab.ui.control.Button
        HelpButton_Visualization_PCA_BoxPlots  matlab.ui.control.Button
        UIAxes_Box_plots                matlab.ui.control.UIAxes
        UIAxes_PCA                      matlab.ui.control.UIAxes
        Panel_3_Prediction_approches    matlab.ui.container.Panel
        GridLayout_Panel_3_Prediction_approches  matlab.ui.container.GridLayout
        HelpButton_Panel_3_Prediction_approches  matlab.ui.control.Button
        PolygenicRiskScorePanel         matlab.ui.container.Panel
        GridLayout_Panel_PolygenicRiskScore  matlab.ui.container.GridLayout
        Button_PolygenicRiskScore       matlab.ui.control.Button
        Label_Genotype_Phenotype        matlab.ui.control.Label
        GenomicmetabolomicandpanOMICpredictionPanel  matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        CheckBox_Genome_Metabolome      matlab.ui.control.CheckBox
        CheckBox_Metabolome_Phenotype   matlab.ui.control.CheckBox
        CheckBox_Genome_Phenotype       matlab.ui.control.CheckBox
        CheckBox_Genome_Metabolome_Phenotype  matlab.ui.control.CheckBox
        CheckBox_Genome_Metabolome_OtherOMICs_Phenotype  matlab.ui.control.CheckBox
        CheckBox_OthersOMICs_Phenotype  matlab.ui.control.CheckBox
        Label_3_Prediction_approches    matlab.ui.control.Label
        Panel_4_Prediction_methods      matlab.ui.container.Panel
        GridLayout_Panel_4_Prediction_methods  matlab.ui.container.GridLayout
        LoadingpretrainedmodelsPanel    matlab.ui.container.Panel
        GridLayout_LoadingpretrainedmodelsPanel  matlab.ui.container.GridLayout
        CheckBox_Default                matlab.ui.control.CheckBox
        CheckBox_Upload_own_model       matlab.ui.control.CheckBox
        Upload_pretrained_models        matlab.ui.control.Button
        HelpButton_Loading_pretrained_models  matlab.ui.control.Button
        Label_4_Prediction_methods      matlab.ui.control.Label
        Panel_Linear_approches          matlab.ui.container.Panel
        GridLayout_Panel_Linear_approaches  matlab.ui.container.GridLayout
        CheckBox_LASSO                  matlab.ui.control.CheckBox
        CheckBox_RR                     matlab.ui.control.CheckBox
        CheckBox_ENR                    matlab.ui.control.CheckBox
        CheckBox_GPR                    matlab.ui.control.CheckBox
        CheckBox_SVR                    matlab.ui.control.CheckBox
        CheckBox_PLS                    matlab.ui.control.CheckBox
        HelpButton_Prediction_methods_Linear_approaches  matlab.ui.control.Button
        Button_Linear_approaches_Parameters  matlab.ui.control.Button
        Panel_Nonlinear_approaches      matlab.ui.container.Panel
        GridLayout_Panel_Nonlinear_approaches  matlab.ui.container.GridLayout
        CheckBox_RandomForest           matlab.ui.control.CheckBox
        HelpButton_Prediction_methods_Nonlinear_approaches  matlab.ui.control.Button
        Button_Nonlinear_approaches_Parameters  matlab.ui.control.Button
        Panel_Deep_learning_approaches  matlab.ui.container.Panel
        GridLayout_Panel_Deep_learning_approaches  matlab.ui.container.GridLayout
        CheckBox_fullyconnected_NN      matlab.ui.control.CheckBox
        CheckBox_CNN                    matlab.ui.control.CheckBox
        CheckBox_LSTM                   matlab.ui.control.CheckBox
        HelpButton_Prediction_methods_Deep_learning_approaches  matlab.ui.control.Button
        Button_Deep_learning_approaches_Parameters  matlab.ui.control.Button
        Panel_5_Predictive_modeling     matlab.ui.container.Panel
        GridLayout_Panel_5_Predictive_modeling  matlab.ui.container.GridLayout
        Button_Generate_HTML_reports    matlab.ui.control.Button
        Button_Keep_as_Pretrained_yourself  matlab.ui.control.Button
        CheckBox_Keep_for_further_predictions  matlab.ui.control.CheckBox
        HelpButton_Panel_5_Predictive_modelling  matlab.ui.control.Button
        Panel_Univariate_prediction     matlab.ui.container.Panel
        GridLayout_Panel_Univariate_prediction  matlab.ui.container.GridLayout
        EditField_MSE_univariate_prediction  matlab.ui.control.NumericEditField
        MSEEditFieldLabel               matlab.ui.control.Label
        Button_perform_univariate_prediction  matlab.ui.control.Button
        EditField_cc_univariate_prediction  matlab.ui.control.NumericEditField
        CorrelationcoefEditFieldLabel   matlab.ui.control.Label
        EditField_MAE_univariate_prediction  matlab.ui.control.NumericEditField
        MAEEditFieldLabel               matlab.ui.control.Label
        Label_EditField_names_univ_prediction  matlab.ui.control.Label
        EditField_names_univ_prediction  matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        Spinner_index_univariate_prediction  matlab.ui.control.Spinner
        SamplenumberLabel               matlab.ui.control.Label
        UIAxes_Univariate_prediction    matlab.ui.control.UIAxes
        Panel_Multivariate_prediction   matlab.ui.container.Panel
        GridLayout_Panel_Multivariate_prediction  matlab.ui.container.GridLayout
        Button_perform_multivariate_prediction  matlab.ui.control.Button
        CorrelationcoefEditField_2Label  matlab.ui.control.Label
        EditField_cc_multivariate_prediction  matlab.ui.control.NumericEditField
        EditField_MAE_multivariate_prediction  matlab.ui.control.NumericEditField
        MAEEditField_2Label             matlab.ui.control.Label
        EditField_MSE_multivariate_prediction  matlab.ui.control.NumericEditField
        MSEEditField_2Label             matlab.ui.control.Label
        UIAxes_Multivariate_prediction  matlab.ui.control.UIAxes
        Label_Panel_5_Predictive_modeling  matlab.ui.control.Label
        Title_Mosys_logo_image          matlab.ui.control.Image
        Title_BUT_logo_image            matlab.ui.control.Image
        Title_PanOMICs_app              matlab.ui.control.Label
        RESETButton                     matlab.ui.control.Button
        Subtitle_BUT_logo_image         matlab.ui.control.Image
        Subtitle_FEEC_logo_image        matlab.ui.control.Image
        Subtitle_Vime_logo_image        matlab.ui.control.Image
        Subtitle_Mosys_logo_image       matlab.ui.control.Image
        Subtitle_UniWien_logo_image     matlab.ui.control.Image
        GenomicsInputTab                matlab.ui.container.Tab
        GridLayout_GenomicsInputTab_ZOOM_in  matlab.ui.container.GridLayout
        Panel_Genomics_input            matlab.ui.container.Panel
        GridLayout_Panel_Genomics_input  matlab.ui.container.GridLayout
        HELPButton_Panel_Genomics_input  matlab.ui.control.Button
        Button_Back_to_MAIN_Genomics    matlab.ui.control.Button
        UITable_Genomics_input_ZOOM_in  matlab.ui.control.Table
        MetabolomicsInputTab            matlab.ui.container.Tab
        GridLayout_MetabolomicsInputTab_ZOOM_in  matlab.ui.container.GridLayout
        Panel_Metabolomics_input        matlab.ui.container.Panel
        GridLayout_Panel_Metabolomics_input  matlab.ui.container.GridLayout
        HELPButton_Panel_Metabolomics_input  matlab.ui.control.Button
        Button_Back_to_MAIN_Metabolomics  matlab.ui.control.Button
        UITable_Metabolomics_input_ZOOM_in  matlab.ui.control.Table
        PhenotypeInputTab               matlab.ui.container.Tab
        GridLayout_PhenotypeInputTab_ZOOM_in  matlab.ui.container.GridLayout
        Panel_Phenotype_input           matlab.ui.container.Panel
        GridLayout_Panel_Phenotype_input  matlab.ui.container.GridLayout
        HELPButton_Panel_Phenotype_input  matlab.ui.control.Button
        Button_Back_to_MAIN_Phenotype   matlab.ui.control.Button
        UITable_Phenotype_input_ZOOM_in  matlab.ui.control.Table
        OtherOMICsInputTab              matlab.ui.container.Tab
        GridLayout_OtherOMICsInputTab_ZOOM_in  matlab.ui.container.GridLayout
        Panel_OtherOMICs_input          matlab.ui.container.Panel
        GridLayout_Panel_OtherOMICs_input  matlab.ui.container.GridLayout
        HELPButton_Panel_OtherOMICs_input  matlab.ui.control.Button
        Button_Back_to_MAIN_OtherOMICs  matlab.ui.control.Button
        UITable_OtherOMICs_input_ZOOM_in  matlab.ui.control.Table
        GenomicsInputTab_preprocessed   matlab.ui.container.Tab
        GridLayout_GenomicsInputTab_ZOOM_in_preprocessed  matlab.ui.container.GridLayout
        Panel_Genomics_input_preprocessed  matlab.ui.container.Panel
        GridLayout_Panel_Genomics_input_preprocessed  matlab.ui.container.GridLayout
        HELPButton_Panel_Genomics_input_preprocessed  matlab.ui.control.Button
        Button_Back_to_MAIN_Genomics_preprocessed  matlab.ui.control.Button
        UITable_Genomics_input_ZOOM_in_preprocessed  matlab.ui.control.Table
        MetabolomicsInputTab_preprocessed  matlab.ui.container.Tab
        GridLayout_MetabolomicsInputTab_ZOOM_in_preprocessed  matlab.ui.container.GridLayout
        Panel_Metabolomics_input_preprocessed  matlab.ui.container.Panel
        GridLayout_Panel_Metabolomics_input_preprocessed  matlab.ui.container.GridLayout
        HELPButton_Panel_Metabolomics_input_preprocessed  matlab.ui.control.Button
        Button_Back_to_MAIN_Metabolomics_preprocessed  matlab.ui.control.Button
        UITable_Metabolomics_input_ZOOM_in_preprocessed  matlab.ui.control.Table
        PhenotypeInputTab_preprocessed  matlab.ui.container.Tab
        GridLayout_PhenotypeInputTab_ZOOM_in_preprocessed  matlab.ui.container.GridLayout
        Panel_Phenotype_input_preprocessed  matlab.ui.container.Panel
        GridLayout_Panel_Phenotype_input_preprocessed  matlab.ui.container.GridLayout
        HELPButton_Panel_Phenotype_input_preprocessed  matlab.ui.control.Button
        Button_Back_to_MAIN_Phenotype_preprocessed  matlab.ui.control.Button
        UITable_Phenotype_input_ZOOM_in_preprocessed  matlab.ui.control.Table
        OtherOMICsInputTab_preprocessed  matlab.ui.container.Tab
        GridLayout_OtherOMICsInputTab_ZOOM_in_preprocessed  matlab.ui.container.GridLayout
        Panel_OtherOMICs_input_preprocessed  matlab.ui.container.Panel
        GridLayout_Panel_OtherOMICs_input_preprocessed  matlab.ui.container.GridLayout
        HELPButton_Panel_OtherOMICs_input_preprocessed  matlab.ui.control.Button
        Button_Back_to_MAIN_OtherOMICs_preprocessed  matlab.ui.control.Button
        UITable_OtherOMICs_input_ZOOM_in_preprocessed  matlab.ui.control.Table
        GWASTab                         matlab.ui.container.Tab
        GridLayout_GWAS                 matlab.ui.container.GridLayout
        Panel_GWAS                      matlab.ui.container.Panel
        GridLayout_Panel_GWAS           matlab.ui.container.GridLayout
        Button_Backto_to_MAIN_GWAS      matlab.ui.control.Button
        UITable_Input_SNPsINFO_data     matlab.ui.control.Table
        Button_Example_data_GWAS        matlab.ui.control.Button
        Button_Load_SNPs_data           matlab.ui.control.Button
        Button_Calculate                matlab.ui.control.Button
        UITable_Results_GWAS            matlab.ui.control.Table
        SaveResultsascsvButton          matlab.ui.control.Button
        HELPButton_Panel_GWAS           matlab.ui.control.Button
        UIAxes_GWAS                     matlab.ui.control.UIAxes
        SectionVisualization_HistogramTab  matlab.ui.container.Tab
        GridLayout_HistogramTab         matlab.ui.container.GridLayout
        Panel_HistogramTab              matlab.ui.container.Panel
        GridLayout_Panel_HistogramTab   matlab.ui.container.GridLayout
        HELPButton_Panel_HistogramTab   matlab.ui.control.Button
        Button_Back_to_MAIN_HistogramTab  matlab.ui.control.Button
        UIAxes_HistogramTab             matlab.ui.control.UIAxes
        SectionVisualization_PCATab     matlab.ui.container.Tab
        GridLayout_PCATab               matlab.ui.container.GridLayout
        Panel_PCATab                    matlab.ui.container.Panel
        GridLayout_Panel_PCATab         matlab.ui.container.GridLayout
        Button_Back_to_MAIN_PCATab      matlab.ui.control.Button
        HELPButton_Panel_PCATab         matlab.ui.control.Button
        UIAxes_PCATab                   matlab.ui.control.UIAxes
        SectionVisualization_BoxPlotTab  matlab.ui.container.Tab
        GridLayout_BoxPlotTab           matlab.ui.container.GridLayout
        Panel_BoxPlotTab                matlab.ui.container.Panel
        GridLayout_Panel_BoxPlotTab     matlab.ui.container.GridLayout
        Button_Back_to_MAIN_BoxPlotTab  matlab.ui.control.Button
        HELPButton_Panel_BoxPlotTab     matlab.ui.control.Button
        UIAxes_BoxPlotTab               matlab.ui.control.UIAxes
        PolygenicRiskScoreTab           matlab.ui.container.Tab
        GridLayout_PRS                  matlab.ui.container.GridLayout
        PRSPolygenicRiskScorePanel      matlab.ui.container.Panel
        GridLayout_Panel_PRS            matlab.ui.container.GridLayout
        Button_Load_control_group       matlab.ui.control.Button
        UITable_Input_control_group     matlab.ui.control.Table
        Button_Example_data_control_group  matlab.ui.control.Button
        Button_Load_case_group          matlab.ui.control.Button
        UITable_Input_case_group        matlab.ui.control.Table
        Button_Example_data_case_group  matlab.ui.control.Button
        CalculatePRSButton              matlab.ui.control.Button
        UITable_Results_Control_group   matlab.ui.control.Table
        Button_Downloandresultscontrolgroup  matlab.ui.control.Button
        UITable_Results_Case_group      matlab.ui.control.Table
        Button_Downloadresultscasegroup  matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        Button_Back_to_MAIN_PRS         matlab.ui.control.Button
        UIAxes_PRS                      matlab.ui.control.UIAxes
        Parameters_LinearTab            matlab.ui.container.Tab
        GridLayout_Parameters_Linear    matlab.ui.container.GridLayout
        ParametersLinearPanel           matlab.ui.container.Panel
        GridLayout_Panel_ParametersLinear  matlab.ui.container.GridLayout
        BacktoMAINButton                matlab.ui.control.Button
        PLSPanel                        matlab.ui.container.Panel
        GridLayout_Panel_Parameters_PLS  matlab.ui.container.GridLayout
        Label_4                         matlab.ui.control.Label
        SVRPanel                        matlab.ui.container.Panel
        GridLayout_Panel_Parameters_SVR  matlab.ui.container.GridLayout
        Label_3                         matlab.ui.control.Label
        GPRPanel                        matlab.ui.container.Panel
        GridLayout_Panel_Parameters_GPR  matlab.ui.container.GridLayout
        Label_2                         matlab.ui.control.Label
        ENRPanel                        matlab.ui.container.Panel
        GridLayout_Panel_Parameters_ENR  matlab.ui.container.GridLayout
        CrossvalidationEditField_ENRcv  matlab.ui.control.NumericEditField
        CrossvalidationEditField_3Label  matlab.ui.control.Label
        AlfaEditField_ENRalfa           matlab.ui.control.NumericEditField
        AlfaEditField_3Label            matlab.ui.control.Label
        ResetparametersButton_ENRparameters  matlab.ui.control.Button
        HelpButton_ENRparameters        matlab.ui.control.Button
        RRPanel                         matlab.ui.container.Panel
        GridLayout_Panel_Parameters_RR  matlab.ui.container.GridLayout
        AlfaEditField_RRalfa            matlab.ui.control.NumericEditField
        AlfaEditField_2Label            matlab.ui.control.Label
        CrossvalidationEditField_RRcv   matlab.ui.control.NumericEditField
        CrossvalidationEditField_2Label  matlab.ui.control.Label
        ResetparametersButton_RRparameters  matlab.ui.control.Button
        HelpButton_RRparameters         matlab.ui.control.Button
        LASSOPanel                      matlab.ui.container.Panel
        GridLayout_Panel_Parameters_LASSO  matlab.ui.container.GridLayout
        CrossvalidationEditField_LASSOcv  matlab.ui.control.NumericEditField
        CrossvalidationEditFieldLabel   matlab.ui.control.Label
        AlfaEditField_LASSOalfa         matlab.ui.control.NumericEditField
        AlfaEditFieldLabel              matlab.ui.control.Label
        ResetparametersButton_LASSOparameters  matlab.ui.control.Button
        HelpButton_LASSOparameters      matlab.ui.control.Button
        Parameters_NonlinearTab         matlab.ui.container.Tab
        GridLayout_Parameters_nonLinear  matlab.ui.container.GridLayout
        Parameters_nonLinearPanel       matlab.ui.container.Panel
        GridLayout_Panel_Parameters_nonLinear  matlab.ui.container.GridLayout
        RandomForerstPanel              matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Label_5                         matlab.ui.control.Label
        NumberofdecisiontreesEditField  matlab.ui.control.NumericEditField
        NumberofdecisiontreesLabel      matlab.ui.control.Label
        HelpButton_2                    matlab.ui.control.Button
        BacktoMAINButton_nonLinearParameters  matlab.ui.control.Button
        Parameters_DeepLearnTab         matlab.ui.container.Tab
        GridLayout_Parameters_DeepLearn  matlab.ui.container.GridLayout
        Panel_Parameters_DeepLearn      matlab.ui.container.Panel
        GridLayout_Panel_Parameters_DeepLearn  matlab.ui.container.GridLayout
        FullyconnectedNNPanel           matlab.ui.container.Panel
        GridLayout_Panel_FullyconnctedNN  matlab.ui.container.GridLayout
        EditField_Inputlayer_FullyconnectedNN_1  matlab.ui.control.EditField
        EditField_fullyconnected_FullyconnectedNN_2  matlab.ui.control.NumericEditField
        EditField_relulayer_FullyconnectedNN_3  matlab.ui.control.EditField
        EditField_fullyconnected_FullyconnectedNN_4  matlab.ui.control.NumericEditField
        EditField_relulayer_FullyconnectedNN_5  matlab.ui.control.EditField
        relulayerEditField_2Label       matlab.ui.control.Label
        relulayerEditFieldLabel         matlab.ui.control.Label
        InputlayerEditField_2Label      matlab.ui.control.Label
        fullyconnectedlayernumberofhiddenunitsEditField_6Label  matlab.ui.control.Label
        fullyconnectedlayernumberofhiddenunitsEditField_7Label  matlab.ui.control.Label
        EditField_fullyconnected_FullyconnectedNN_6  matlab.ui.control.NumericEditField
        EditFiefullyconnectedlayernumberofhiddenunitsld3EditFieldLabel  matlab.ui.control.Label
        EditField_fullyconnected_FullyconnectedNN_7  matlab.ui.control.EditField
        fullyconnectedlayernumberofhiddenunitsEditField_4Label  matlab.ui.control.Label
        EditField_Minibatchsize_FullyconnectedNN  matlab.ui.control.NumericEditField
        MinibatchsizeEditFieldLabel     matlab.ui.control.Label
        HelpButton_FullyconnectedNN     matlab.ui.control.Button
        LSTMPanel                       matlab.ui.container.Panel
        GridLayout_Panel_LSTM           matlab.ui.container.GridLayout
        EditField_Inputlayer_LSTM_1     matlab.ui.control.EditField
        InputlayerEditFieldLabel        matlab.ui.control.Label
        EditField_LSTMlayer_LSTM_2      matlab.ui.control.NumericEditField
        LSTMlayernumberofhiddenunitsEditFieldLabel  matlab.ui.control.Label
        EditField_fullyconnected_LSTM_3  matlab.ui.control.NumericEditField
        fullyconnectedlayernumberofhiddenunitsEditFieldLabel  matlab.ui.control.Label
        EditField_dropout_LSTM_4        matlab.ui.control.NumericEditField
        dropoutlayerEditFieldLabel      matlab.ui.control.Label
        EditField_fullyconnected_LSTM_5  matlab.ui.control.EditField
        fullyconnectedlayernumberofhiddenunitsEditField_3Label  matlab.ui.control.Label
        EditField_regressionlayer_LSTM_6  matlab.ui.control.EditField
        regressionlayerEditFieldLabel   matlab.ui.control.Label
        EditField_Minibatchsize_LSTM    matlab.ui.control.NumericEditField
        MinibatchsizeEditField_2Label   matlab.ui.control.Label
        HelpButton_LSTM                 matlab.ui.control.Button
        CNNPanel                        matlab.ui.container.Panel
        GridLayout_Panel_CNN            matlab.ui.container.GridLayout
        EditField_Inputlayer_CNN_1      matlab.ui.control.EditField
        EditField_convolutionlayer_CNN_2  matlab.ui.control.NumericEditField
        EditField_relulayer_CNN_3       matlab.ui.control.EditField
        EditField_convolutionlayer_CNN_4  matlab.ui.control.NumericEditField
        EditField_relulayer_CNN_5       matlab.ui.control.EditField
        EditField_convolutionlayer_CNN_6  matlab.ui.control.NumericEditField
        EditField_relulayer_CNN_7       matlab.ui.control.EditField
        EditField_fullyconnected_CNN_8  matlab.ui.control.NumericEditField
        EditField_fullyconnected_CNN_9  matlab.ui.control.NumericEditField
        fullyconnectedlayernumberofhiddenunitsEditField_9Label  matlab.ui.control.Label
        fullyconnectedlayernumberofhiddenunitsEditField_8Label  matlab.ui.control.Label
        relulayerEditField_5Label       matlab.ui.control.Label
        convolutionlayerwidthofthefiltersLabel_3  matlab.ui.control.Label
        relulayerEditField_4Label       matlab.ui.control.Label
        convolutionlayerwidthofthefiltersLabel_2  matlab.ui.control.Label
        relulayerEditField_3Label       matlab.ui.control.Label
        convolutionlayerwidthofthefiltersLabel  matlab.ui.control.Label
        fullyconnectedlayernumberofhiddenunitsEditField_5Label  matlab.ui.control.Label
        InputlayerEditField_3Label      matlab.ui.control.Label
        EditField_fullyconnected_CNN_10  matlab.ui.control.EditField
        EditField_Minibatchsize_CNN     matlab.ui.control.NumericEditField
        MinibatchsizeEditField_3Label   matlab.ui.control.Label
        HelpButton_CNN                  matlab.ui.control.Button
        BacktoMAINButton_DeepLearn      matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description

        rawFileName;  % Name of file where data is located
        rawFilePath;  % Name of file path
        dataG;        % Genomic data
        dataG_no;     % Genomic data – only number
        dataM;        % Metabolomic data
        dataM_no;     % Metabolomic data – only number
        dataPhe;      % Phenotype data
        dataPhe_no;   % Phenotype data – only number
        dataOther;    % OtherOMics data
        dataOther_no; % Other data – only number

        automatic_preprocessed_value;
        already_preprocessed_value;

        x_input; %input in predictions modelling
        y_output; %output in predictions modelling
        y_output_var;

        i_dotplotSample; i_histplotSample; % Selected sample for visualization

        %Check Box Vars - for setting boxvalues regarding panel 2
        CheckBox_LASSO_Value; CheckBox_RR_Value; CheckBox_ENR_Value; 
        CheckBox_GPR_Value; CheckBox_SVR_Value;
        CheckBox_PLS_Value; CheckBox_RandomForest_Value; 
        CheckBox_LSTM_Value; CheckBox_CNN_Value;
        CheckBox_fullyconnected_NN_Value; 
        
        CheckBox_Sum; % ???

        %Vars - for setting boxvalues regarding panel 2
        CheckBox_Genome_Metabolome_BoxValue;
        CheckBox_Metabolome_Phenotype_BoxValue;
        CheckBox_Genome_Phenotype_BoxValue;
        CheckBox_Genome_Metabolome_Phenotype_BoxValue;
        CheckBox_Genome_Metabolome_OtherOMICs_Phenotype_BoxValue;
        CheckBox_OthersOMICs_Phenotype_BoxValue;

        %Check Box Vars - for setting boxvalues regarding panel 3
        CheckBox_Default_Value;
        CheckBox_Upload_own_model_Value;

        %Vars - for setting boxvalues regarding panel 3 - Loading models
        CheckBox_Upload_own_model_BoxValue;
        CheckBox_Default_BoxValue;

        modelFileName; %load own prediction model
        modelFilePath;
        prediction_model;
        
        %Univariate vars__________________________________________________
        %Selected metabolites for prediction
        i_UniVarSample; UniPred_G; UniPred_M; UniPred_P; UniPred_Others;
        %_________________________________________________________________

        Pred_Val; %Predictied values
        Orig_Val; %Originals values

        %Lasso input parameteres:
        LASSO_alfa = 1; 
        LASSO_cv = 5; %cross-val = 5
        RR_alfa = 0.000001; 
        RR_cv = 5; %cross-val = 5
        ENR_alfa = 0.75; 
        ENR_cv = 5; %cross-val = 5

        %LSTM input parameteres:
        lstmlayer_LSTM_1 = 200;
        fullyconnectedlayer_LSTM_2 = 50;
        dropoutlayer_LSTM_3 = 0.5;
        miniBatchSize_LSTM = 25;
        %CNN input parameteres:
        convolutionlayer_CNN_1 = 3;
        convolutionlayer_CNN_2 = 3;
        convolutionlayer_CNN_3 = 3;
        fullyconnectedlayer_CNN_4 = 250;
        fullyconnectedlayer_CNN_5 = 50;
        miniBatchSize_CNN = 25;
        %fullNN input parameteres:
        fullyconnectedlayer_fullNN_1 = 128;
        fullyconnectedlayer_fullNN_2 = 64;
        fullyconnectedlayer_fullNN_3 = 32;
        miniBatchSize_fullNN = 25;
        %Outputs vars
        OutputMSE; OutputMAE;

        %VAR - waitingbar
        f;

        %_________________________________________________________________
        number_of_decisionsTrees_input = 250;
        max_epoches_LSTM_input;
        max_epoches_CNN_input;
        gradientTreshold_LSTM_input;
        gradientTreshold_CNN_input; 

        % GWAS & PRS
        %_________________________________________________________________
        InfoSNP;
        Geno;        % Genomic data
        Phe;
        control_data;
        case_data;
        control_scores;
        case_scores;
        results;

        % save *.mat______________________________________________________
        univar = 0;
        lasso_univar_mat = [];
        ENR_univar_mat = [];
        RR_univar_mat = [];
        GPR_univar_mat = [];
        SVR_univar_mat = [];
        PLS_univar_mat = [];
        RF_univar_mat = [];
        LSTM_univar_mat = [];
        CNN_univar_mat = [];
        fullNN_univar_mat = [];

        multivar = 0;
        lasso_multivar_mat = [];
        ENR_multivar_mat = [];
        RR_multivar_mat = [];
        GPR_multivar_mat = [];
        SVR_multivar_mat = [];
        PLS_multivar_mat = [];
        RF_multivar_mat = [];
        LSTM_multivar_mat = [];
        CNN_multivar_mat = [];
        fullNN_multivar_mat = [];


        %HTML report______________________________________________________

        generate_HTML_report = 0;
        LASSO_mse = 0; LASSO_mae = 0; LASSO_cc = 0;
        RR_mse    = 0; RR_mae    = 0; RR_cc    = 0;
        ENR_mse   = 0; ENR_mae   = 0; ENR_cc   = 0;
        GPR_mse   = 0; GPR_mae   = 0; GPR_cc   = 0;
        SVR_mse   = 0; SVR_mae   = 0; SVR_cc   = 0;
        PLS_mse   = 0; PLS_mae   = 0; PLS_cc   = 0;
        RF_mse    = 0; RF_mae    = 0; RF_cc    = 0;
        FullNN_mse  = 0; FullNN_mae  = 0; FullNN_cc  = 0;
        CNN_mse   = 0; CNN_mae   = 0; CNN_cc   = 0;
        LSTM_mse  = 0; LSTM_mae  = 0; LSTM_cc  = 0;

        %_________________________________________________________________
    end
    
    methods (Access = private)
        
        function updateTable(app)
            % Get Table UI component data
            app.dataG.Data = app.UITable_Load_GenomicsInfo.DisplayData;            
            app.dataM.Data = app.UITable_Load_MetabolomicsInfo.DisplayData;
            app.dataPhe.Data = app.UITable_Load_PhenotypeInfo.DisplayData;
            app.dataOther.Data = app.UITable_Load_OtherOMICsInfo.DisplayData;
        end


        % Function to show help for a specific section
        function showHelp(app, sectionTitle)

            switch sectionTitle
                case 'Warning'
                    uialert(app.UIFigure_Main, ...
                        'It is not possible to calculate PCA from a single feature.', ...
                        'Warning', ...
                        'Icon','info');
    
                otherwise
                    uialert(app.UIFigure_Main, ...
                        'Help text not found for the selected section.', ...
                        'Error', ...
                        'Icon','error');
            end
        end


        % Function to show help for a specific section
        function showHelp_GWAS(app, sectionTitle)

            switch sectionTitle
                case 'Warning GWAS'
                    uialert(app.UIFigure_Main, ...
                        'Genomic data and Phenotype data must be loaded before running GWAS.', ...
                        'Warning GWAS', ...
                        'Icon','info');
    
                otherwise
                    uialert(app.UIFigure_Main, ...
                        'Genomic data and Phenotype data must be loaded before running GWAS.', ...
                        'Error', ...
                        'Icon','error');
            end
        end

        % Function to show help for a specific section
        function showHelp_PRS(app, sectionTitle)

            switch sectionTitle
                case 'Warning PRS'
                    uialert(app.UIFigure_Main, ...
                        'Genomic data and Phenotype data must be loaded before running Polygenic Risk Score (PRS) section.', ...
                        'Warning PRS', ...
                        'Icon','info');
    
                otherwise
                    uialert(app.UIFigure_Main, ...
                        'Genomic data and Phenotype data must be loaded before running PRS.', ...
                        'Error', ...
                        'Icon','error');
            end
        end

        function updatePCAPlot(app)

            if app.ButtonGenome.Value == 1
            app.dataG_no = table2array(app.dataG(:, 2:end));
            %plot(app.PCAplot, app.dataG_no(:,app.i_dotplotSample),'o');
            [~, score, ~, ~, ~, ~] = pca(app.dataG_no);
            scatter(app.UIAxes_PCA,score(:,1), score(:,2));

            elseif  app.ButtonMetabolome.Value == 1
            app.dataM_no = table2array(app.dataM(:, 2:end));
            [~, score, ~, ~, ~, ~] = pca(cell2mat(app.dataM_no));
            scatter(app.UIAxes_PCA,score(:,1), score(:,2));

            elseif  app.ButtonPhenotype.Value == 1
            app.dataPhe_no = table2array(app.dataPhe(:, 2:end));

                if any(size(app.dataPhe_no) == 1)
                    app.showHelp('Warning');
                else
                    [~, score, ~, ~, ~, ~] = pca(cell2mat(app.dataPhe_no));
                    scatter(app.UIAxes_PCA,score(:,1), score(:,2));
                end

            elseif  app.ButtonOtherOMICs.Value == 1
            app.dataOther_no = table2array(app.dataOther(:, 2:end));
                if any(size(app.dataOther_no) == 1)
                    app.showHelp('Warning');
                else
                    [~, score, ~, ~, ~, ~] = pca(cell2mat(app.dataOther_no));
                    scatter(app.UIAxes_PCA,score(:,1), score(:,2));
                end 
            end
        end

        function updateBoxPlot(app)

            if app.ButtonGenome.Value == 1
            app.dataG_no = table2array(app.dataG(:, 2:end));
            boxplot(app.UIAxes_Box_plots, cell2mat({app.dataG_no}));
            elseif  app.ButtonMetabolome.Value == 1
            app.dataM_no = table2array(app.dataM(:, 2:end));
            boxplot(app.UIAxes_Box_plots, cell2mat(app.dataM_no)');
            elseif  app.ButtonPhenotype.Value == 1
            app.dataPhe_no = table2array(app.dataPhe(:, 2:end));
                if any(size(app.dataPhe_no) == 1)
                    boxplot(app.UIAxes_Box_plots, [app.dataPhe_no]);
                else
                    boxplot(app.UIAxes_Box_plots, cell2mat(app.dataPhe_no));
                end  

            elseif  app.ButtonOtherOMICs.Value == 1
            app.dataOther_no = table2array(app.dataOther(:, 2:end));

                if any(size(app.dataOther_no) == 1)
                    boxplot(app.UIAxes_Box_plots, [app.dataOther_no]);
                else
                    boxplot(app.UIAxes_Box_plots, cell2mat(app.dataOther_no));
                end          
            end

        end


        function updateHistPlot(app)
            histogram(app.UIAxes_Histogram,cell2mat(app.dataM_no(:,app.i_histplotSample)'));
        end


        function ENR(app)
            % ENR prediction approach:
            X = app.x_input;
            y = app.y_output;

            if isempty(app.ENR_univar_mat) & (app.univar == 1)
                n = length(y);
                c = cvpartition(n,'HoldOut',0.3);
                idxTrain = training(c,1);
                idxTest = ~idxTrain;
                XTrain = X(idxTrain,:);
                yTrain = y(idxTrain);
                XTest = X(idxTest,:);
                yTest = y(idxTest);
                [B,FitInfo] = lasso(XTrain,yTrain,'Alpha',app.ENR_alfa,'CV',app.ENR_cv); %cross-val = 5
                idxLambda1SE = FitInfo.Index1SE;
                coef = B(:,idxLambda1SE);
                coef0 = FitInfo.Intercept(idxLambda1SE);
    
                app.Pred_Val = XTest*coef + coef0;
                app.Orig_Val = yTest;

                app.ENR_univar_mat.B = B;
                app.ENR_univar_mat.FitInfo = FitInfo;

                app.GPR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.ENR_multivar_mat = [];
                app.GPR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            elseif (app.univar == 1)
                n = length(y);
                c = cvpartition(n,'HoldOut',0.3);
                idxTrain = training(c,1);
                idxTest = ~idxTrain;
                XTrain = X(idxTrain,:);
                yTrain = y(idxTrain);
                XTest = X(idxTest,:);
                yTest = y(idxTest);
                
                if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                    B = app.ENR_univar_mat.B;
                    FitInfo = app.ENR_univar_mat.FitInfo;               
                else
                    B = app.ENR_univar_mat.var_results.B;
                    FitInfo = app.ENR_univar_mat.var_results.FitInfo;
                end

                idxLambda1SE = FitInfo.Index1SE;
                coef = B(:,idxLambda1SE);
                coef0 = FitInfo.Intercept(idxLambda1SE);
    
                app.Pred_Val = XTest*coef + coef0;
                app.Orig_Val = yTest;

                app.GPR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.ENR_multivar_mat = [];
                app.GPR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            end

            if isempty(app.ENR_multivar_mat) & (app.multivar == 1)
                n = length(y);
                c = cvpartition(n,'HoldOut',0.3);
                idxTrain = training(c,1);
                idxTest = ~idxTrain;
                XTrain = X(idxTrain,:);
                yTrain = y(idxTrain);
                XTest = X(idxTest,:);
                yTest = y(idxTest);
                [B,FitInfo] = lasso(XTrain,yTrain,'Alpha',app.ENR_alfa,'CV',app.ENR_cv); %cross-val = 5
                idxLambda1SE = FitInfo.Index1SE;
                coef = B(:,idxLambda1SE);
                coef0 = FitInfo.Intercept(idxLambda1SE);
    
                app.Pred_Val = XTest*coef + coef0;
                app.Orig_Val = yTest;

                app.ENR_multivar_mat.B = B;
                app.ENR_multivar_mat.FitInfo = FitInfo;

                app.GPR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.ENR_univar_mat = [];
                app.GPR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            elseif (app.multivar == 1)
                n = length(y);
                c = cvpartition(n,'HoldOut',0.3);
                idxTrain = training(c,1);
                idxTest = ~idxTrain;
                XTrain = X(idxTrain,:);
                yTrain = y(idxTrain);
                XTest = X(idxTest,:);
                yTest = y(idxTest);

                if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                    B = app.ENR_multivar_mat.B;
                    FitInfo = app.ENR_multivar_mat.FitInfo;
                else
                    B = app.ENR_multivar_mat.var_results.B;
                    FitInfo = app.ENR_multivar_mat.var_results.FitInfo;
                end

                idxLambda1SE = FitInfo.Index1SE;
                coef = B(:,idxLambda1SE);
                coef0 = FitInfo.Intercept(idxLambda1SE);
    
                app.Pred_Val = XTest*coef + coef0;
                app.Orig_Val = yTest;

                app.GPR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.ENR_univar_mat = [];
                app.GPR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            end
        end


        function LASSO(app)
            % LASSO prediction approach:
            X = app.x_input;
            y = app.y_output;

            if isempty(app.lasso_univar_mat) & (app.univar == 1)
                n = length(y);
                c = cvpartition(n,'HoldOut',0.3);
                idxTrain = training(c,1);
                idxTest = ~idxTrain;
                XTrain = X(idxTrain,:);
                yTrain = y(idxTrain);
                XTest = X(idxTest,:);
                yTest = y(idxTest);
                [B,FitInfo] = lasso(XTrain,yTrain,'Alpha',app.LASSO_alfa,'CV',app.LASSO_cv); %cross-val = app.LASSO_cv
                idxLambda1SE = FitInfo.Index1SE;
                coef = B(:,idxLambda1SE);
                coef0 = FitInfo.Intercept(idxLambda1SE);
    
                app.Pred_Val = XTest*coef + coef0;
                app.Orig_Val = yTest;

                app.lasso_univar_mat.B = B;
                app.lasso_univar_mat.FitInfo = FitInfo;
    
                app.ENR_univar_mat = []; app.RR_univar_mat = []; app.GPR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.lasso_multivar_mat = [];
                app.ENR_multivar_mat = []; app.RR_multivar_mat = []; app.GPR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            elseif (app.univar == 1)
                n = length(y);
                c = cvpartition(n,'HoldOut',0.3);
                idxTrain = training(c,1);
                idxTest = ~idxTrain;
                XTrain = X(idxTrain,:);
                yTrain = y(idxTrain);
                XTest = X(idxTest,:);
                yTest = y(idxTest);

                if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                    B = app.lasso_univar_mat.B;
                    FitInfo = app.lasso_univar_mat.FitInfo;
                else
                    B = app.lasso_univar_mat.var_results.B;
                    FitInfo = app.lasso_univar_mat.var_results.FitInfo;
                end

                idxLambda1SE = FitInfo.Index1SE;
                coef = B(:,idxLambda1SE);
                coef0 = FitInfo.Intercept(idxLambda1SE);
    
                app.Pred_Val = XTest*coef + coef0;
                app.Orig_Val = yTest;

                app.ENR_univar_mat = []; app.RR_univar_mat = []; app.GPR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.lasso_multivar_mat = [];
                app.ENR_multivar_mat = []; app.RR_multivar_mat = []; app.GPR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            end

            if isempty(app.lasso_multivar_mat) & (app.multivar == 1)
                n = length(y);
                c = cvpartition(n,'HoldOut',0.3);
                idxTrain = training(c,1);
                idxTest = ~idxTrain;
                XTrain = X(idxTrain,:);
                yTrain = y(idxTrain);
                XTest = X(idxTest,:);
                yTest = y(idxTest);
                [B,FitInfo] = lasso(XTrain,yTrain,'Alpha',app.LASSO_alfa,'CV',app.LASSO_cv); %cross-val = app.LASSO_cv
                idxLambda1SE = FitInfo.Index1SE;
                coef = B(:,idxLambda1SE);
                coef0 = FitInfo.Intercept(idxLambda1SE);
    
                app.Pred_Val = XTest*coef + coef0;
                app.Orig_Val = yTest;

                app.lasso_multivar_mat.B = B;
                app.lasso_multivar_mat.FitInfo = FitInfo;

                app.ENR_univar_mat = []; app.RR_univar_mat = []; app.GPR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.lasso_univar_mat = [];
                app.ENR_multivar_mat = []; app.RR_multivar_mat = []; app.GPR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
    
            elseif (app.multivar == 1)
                n = length(y);
                c = cvpartition(n,'HoldOut',0.3);
                idxTrain = training(c,1);
                idxTest = ~idxTrain;
                XTrain = X(idxTrain,:);
                yTrain = y(idxTrain);
                XTest = X(idxTest,:);
                yTest = y(idxTest);

                if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                    B = app.lasso_multivar_mat.B;
                    FitInfo = app.lasso_multivar_mat.FitInfo;
                else
                    B = app.lasso_multivar_mat.var_results.B;
                    FitInfo = app.lasso_multivar_mat.var_results.FitInfo;
                end


                idxLambda1SE = FitInfo.Index1SE;
                coef = B(:,idxLambda1SE);
                coef0 = FitInfo.Intercept(idxLambda1SE);
    
                app.Pred_Val = XTest*coef + coef0;
                app.Orig_Val = yTest;

                app.ENR_univar_mat = []; app.RR_univar_mat = []; app.GPR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.lasso_univar_mat = [];
                app.ENR_multivar_mat = []; app.RR_multivar_mat = []; app.GPR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            end
        end

        function RR(app)
            % RR prediction approach:
            X = app.x_input;
            y = app.y_output;

            if isempty(app.RR_univar_mat) & (app.univar == 1)
                n = length(y);
                c = cvpartition(n,'HoldOut',0.3);
                idxTrain = training(c,1);
                idxTest = ~idxTrain;
                XTrain = X(idxTrain,:);
                yTrain = y(idxTrain);
                XTest = X(idxTest,:);
                yTest = y(idxTest);
                [B,FitInfo] = lasso(XTrain,yTrain,'Alpha',app.RR_alfa,'CV',app.RR_cv); %cross-val = 5
                idxLambda1SE = FitInfo.Index1SE;
                coef = B(:,idxLambda1SE);
                coef0 = FitInfo.Intercept(idxLambda1SE);
    
                app.Pred_Val = XTest*coef + coef0;
                app.Orig_Val = yTest;

                app.RR_univar_mat.B = B;
                app.RR_univar_mat.FitInfo = FitInfo;

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.GPR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.RR_multivar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.GPR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            elseif (app.univar == 1)
                n = length(y);
                c = cvpartition(n,'HoldOut',0.3);
                idxTrain = training(c,1);
                idxTest = ~idxTrain;
                XTrain = X(idxTrain,:);
                yTrain = y(idxTrain);
                XTest = X(idxTest,:);
                yTest = y(idxTest);

                if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                    B = app.RR_univar_mat.B;
                    FitInfo = app.RR_univar_mat.FitInfo;
                else
                    B = app.RR_univar_mat.var_results.B;
                    FitInfo = app.RR_univar_mat.var_results.FitInfo;
                end


                idxLambda1SE = FitInfo.Index1SE;
                coef = B(:,idxLambda1SE);
                coef0 = FitInfo.Intercept(idxLambda1SE);
    
                app.Pred_Val = XTest*coef + coef0;
                app.Orig_Val = yTest;

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.GPR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.RR_multivar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.GPR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            end

            if isempty(app.RR_multivar_mat) & (app.multivar == 1)
                n = length(y);
                c = cvpartition(n,'HoldOut',0.3);
                idxTrain = training(c,1);
                idxTest = ~idxTrain;
                XTrain = X(idxTrain,:);
                yTrain = y(idxTrain);
                XTest = X(idxTest,:);
                yTest = y(idxTest);
                [B,FitInfo] = lasso(XTrain,yTrain,'Alpha',app.RR_alfa,'CV',app.RR_cv); %cross-val = 5
                idxLambda1SE = FitInfo.Index1SE;
                coef = B(:,idxLambda1SE);
                coef0 = FitInfo.Intercept(idxLambda1SE);
    
                app.Pred_Val = XTest*coef + coef0;
                app.Orig_Val = yTest;

                app.RR_multivar_mat.B = B;
                app.RR_multivar_mat.FitInfo = FitInfo;

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.GPR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.RR_univar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.GPR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            elseif (app.multivar == 1)
                n = length(y);
                c = cvpartition(n,'HoldOut',0.3);
                idxTrain = training(c,1);
                idxTest = ~idxTrain;
                XTrain = X(idxTrain,:);
                yTrain = y(idxTrain);
                XTest = X(idxTest,:);
                yTest = y(idxTest);

                if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                    B = app.RR_multivar_mat.B;
                    FitInfo = app.RR_multivar_mat.FitInfo;
                else
                    B = app.RR_multivar_mat.var_results.B;
                    FitInfo = app.RR_multivar_mat.var_results.FitInfo;
                end


                idxLambda1SE = FitInfo.Index1SE;
                coef = B(:,idxLambda1SE);
                coef0 = FitInfo.Intercept(idxLambda1SE);
    
                app.Pred_Val = XTest*coef + coef0;
                app.Orig_Val = yTest;

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.GPR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.RR_univar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.GPR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            end

        end

        function GPR(app)
            %Fit a Gaussian process regression (GPR) model

            %cross-valid = 5 
            ypred11 =[];
            testM1 = [];

            if isempty(app.GPR_univar_mat) & (app.univar == 1)
                for i=1:5
                    if i == 1
                        testG = app.x_input(1: floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(1: floor(size(app.x_input,1)/5)*i,:);
    
                        trainG = app.x_input(floor(size(app.x_input,1)/5)*i +1: end,:);
                        trainM = app.y_output(floor(size(app.x_input,1)/5)*i +1: end,:);
                    else
                        testG = app.x_input(floor(size(app.x_input,1)/5)*(i-1) : floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(floor(size(app.y_output,1)/5)*(i-1) : floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input([1: floor(size(app.x_input,1)/5)*(i-1)-1, floor(size(app.x_input,1)/5)*i:end],:);
                        trainM = app.y_output([1: floor(size(app.y_output,1)/5)*(i-1)-1, floor(size(app.y_output,1)/5)*i:end],:);
                    end

                    X = trainG;
                    Y = trainM;
    
                    if size(Y,2) == 1
                    %Univariate
                        gprMdl1 = fitrgp(X,Y);
                        x = testG;
                        [ypred1,~,~] = predict(gprMdl1,x);
    
                        ypred11 = [ypred11; ypred1];
                        testM1 = [testM1; testM];
                    else
                    %Multivariate
                        gprMdl1 = fitrgp(X,mean(Y,2));
                        x = testG;
                        [ypred1,~,~] = predict(gprMdl1,x);
    
                        ypred11 = [ypred11; ypred1];
                        testM1 = [testM1; mean(testM,2)];
                    end
                end
                app.GPR_univar_mat.gprMdl1 = gprMdl1;
    
                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.GPR_multivar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

            elseif (app.univar == 1)   
                 for i=1:5
                    if i == 1
                        testG = app.x_input(1: floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(1: floor(size(app.x_input,1)/5)*i,:);

                        trainG = app.x_input(floor(size(app.x_input,1)/5)*i +1: end,:);
                        trainM = app.y_output(floor(size(app.x_input,1)/5)*i +1: end,:);
                    else
                        testG = app.x_input(floor(size(app.x_input,1)/5)*(i-1) : floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(floor(size(app.y_output,1)/5)*(i-1) : floor(size(app.y_output,1)/5)*i,:);

                        trainG = app.x_input([1: floor(size(app.x_input,1)/5)*(i-1)-1, floor(size(app.x_input,1)/5)*i:end],:);
                        trainM = app.y_output([1: floor(size(app.y_output,1)/5)*(i-1)-1, floor(size(app.y_output,1)/5)*i:end],:);
                    end

                    X = trainG;
                    Y = trainM;

                    if size(Y,2) == 1                                   
                    %Univariate
                        if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                            gprMdl1 = app.GPR_univar_mat.gprMdl1;
                        else
                            gprMdl1 = app.GPR_univar_mat.var_results.gprMdl1;
                        end
                        
                        x = testG;
                        [ypred1,~,~] = predict(gprMdl1,x);

                        ypred11 = [ypred11; ypred1];
                        testM1 = [testM1; testM];
                    else
                    %Multivariate
                        if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                            gprMdl1 = app.GPR_multivar_mat.gprMdl1;
                        else
                            gprMdl1 = app.GPR_multivar_mat.var_results.gprMdl1;
                        end

                        x = testG;
                        [ypred1,~,~] = predict(gprMdl1,x);

                        ypred11 = [ypred11; ypred1];
                        testM1 = [testM1; mean(testM,2)];
                    end  
                 end

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.GPR_multivar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            end
 
            if isempty(app.GPR_multivar_mat) & (app.multivar == 1)
                for i=1:5
                    if i == 1
                        testG = app.x_input(1: floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(1: floor(size(app.x_input,1)/5)*i,:);
    
                        trainG = app.x_input(floor(size(app.x_input,1)/5)*i +1: end,:);
                        trainM = app.y_output(floor(size(app.x_input,1)/5)*i +1: end,:);
                    else
                        testG = app.x_input(floor(size(app.x_input,1)/5)*(i-1) : floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(floor(size(app.y_output,1)/5)*(i-1) : floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input([1: floor(size(app.x_input,1)/5)*(i-1)-1, floor(size(app.x_input,1)/5)*i:end],:);
                        trainM = app.y_output([1: floor(size(app.y_output,1)/5)*(i-1)-1, floor(size(app.y_output,1)/5)*i:end],:);
                    end

                    X = trainG;
                    Y = trainM;
    
                    if size(Y,2) == 1
                    %Univariate
                        gprMdl1 = fitrgp(X,Y);
                        x = testG;
                        [ypred1,~,~] = predict(gprMdl1,x);
    
                        ypred11 = [ypred11; ypred1];
                        testM1 = [testM1; testM];
                    else
                    %Multivariate
                        gprMdl1 = fitrgp(X,mean(Y,2));
                        x = testG;
                        [ypred1,~,~] = predict(gprMdl1,x);
    
                        ypred11 = [ypred11; ypred1];
                        testM1 = [testM1; mean(testM,2)];
                    end
                end
                app.GPR_multivar_mat.gprMdl1 = gprMdl1;

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.GPR_univar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            elseif (app.multivar == 1)     
                 for i=1:5
                    if i == 1
                        testG = app.x_input(1: floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(1: floor(size(app.x_input,1)/5)*i,:);

                        trainG = app.x_input(floor(size(app.x_input,1)/5)*i +1: end,:);
                        trainM = app.y_output(floor(size(app.x_input,1)/5)*i +1: end,:);
                    else
                        testG = app.x_input(floor(size(app.x_input,1)/5)*(i-1) : floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(floor(size(app.y_output,1)/5)*(i-1) : floor(size(app.y_output,1)/5)*i,:);

                        trainG = app.x_input([1: floor(size(app.x_input,1)/5)*(i-1)-1, floor(size(app.x_input,1)/5)*i:end],:);
                        trainM = app.y_output([1: floor(size(app.y_output,1)/5)*(i-1)-1, floor(size(app.y_output,1)/5)*i:end],:);
                    end

                    X = trainG;
                    Y = trainM;

                    if size(Y,2) == 1                                    
                    %Univariate
                        if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                            gprMdl1 = app.GPR_univar_mat.gprMdl1;
                        else
                            gprMdl1 = app.GPR_univar_mat.var_results.gprMdl1;
                        end
                        x = testG;
                        [ypred1,~,~] = predict(gprMdl1,x);

                        ypred11 = [ypred11; ypred1];
                        testM1 = [testM1; testM];
                    else
                    %Multivariate
                        if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                            gprMdl1 = app.GPR_multivar_mat.gprMdl1;
                        else
                            gprMdl1 = app.GPR_multivar_mat.var_results.gprMdl1;
                        end
                        x = testG;
                        [ypred1,~,~] = predict(gprMdl1,x);

                        ypred11 = [ypred11; ypred1];
                        testM1 = [testM1; mean(testM,2)];
                    end  
                 end

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.GPR_univar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            end

            
            app.Pred_Val = ypred11;
            app.Orig_Val = testM1;

        end

        function SVR(app)
            % SVR
            %cross-valid = 5 
            ypred11 =[];
            testM1 = [];

            if isempty(app.SVR_univar_mat) & (app.univar == 1)
                for i=1:5
                    if i == 1
                        testG = app.x_input(1: floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(1: floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input(floor(size(app.x_input,1)/5)*i +1: end,:);
                        trainM = app.y_output(floor(size(app.x_input,1)/5)*i +1: end,:);
                    else
                        testG = app.x_input(floor(size(app.x_input,1)/5)*(i-1) : floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(floor(size(app.y_output,1)/5)*(i-1) : floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input([1: floor(size(app.x_input,1)/5)*(i-1)-1, floor(size(app.x_input,1)/5)*i:end],:);
                        trainM = app.y_output([1: floor(size(app.y_output,1)/5)*(i-1)-1, floor(size(app.y_output,1)/5)*i:end],:);
                    end
                    X = trainG;
                    Y = trainM;
    
                    if size(Y,2) == 1
                    %Univariate
                        MdlLin = fitrsvm(X,Y);
                        ypred1 = predict(MdlLin,testG);
    
                        ypred11 = [ypred11; ypred1];
                        testM1 = [testM1; testM];
                    else
                    %Multivariate
                        MdlLin = fitrsvm(X,mean(Y,2));
                        ypred1 = predict(MdlLin,testG);
    
                        ypred11 = [ypred11; ypred1];
                        testM1 = [testM1; mean(testM,2)];
                    end
                end

                app.SVR_univar_mat.MdlLin = MdlLin;

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.SVR_multivar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            elseif (app.univar == 1)
                for i=1:5
                    if i == 1
                        testG = app.x_input(1: floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(1: floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input(floor(size(app.x_input,1)/5)*i +1: end,:);
                        trainM = app.y_output(floor(size(app.x_input,1)/5)*i +1: end,:);
                    else
                        testG = app.x_input(floor(size(app.x_input,1)/5)*(i-1) : floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(floor(size(app.y_output,1)/5)*(i-1) : floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input([1: floor(size(app.x_input,1)/5)*(i-1)-1, floor(size(app.x_input,1)/5)*i:end],:);
                        trainM = app.y_output([1: floor(size(app.y_output,1)/5)*(i-1)-1, floor(size(app.y_output,1)/5)*i:end],:);
                    end
                    X = trainG;
                    Y = trainM;
    
                    if size(Y,2) == 1                                   
                    %Univariate
                        if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                            MdlLin = app.SVR_univar_mat.MdlLin;
                        else
                            MdlLin = app.SVR_univar_mat.var_results.MdlLin;
                        end

                        ypred1 = predict(MdlLin,testG);
    
                        ypred11 = [ypred11; ypred1];
                        testM1 = [testM1; testM];
                    else
                    %Multivariate
                        if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                            MdlLin = app.SVR_multivar_mat.MdlLin;
                        else
                            MdlLin = app.SVR_multivar_mat.var_results.MdlLin;
                        end
                        ypred1 = predict(MdlLin,testG);
    
                        ypred11 = [ypred11; ypred1];
                        testM1 = [testM1; mean(testM,2)];
                    end
                end

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.SVR_multivar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            end
 
            if isempty(app.SVR_multivar_mat) & (app.multivar == 1)
                for i=1:5
                    if i == 1
                        testG = app.x_input(1: floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(1: floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input(floor(size(app.x_input,1)/5)*i +1: end,:);
                        trainM = app.y_output(floor(size(app.x_input,1)/5)*i +1: end,:);
                    else
                        testG = app.x_input(floor(size(app.x_input,1)/5)*(i-1) : floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(floor(size(app.y_output,1)/5)*(i-1) : floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input([1: floor(size(app.x_input,1)/5)*(i-1)-1, floor(size(app.x_input,1)/5)*i:end],:);
                        trainM = app.y_output([1: floor(size(app.y_output,1)/5)*(i-1)-1, floor(size(app.y_output,1)/5)*i:end],:);
                    end
                    X = trainG;
                    Y = trainM;
    
                    if size(Y,2) == 1
                    %Univariate
                        MdlLin = fitrsvm(X,Y);
                        ypred1 = predict(MdlLin,testG);
    
                        ypred11 = [ypred11; ypred1];
                        testM1 = [testM1; testM];
                    else
                    %Multivariate
                        MdlLin = fitrsvm(X,mean(Y,2));
                        ypred1 = predict(MdlLin,testG);
    
                        ypred11 = [ypred11; ypred1];
                        testM1 = [testM1; mean(testM,2)];
                    end
                end

                app.SVR_multivar_mat.MdlLin = MdlLin;

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.SVR_univar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            elseif (app.multivar == 1)
                for i=1:5
                    if i == 1
                        testG = app.x_input(1: floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(1: floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input(floor(size(app.x_input,1)/5)*i +1: end,:);
                        trainM = app.y_output(floor(size(app.x_input,1)/5)*i +1: end,:);
                    else
                        testG = app.x_input(floor(size(app.x_input,1)/5)*(i-1) : floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(floor(size(app.y_output,1)/5)*(i-1) : floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input([1: floor(size(app.x_input,1)/5)*(i-1)-1, floor(size(app.x_input,1)/5)*i:end],:);
                        trainM = app.y_output([1: floor(size(app.y_output,1)/5)*(i-1)-1, floor(size(app.y_output,1)/5)*i:end],:);
                    end
                    X = trainG;
                    Y = trainM;
    
                    if size(Y,2) == 1                                     %Now, users have to care / possible save univ and multi [divided in future]
                    %Univariate
                        if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                            MdlLin = app.SVR_univar_mat.MdlLin;
                        else
                            MdlLin = app.SVR_univar_mat.var_results.MdlLin;
                        end
                        ypred1 = predict(MdlLin,testG);
    
                        ypred11 = [ypred11; ypred1];
                        testM1 = [testM1; testM];
                    else
                    %Multivariate
                        if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                            MdlLin = app.SVR_multivar_mat.MdlLin;
                        else
                            MdlLin = app.SVR_multivar_mat.var_results.MdlLin;
                        end

                        ypred1 = predict(MdlLin,testG);
    
                        ypred11 = [ypred11; ypred1];
                        testM1 = [testM1; mean(testM,2)];
                    end
                end

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.SVR_univar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            end
                      
            app.Pred_Val = ypred11;
            app.Orig_Val = testM1;

        end

        function PLS(app)
            % PLS
            %cross-valid = 5 
            ypred11 =[];
            testM1 = [];

            if isempty(app.PLS_univar_mat) & (app.univar == 1)
                for i=1:5
                    if i == 1
                        testG = app.x_input(1: floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(1: floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input(floor(size(app.x_input,1)/5)*i +1: end,:);
                        trainM = app.y_output(floor(size(app.x_input,1)/5)*i +1: end,:);
                    else
                        testG = app.x_input(floor(size(app.x_input,1)/5)*(i-1) : floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(floor(size(app.y_output,1)/5)*(i-1) : floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input([1: floor(size(app.x_input,1)/5)*(i-1)-1, floor(size(app.x_input,1)/5)*i:end],:);
                        trainM = app.y_output([1: floor(size(app.y_output,1)/5)*(i-1)-1, floor(size(app.y_output,1)/5)*i:end],:);
                    end
    
                   [XL,yl,XS,YS,beta,PCTVAR,MSE,stats] = plsregress(trainG,trainM);
                    yfit = [ones(size(testG,1),1) testG]*beta;
                    ypred11 = [ypred11; yfit];
                    testM1 = [testM1; testM];
                end

                app.PLS_univar_mat.XL = XL;
                app.PLS_univar_mat.yl = yl;
                app.PLS_univar_mat.XS = XS;
                app.PLS_univar_mat.YS = YS;
                app.PLS_univar_mat.beta = beta;
                app.PLS_univar_mat.PCTVAR = PCTVAR;
                app.PLS_univar_mat.MSE = MSE;
                app.PLS_univar_mat.stats = stats;


                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.PLS_multivar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

            elseif (app.univar == 1)
                 for i=1:5
                    if i == 1
                        testG = app.x_input(1: floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(1: floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input(floor(size(app.x_input,1)/5)*i +1: end,:);
                        trainM = app.y_output(floor(size(app.x_input,1)/5)*i +1: end,:);
                    else
                        testG = app.x_input(floor(size(app.x_input,1)/5)*(i-1) : floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(floor(size(app.y_output,1)/5)*(i-1) : floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input([1: floor(size(app.x_input,1)/5)*(i-1)-1, floor(size(app.x_input,1)/5)*i:end],:);
                        trainM = app.y_output([1: floor(size(app.y_output,1)/5)*(i-1)-1, floor(size(app.y_output,1)/5)*i:end],:);
                    end
  
                    if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                        XL = app.PLS_univar_mat.XL;
                        yl = app.PLS_univar_mat.yl;
                        XS = app.PLS_univar_mat.XS;
                        YS = app.PLS_univar_mat.YS;
                        beta = app.PLS_univar_mat.beta;
                        PCTVAR = app.PLS_univar_mat.PCTVAR;
                        MSE = app.PLS_univar_mat.MSE;
                        stats = app.PLS_univar_mat.stats;
                    else
                        XL = app.PLS_univar_mat.var_results.XL;
                        yl = app.PLS_univar_mat.var_results.yl;
                        XS = app.PLS_univar_mat.var_results.XS;
                        YS = app.PLS_univar_mat.var_results.YS;
                        beta = app.PLS_univar_mat.var_results.beta;
                        PCTVAR = app.PLS_univar_mat.var_results.PCTVAR;
                        MSE = app.PLS_univar_mat.var_results.MSE;
                        stats = app.PLS_univar_mat.var_results.stats;
                    end

                    yfit = [ones(size(testG,1),1) testG]*beta;
                    ypred11 = [ypred11; yfit];
                    testM1 = [testM1; testM];
                 end

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.PLS_multivar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            end

            if isempty(app.PLS_multivar_mat) & (app.multivar == 1)
                for i=1:5
                    if i == 1
                        testG = app.x_input(1: floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(1: floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input(floor(size(app.x_input,1)/5)*i +1: end,:);
                        trainM = app.y_output(floor(size(app.x_input,1)/5)*i +1: end,:);
                    else
                        testG = app.x_input(floor(size(app.x_input,1)/5)*(i-1) : floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(floor(size(app.y_output,1)/5)*(i-1) : floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input([1: floor(size(app.x_input,1)/5)*(i-1)-1, floor(size(app.x_input,1)/5)*i:end],:);
                        trainM = app.y_output([1: floor(size(app.y_output,1)/5)*(i-1)-1, floor(size(app.y_output,1)/5)*i:end],:);
                    end
    
                   [XL,yl,XS,YS,beta,PCTVAR,MSE,stats] = plsregress(trainG,trainM);
                    yfit = [ones(size(testG,1),1) testG]*beta;
                    ypred11 = [ypred11; yfit];
                    testM1 = [testM1; testM];
                end

                app.PLS_multivar_mat.XL = XL;
                app.PLS_multivar_mat.yl = yl;
                app.PLS_multivar_mat.XS = XS;
                app.PLS_multivar_mat.YS = YS;
                app.PLS_multivar_mat.beta = beta;
                app.PLS_multivar_mat.PCTVAR = PCTVAR;
                app.PLS_multivar_mat.MSE = MSE;
                app.PLS_multivar_mat.stats = stats;


                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.PLS_univar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

            elseif (app.multivar == 1)
                 for i=1:5
                    if i == 1
                        testG = app.x_input(1: floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(1: floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input(floor(size(app.x_input,1)/5)*i +1: end,:);
                        trainM = app.y_output(floor(size(app.x_input,1)/5)*i +1: end,:);
                    else
                        testG = app.x_input(floor(size(app.x_input,1)/5)*(i-1) : floor(size(app.x_input,1)/5)*i,:);
                        testM = app.y_output(floor(size(app.y_output,1)/5)*(i-1) : floor(size(app.y_output,1)/5)*i,:);
    
                        trainG = app.x_input([1: floor(size(app.x_input,1)/5)*(i-1)-1, floor(size(app.x_input,1)/5)*i:end],:);
                        trainM = app.y_output([1: floor(size(app.y_output,1)/5)*(i-1)-1, floor(size(app.y_output,1)/5)*i:end],:);
                    end
 
                     if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                        XL = app.PLS_multivar_mat.XL;
                        yl = app.PLS_multivar_mat.yl;
                        XS = app.PLS_multivar_mat.XS;
                        YS = app.PLS_multivar_mat.YS;
                        beta = app.PLS_multivar_mat.beta;
                        PCTVAR = app.PLS_multivar_mat.PCTVAR;
                        MSE = app.PLS_multivar_mat.MSE;
                        stats = app.PLS_multivar_mat.stats;
                    else
                        XL = app.PLS_multivar_mat.var_results.XL;
                        yl = app.PLS_multivar_mat.var_results.yl;
                        XS = app.PLS_multivar_mat.var_results.XS;
                        YS = app.PLS_multivar_mat.var_results.YS;
                        beta = app.PLS_multivar_mat.var_results.beta;
                        PCTVAR = app.PLS_multivar_mat.var_results.PCTVAR;
                        MSE = app.PLS_multivar_mat.var_results.MSE;
                        stats = app.PLS_multivar_mat.var_results.stats;
                    end


                    yfit = [ones(size(testG,1),1) testG]*beta;
                    ypred11 = [ypred11; yfit];
                    testM1 = [testM1; testM];
                 end

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.PLS_univar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
            end

            app.Pred_Val = ypred11;
            app.Orig_Val = testM1;
        end

        function RF(app)
            % RF
            X = app.x_input;
            y = app.y_output;

           if isempty(app.RF_univar_mat) & (app.univar == 1)

               if size(y,1) == 1
                    y = y';
               end

                if size(y,2) == 1                                        %Now, users have to care / possible save univ and multi [divided in future]
                    %Univariate
                    RF_GM_1 = TreeBagger(app.number_of_decisionsTrees_input, X, y, Method = 'regression', OOBPrediction='on');
                    app.Pred_Val = oobQuantilePredict(RF_GM_1);
                    app.Orig_Val = y;
                else
                    %Multivariate
                    RF_GM_1 = TreeBagger(app.number_of_decisionsTrees_input, X, mean(y,2), Method = 'regression', OOBPrediction='on');
                    app.Pred_Val = oobQuantilePredict(RF_GM_1);
                    app.Orig_Val = mean(y,2);
                end

                app.RF_univar_mat.RF_GM_1 = RF_GM_1;

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.RF_multivar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

           elseif (app.univar == 1)

                 if size(y,2) == 1  
                 %Univariate
                    RF_GM_1 = app.RF_univar_mat.RF_GM_1;
                    app.Pred_Val = oobQuantilePredict(RF_GM_1);
                    app.Orig_Val = y;
                else
                    %Multivariate
                    if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                        RF_GM_1 = app.RF_multivar_mat.RF_GM_1;
                    else
                        RF_GM_1 = app.RF_multivar_mat.var_results.RF_GM_1;
                    end
                    app.Pred_Val = oobQuantilePredict(RF_GM_1);
                    app.Orig_Val = mean(y,2);
                 end

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.RF_multivar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

           end

           if isempty(app.RF_multivar_mat) & (app.multivar == 1)

                if size(y,1) == 1
                   y = y';
                end
                
                RF_GM_1 = TreeBagger(app.number_of_decisionsTrees_input, X, mean(y,2), Method = 'regression', OOBPrediction='on');
                app.Pred_Val = oobQuantilePredict(RF_GM_1);
                app.Orig_Val = mean(y,2);

                app.RF_multivar_mat.RF_GM_1 = RF_GM_1;

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.RF_univar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

           elseif (app.multivar == 1)

                 if size(y,2) == 1                                      
                    RF_GM_1 = app.RF_multivar_mat.RF_GM_1;
                    app.Pred_Val = oobQuantilePredict(RF_GM_1);
                    app.Orig_Val = y;
                else
                    RF_GM_1 = app.RF_multivar_mat.RF_GM_1;
                    app.Pred_Val = oobQuantilePredict(RF_GM_1);
                    app.Orig_Val = mean(y,2);
                 end

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.RF_univar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

           end
        end

        function LSTM(app)
            % LSTM
            if size(app.y_output,1) == 1
               app.y_output = app.y_output';
            end

            %80:20 TRAINING:TESTING
            inputTrain = app.x_input(1: floor(0.8*size(app.x_input,1)),:);
            inputTest = app.x_input(floor(0.8*size(app.x_input,1))+1 : end,:);
            outputTrain = app.y_output(1:floor(0.8*size(app.y_output,1)),:);
            outputTest = app.y_output(floor(0.8*size(app.y_output,1))+1 : end,:);

            %**************************************************************************
            %openExample('nnet/ClassificationLSTMNetworksExample')
            
            %**************************************************************************
            %openExample('nnet/ClassificationLSTMNetworksExample')
            numResponses = 1;
            numFeatures = size(app.x_input,2);
            layers = [ ...
                sequenceInputLayer(numFeatures)
                lstmLayer(app.lstmlayer_LSTM_1,'OutputMode','sequence')
                fullyConnectedLayer(app.fullyconnectedlayer_LSTM_2)
                dropoutLayer(app.dropoutlayer_LSTM_3)
                fullyConnectedLayer(numResponses)
                regressionLayer];
            %
            % Training
            miniBatchSize = app.miniBatchSize_LSTM;

            %if gpuDeviceCount > 0
                 % options = trainingOptions('adam', ...
                 %    'ExecutionEnvironment','gpu', ...
                 %    'MaxEpochs',app.max_epoches_LSTM_input, ...
                 %    'MiniBatchSize',miniBatchSize, ...
                 %    'GradientThreshold',app.gradientTreshold_LSTM_input, ...
                 %    'Shuffle','never',...
                 %    'Verbose',0);
            %else 
                options = trainingOptions('adam', ...
                    'ExecutionEnvironment','cpu', ...
                    'MaxEpochs',app.max_epoches_LSTM_input, ...
                    'MiniBatchSize',miniBatchSize, ...
                    'GradientThreshold',app.gradientTreshold_LSTM_input, ...
                    'Shuffle','never',...
                    'Verbose',0);
            %end

              if isempty(app.LSTM_univar_mat) & (app.univar == 1)                                             
                net = trainNetwork(inputTrain',outputTrain',layers,options);
                app.LSTM_univar_mat.net = net;

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.LSTM_multivar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
              elseif (app.univar == 1) 
                if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                    net = app.LSTM_univar_mat.net;
                else
                    net = app.LSTM_univar_mat.var_results.net;
                end               

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.LSTM_multivar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
              end

             if isempty(app.LSTM_multivar_mat) & (app.multivar == 1)                                             
                net = trainNetwork(inputTrain',mean(outputTrain,2)',layers,options);
                app.LSTM_multivar_mat.net = net;

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.LSTM_univar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
              elseif (app.multivar == 1) 
                if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                    net = app.LSTM_multivar_mat.net;
                else
                    net = app.LSTM_multivar_mat.var_results.net;
                end   

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.LSTM_univar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
              end

            % Testing
            app.Pred_Val = predict(net,inputTest','MiniBatchSize',1);
            app.Pred_Val = app.Pred_Val';

            if size(outputTrain,1) == 1
                app.Orig_Val = outputTest; %Univariate
            else
                app.Orig_Val = mean(outputTest,2);  %Multivariate
            end
            
        end

        function CNN(app)
            % CNN
            if size(app.y_output,1) == 1
               app.y_output = app.y_output';
            end

            %80:20 TRAINING:TESTING
            inputTrain = app.x_input(1: floor(0.8*size(app.x_input,1)),:);
            inputTest = app.x_input(floor(0.8*size(app.x_input,1))+1 : end,:);
            outputTrain = app.y_output(1:floor(0.8*size(app.y_output,1)),:);
            outputTest = app.y_output(floor(0.8*size(app.y_output,1))+1 : end,:);

            %**************************************************************************
            %openExample('nnet/ClassificationLSTMNetworksExample')
            
            %**************************************************************************
            %openExample('nnet/ClassificationLSTMNetworksExample')
            numResponses = 1;
            numFeatures = size(app.x_input,2);
            layers = [ ...
                sequenceInputLayer(numFeatures)
                convolution1dLayer(app.convolutionlayer_CNN_1,numFeatures,Padding="causal")
                reluLayer
                convolution1dLayer(app.convolutionlayer_CNN_2,numFeatures*2,Padding="same")
                reluLayer
                convolution1dLayer(app.convolutionlayer_CNN_3,numFeatures,Padding="same")
                reluLayer
                fullyConnectedLayer(app.fullyconnectedlayer_CNN_4)
                fullyConnectedLayer(app.fullyconnectedlayer_CNN_5)
                fullyConnectedLayer(numResponses)
                regressionLayer];
            %
            % Training
            miniBatchSize = app.miniBatchSize_CNN;

            % if gpuDeviceCount > 0
            %      options = trainingOptions('adam', ...
            %         'ExecutionEnvironment','gpu', ...
            %         'MaxEpochs',app.max_epoches_CNN_input, ...
            %         'MiniBatchSize',miniBatchSize, ...
            %         'GradientThreshold',app.gradientTreshold_CNN_input, ...
            %         'Shuffle','never',...
            %         'Verbose',0);
            % else 
                options = trainingOptions('adam', ...
                    'ExecutionEnvironment','cpu', ...
                    'MaxEpochs',app.max_epoches_CNN_input, ...
                    'MiniBatchSize',miniBatchSize, ...
                    'GradientThreshold',app.gradientTreshold_CNN_input, ...
                    'Shuffle','never',...
                    'Verbose',0);
            %end
           
              if isempty(app.CNN_univar_mat) & (app.univar == 1)                                             
                net = trainNetwork(inputTrain',outputTrain',layers,options);
                app.CNN_univar_mat.net = net;

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.LSTM_univar_mat = []; app.fullNN_univar_mat = [];
                app.CNN_multivar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.LSTM_multivar_mat = []; app.fullNN_multivar_mat = [];
              elseif (app.univar == 1)
                if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                    net = app.CNN_univar_mat.net;
                else
                    net = app.CNN_univar_mat.var_results.net;
                end

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.LSTM_univar_mat = []; app.fullNN_univar_mat = [];
                app.CNN_multivar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.LSTM_multivar_mat = []; app.fullNN_multivar_mat = [];
              end

             if isempty(app.CNN_multivar_mat) & (app.multivar == 1)                                             
                net = trainNetwork(inputTrain',mean(outputTrain,2)',layers,options);
                app.CNN_multivar_mat.net = net;

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.LSTM_univar_mat = []; app.fullNN_univar_mat = [];
                app.CNN_univar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.LSTM_multivar_mat = []; app.fullNN_multivar_mat = [];
              elseif (app.multivar == 1) 
                if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                    net = app.CNN_multivar_mat.net;
                else
                    net = app.CNN_multivar_mat.var_results.net;
                end

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.LSTM_univar_mat = []; app.fullNN_univar_mat = [];
                app.CNN_univar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.LSTM_multivar_mat = []; app.fullNN_multivar_mat = [];
              end

            % Testing
            app.Pred_Val = predict(net,inputTest','MiniBatchSize',1);
            app.Pred_Val = app.Pred_Val';

            if size(outputTrain,2) == 1
                app.Orig_Val = outputTest; %Univariate
            else
                app.Orig_Val = mean(outputTest,2);  %Multivariate
            end
        end


        function fullyconnected_NN(app)
            % Fully connected feedforward NN
            if size(app.y_output,1) == 1
               app.y_output = app.y_output';
            end
        
            % 80:20 TRAINING:TESTING
            inputTrain = app.x_input(1: floor(0.8*size(app.x_input,1)),:);
            inputTest = app.x_input(floor(0.8*size(app.x_input,1))+1 : end,:);
            outputTrain = app.y_output(1:floor(0.8*size(app.y_output,1)),:);
            outputTest = app.y_output(floor(0.8*size(app.y_output,1))+1 : end,:);
        
            % Number of inputs and outputs
            numFeatures = size(app.x_input,2);
            numResponses = 1;
        
            % Definition of layers – purely fully connected NN
            layers = [ ...
                featureInputLayer(numFeatures)
                fullyConnectedLayer(app.fullyconnectedlayer_fullNN_1)   
                reluLayer
                fullyConnectedLayer(app.fullyconnectedlayer_fullNN_2)
                reluLayer
                fullyConnectedLayer(app.fullyconnectedlayer_fullNN_3)
                reluLayer
                fullyConnectedLayer(numResponses)
                regressionLayer];
        
            % Training options
            miniBatchSize = app.miniBatchSize_fullNN;
            % if gpuDeviceCount > 0
            %     options = trainingOptions('adam', ...
            %         'ExecutionEnvironment','gpu', ...
            %         'MaxEpochs',app.max_epoches_CNN_input, ...
            %         'MiniBatchSize',miniBatchSize, ...
            %         'GradientThreshold',app.gradientTreshold_CNN_input, ...
            %         'Shuffle','never', ...
            %         'Verbose',0);
            % else
                options = trainingOptions('adam', ...
                    'ExecutionEnvironment','cpu', ...
                    'MaxEpochs',app.max_epoches_CNN_input, ...
                    'MiniBatchSize',miniBatchSize, ...
                    'GradientThreshold',app.gradientTreshold_CNN_input, ...
                    'Shuffle','never', ...
                    'Verbose',0);
            %end
        
              if isempty(app.fullNN_univar_mat) & (app.univar == 1)                                             
                net = trainNetwork(inputTrain,outputTrain,layers,options);
                app.fullNN_univar_mat.net = net;

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.CNN_univar_mat = []; app.LSTM_univar_mat = [];
                app.fullNN_multivar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.CNN_multivar_mat = []; app.LSTM_multivar_mat = [];
              elseif (app.univar == 1)
                if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                    net = app.fullNN_univar_mat.net;
                else
                    net = app.fullNN_univar_mat.var_results.net;
                end

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.CNN_univar_mat = []; app.LSTM_univar_mat = [];
                app.fullNN_multivar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.CNN_multivar_mat = []; app.LSTM_multivar_mat = [];
              end

             if isempty(app.fullNN_multivar_mat) & (app.multivar == 1)                                             
                net = trainNetwork(inputTrain,mean(outputTrain,2),layers,options);
                app.fullNN_multivar_mat.net = net;

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.CNN_univar_mat = []; app.LSTM_univar_mat = [];
                app.fullNN_univar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.CNN_multivar_mat = []; app.LSTM_multivar_mat = [];
              elseif (app.multivar == 1)
                if  (app.CheckBox_Keep_for_further_predictions.Value == 1)
                    net = app.fullNN_multivar_mat.net;
                else
                    net = app.fullNN_multivar_mat.var_results.net;
                end

                app.ENR_univar_mat = []; app.lasso_univar_mat = []; app.RR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.CNN_univar_mat = []; app.LSTM_univar_mat = [];
                app.fullNN_univar_mat = [];
                app.ENR_multivar_mat = []; app.lasso_multivar_mat = []; app.RR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.CNN_multivar_mat = []; app.LSTM_multivar_mat = [];
              end
        
            % Testing
            app.Pred_Val = predict(net,inputTest,'MiniBatchSize',1);
        
            if size(outputTrain,2) == 1
                app.Orig_Val = outputTest; %Univariate
            else
                app.Orig_Val = mean(outputTest,2); %Multivariate
            end
        end

        function UnivariatePrediction(app)
            
            if ~isempty(app.dataG)
                app.UniPred_G = app.dataG_no(:,:)';
            end

            if ~isempty(app.dataM)
                app.UniPred_M = app.dataM_no(app.i_UniVarSample,:);
                app.UniPred_M = cell2mat(app.UniPred_M);
                app.UniPred_M = app.UniPred_M';
            end

            if ~isempty(app.dataPhe)
                app.dataPhe_no = table2cell(app.dataPhe(:, 2:end));
                app.dataPhe_no = cell2mat(app.dataPhe_no);
                app.UniPred_P = app.dataPhe_no;
            end

            if ~isempty(app.dataOther)
                app.dataOther_no = table2cell(app.dataOther(:, 2:end));
                app.dataOther_no = cell2mat(app.dataOther_no);
                app.UniPred_Others = app.dataOther_no;
            end

            if app.CheckBox_Genome_Metabolome_BoxValue == 1
                app.x_input = app.UniPred_G;
                app.y_output = app.UniPred_M;
            elseif app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                app.x_input = app.UniPred_M;
                app.y_output = app.UniPred_P;
            elseif app.CheckBox_Genome_Phenotype_BoxValue == 1
                app.x_input = app.UniPred_G;
                app.y_output = app.UniPred_P;
            elseif app.CheckBox_Genome_Metabolome_Phenotype_BoxValue == 1
                app.x_input = [app.UniPred_G,app.UniPred_M];
                app.y_output = app.UniPred_P;
            elseif app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype_BoxValue == 1
               %...
               %add app.UniPred_OtherOmics
                app.x_input = [app.UniPred_G,app.UniPred_M, app.UniPred_Others];
                app.y_output = app.UniPred_P;
            elseif app.CheckBox_OthersOMICs_Phenotype_BoxValue == 1
                app.x_input = app.UniPred_Others;
                app.y_output = app.UniPred_P;
            end

            if app.CheckBox_LASSO_Value == 1
                %LASSO 
                LASSO(app) %Matlab code

           elseif app.CheckBox_RR_Value == 1
                %RR – univariate prediction
                RR(app) %Matlab code

            elseif app.CheckBox_ENR_Value == 1
                %ENR – univariate prediction 
                ENR(app) %Matlab code

            elseif app.CheckBox_GPR_Value == 1
                %GPR – univariate prediction || GPR-Matlab!!!
                GPR(app) %Matlab code

                %Pythone code
                %pyrunfile('GPR.py',"metabolites_results", UniPred_G, UniPred_M);

            elseif app.CheckBox_SVR_Value == 1
                %SVR – univariate prediction
                SVR(app) %Matlab code

                %Pythone code
                %pyrunfile('SVR.py',"metabolites_results", UniPred_G, UniPred_M);

            elseif app.CheckBox_PLS_Value == 1
                %PLS – univariate prediction
                PLS(app) %Matlab code

            elseif app.CheckBox_RandomForest_Value == 1
                %RF – univariate prediction
                RF(app)

            elseif app.CheckBox_LSTM_Value == 1
                %LSTM – univariate prediction
                LSTM(app)

            elseif app.CheckBox_CNN_Value == 1
                %CNN– univariate prediction
                CNN(app)

            elseif app.CheckBox_fullyconnected_NN_Value == 1
                %fullyconnected_NN– univariate prediction
                fullyconnected_NN(app)
            end
            ...

            if app.generate_HTML_report == 1
                if app.CheckBox_Genome_Metabolome_BoxValue == 1 || app.CheckBox_Metabolome_Phenotype_BoxValue == 1 || app.CheckBox_Genome_Phenotype_BoxValue == 1 || app.CheckBox_OthersOMICs_Phenotype_BoxValue == 1
                        app.lasso_univar_mat = [];
                        app.ENR_univar_mat = []; app.RR_univar_mat = []; app.GPR_univar_mat = []; 
                        app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                        app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                        app.lasso_multivar_mat = [];
                        app.ENR_multivar_mat = []; app.RR_multivar_mat = []; app.GPR_multivar_mat = []; 
                        app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                        app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
        
                        LASSO(app)
        
                           %MSE
                           app.LASSO_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.LASSO_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                               if isnan(corr(app.Orig_Val,app.Pred_Val))
                                app.LASSO_cc = 0;
                               else
                                app.LASSO_cc = double(corr(app.Orig_Val,app.Pred_Val));
                               end
                               app.Pred_Val = app.Pred_Val;
                           else
                            if app.CheckBox_PLS_Value == 1
                                app.LASSO_cc = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                            else
                                app.LASSO_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
                           end
                        
                        RR(app)
        
                           %MSE
                           app.RR_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.RR_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                               if isnan(corr(app.Orig_Val,app.Pred_Val))
                                app.RR_cc = 0;
                               else
                                app.RR_cc = double(corr(app.Orig_Val,app.Pred_Val));
                               end
                               app.Pred_Val = app.Pred_Val;
                           else
                            if app.CheckBox_PLS_Value == 1
                                app.RR_cc = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                            else
                                app.RR_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
                           end
        
                        ENR(app)
        
                           %MSE
                           app.ENR_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.ENR_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                               if isnan(corr(app.Orig_Val,app.Pred_Val))
                                app.ENR_cc = 0;
                               else
                                app.ENR_cc = double(corr(app.Orig_Val,app.Pred_Val));
                               end
                               app.Pred_Val = app.Pred_Val;
                           else
                            if app.CheckBox_PLS_Value == 1
                                app.ENR_cc = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                            else
                                app.ENR_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
                           end
        
                        GPR(app)
        
                           %MSE
                           app.GPR_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.GPR_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                               if isnan(corr(app.Orig_Val,app.Pred_Val))
                                app.GPR_cc = 0;
                               else
                                app.GPR_cc = double(corr(app.Orig_Val,app.Pred_Val));
                               end
                               app.Pred_Val = app.Pred_Val;
                           else
                            if app.CheckBox_PLS_Value == 1
                                app.GPR_cc = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                            else
                                app.GPR_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
                           end
        
                        SVR(app)
        
                           %MSE
                           app.SVR_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.SVR_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                               if isnan(corr(app.Orig_Val,app.Pred_Val))
                                app.SVR_cc = 0;
                               else
                                app.SVR_cc = double(corr(app.Orig_Val,app.Pred_Val));
                               end
                               app.Pred_Val = app.Pred_Val;
                           else
                            if app.CheckBox_PLS_Value == 1
                                app.SVR_cc = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                            else
                                app.SVR_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
                           end
        
                        PLS(app)
        
                           %MSE
                           app.PLS_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.PLS_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                               if isnan(corr(app.Orig_Val,app.Pred_Val))
                                app.PLS_cc = 0;
                               else
                                app.PLS_cc = double(corr(app.Orig_Val,app.Pred_Val));
                               end
                               app.Pred_Val = app.Pred_Val;
                           else
                            if app.CheckBox_PLS_Value == 1
                                app.PLS_cc = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                            else
                                app.PLS_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
                           end
        
                        RF(app)
        
                           %MSE
                           app.RF_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.RF_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                               if isnan(corr(app.Orig_Val,app.Pred_Val))
                                app.RF_cc = 0;
                               else
                                app.RF_cc = double(corr(app.Orig_Val,app.Pred_Val));
                               end
                               app.Pred_Val = app.Pred_Val;
                           else
                               app.RF_cc = double(corr(app.Orig_Val,app.Pred_Val));
                           end
                        
                        LSTM(app)
        
                           %MSE
                           app.LSTM_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.LSTM_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                               if isnan(corr(app.Orig_Val,app.Pred_Val))
                                app.LSTM_cc = 0;
                               else
                                app.LSTM_cc = double(corr(app.Orig_Val,app.Pred_Val));
                               end
                               app.Pred_Val = app.Pred_Val;
                           else
                               app.LSTM_cc = double(corr(app.Orig_Val,app.Pred_Val));
                           end
                        
                        CNN(app)
        
                           %MSE
                           app.CNN_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.CNN_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                               if isnan(corr(app.Orig_Val,app.Pred_Val))
                                app.CNN_cc = 0;
                               else
                                app.CNN_cc = double(corr(app.Orig_Val,app.Pred_Val));
                               end
                               app.Pred_Val = app.Pred_Val;
                           else
                               app.CNN_cc = double(corr(app.Orig_Val,app.Pred_Val));
                           end
        
                        fullyconnected_NN(app)
        
                           %MSE
                           app.FullNN_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.FullNN_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                               if isnan(corr(app.Orig_Val,app.Pred_Val))
                                app.FullNN_cc = 0;
                               else
                                app.FullNN_cc = double(corr(app.Orig_Val,app.Pred_Val));
                               end
                               app.Pred_Val = app.Pred_Val;
                           else
                               app.FullNN_cc = double(corr(app.Orig_Val,app.Pred_Val));
                           end

                elseif app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype_BoxValue == 1 || app.CheckBox_Genome_Metabolome_Phenotype_BoxValue == 1

                        RF(app)
        
                           %MSE
                           app.RF_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.RF_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                               if isnan(corr(app.Orig_Val,app.Pred_Val))
                                app.RF_cc = 0;
                               else
                                app.RF_cc = double(corr(app.Orig_Val,app.Pred_Val));
                               end
                               app.Pred_Val = app.Pred_Val;
                           else
                               app.RF_cc = double(corr(app.Orig_Val,app.Pred_Val));
                           end
                        
                        LSTM(app)
        
                           %MSE
                           app.LSTM_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.LSTM_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                               if isnan(corr(app.Orig_Val,app.Pred_Val))
                                app.LSTM_cc = 0;
                               else
                                app.LSTM_cc = double(corr(app.Orig_Val,app.Pred_Val));
                               end
                               app.Pred_Val = app.Pred_Val;
                           else
                               app.LSTM_cc = double(corr(app.Orig_Val,app.Pred_Val));
                           end
                        
                        CNN(app)
        
                           %MSE
                           app.CNN_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.CNN_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                               if isnan(corr(app.Orig_Val,app.Pred_Val))
                                app.CNN_cc = 0;
                               else
                                app.CNN_cc = double(corr(app.Orig_Val,app.Pred_Val));
                               end
                               app.Pred_Val = app.Pred_Val;
                           else
                               app.CNN_cc = double(corr(app.Orig_Val,app.Pred_Val));
                           end
        
                        fullyconnected_NN(app)
        
                           %MSE
                           app.FullNN_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.FullNN_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                               if isnan(corr(app.Orig_Val,app.Pred_Val))
                                app.FullNN_cc = 0;
                               else
                                app.FullNN_cc = double(corr(app.Orig_Val,app.Pred_Val));
                               end
                               app.Pred_Val = app.Pred_Val;
                           else
                               app.FullNN_cc = double(corr(app.Orig_Val,app.Pred_Val));
                           end
                end
            end

        end


        function MultivariatePrediction(app)
            
            if ~isempty(app.dataG)
                app.UniPred_G = app.dataG_no(:,:)';
            end

            if ~isempty(app.dataM)
                app.UniPred_M = app.dataM_no(:,:);
                app.UniPred_M = cell2mat(app.UniPred_M);
                app.UniPred_M = app.UniPred_M';
            end

            if ~isempty(app.dataPhe)
                app.dataPhe_no = table2cell(app.dataPhe(:, 2:end));
                app.dataPhe_no = cell2mat(app.dataPhe_no);
                app.UniPred_P = app.dataPhe_no';
            end

            if ~isempty(app.dataOther)
                app.dataOther_no = table2cell(app.dataOther(:, 2:end));
                app.dataOther_no = cell2mat(app.dataOther_no);
                app.UniPred_Others = app.dataOther_no;
            end

            if app.CheckBox_Genome_Metabolome_BoxValue == 1
                app.x_input = app.UniPred_G;
                app.y_output = app.UniPred_M;
            elseif app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                app.x_input = app.UniPred_M;
                app.y_output = app.UniPred_P;
            elseif app.CheckBox_Genome_Phenotype_BoxValue == 1
                app.x_input = app.UniPred_G;
                app.y_output = app.UniPred_P;
            elseif app.CheckBox_Genome_Metabolome_Phenotype_BoxValue == 1
                app.x_input = [app.UniPred_G,app.UniPred_M];
                app.y_output = app.UniPred_P;
            elseif app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype_BoxValue == 1
                app.x_input = [app.UniPred_G,app.UniPred_M, app.UniPred_Others];
                app.y_output = app.UniPred_P;
            elseif app.CheckBox_OthersOMICs_Phenotype_BoxValue == 1
                app.x_input = app.UniPred_Others;
                app.y_output = app.UniPred_P;
            end

            if app.CheckBox_LASSO_Value == 1
                %LASSO 
                LASSO(app) %Matlab code

           elseif app.CheckBox_RR_Value == 1
                %RR – multivariate prediction
                RR(app) %Matlab code

            elseif app.CheckBox_ENR_Value == 1
                %ENR – multivariate prediction 
                ENR(app) %Matlab code

            elseif app.CheckBox_GPR_Value == 1
                %GPR – multivariate prediction || GPR-Matlab!!!
                GPR(app) %Matlab code

            elseif app.CheckBox_SVR_Value == 1
                %SVR – multivariate prediction
                SVR(app) %Matlab code

            elseif app.CheckBox_PLS_Value == 1
                %PLS – multivariate prediction
                PLS(app) %Matlab code

            elseif app.CheckBox_RandomForest_Value == 1
                %RF – multivariate prediction
                RF(app) %Matlab code

            elseif app.CheckBox_LSTM_Value == 1
                %LSTM – multivariate prediction
                LSTM(app) %Matlab code

            elseif app.CheckBox_CNN_Value == 1
                %CNN– univariate prediction
                CNN(app) %Matlab code  


            elseif app.CheckBox_fullyconnected_NN_Value == 1
                %fullyconnected_NN– univariate prediction
                fullyconnected_NN(app)
            
            end
            ...

            if app.generate_HTML_report == 1
                if app.CheckBox_Genome_Metabolome_BoxValue == 1 || app.CheckBox_Metabolome_Phenotype_BoxValue == 1 || app.CheckBox_Genome_Phenotype_BoxValue == 1 || app.CheckBox_OthersOMICs_Phenotype_BoxValue == 1
                        app.lasso_univar_mat = [];
                        app.ENR_univar_mat = []; app.RR_univar_mat = []; app.GPR_univar_mat = []; 
                        app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                        app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                        app.lasso_multivar_mat = [];
                        app.ENR_multivar_mat = []; app.RR_multivar_mat = []; app.GPR_multivar_mat = []; 
                        app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                        app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];
        
                        LASSO(app)    
                           %MSE
                           app.LASSO_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.LASSO_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_PLS_Value == 1
                            app.LASSO_cc = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                           else
                            if size(app.Orig_Val,1) == 1
                                app.LASSO_cc = double(corr(app.Orig_Val',app.Pred_Val));
                            else
                                app.LASSO_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
                           end
        
                        RR(app)
                           %MSE
                           app.RR_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.RR_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_PLS_Value == 1
                            app.RR_cc = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                           else
                            if size(app.Orig_Val,1) == 1
                                app.RR_cc = double(corr(app.Orig_Val',app.Pred_Val));
                            else
                                app.RR_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
                           end
        
                        ENR(app)
                           %MSE
                           app.ENR_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.ENR_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_PLS_Value == 1
                            app.ENR_cc = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                           else
                            if size(app.Orig_Val,1) == 1
                                app.ENR_cc = double(corr(app.Orig_Val',app.Pred_Val));
                            else
                                app.ENR_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
                           end
        
                        GPR(app)
                           %MSE
                           app.GPR_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.GPR_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_PLS_Value == 1
                            app.GPR_cc = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                           else
                            if size(app.Orig_Val,1) == 1
                                app.GPR_cc = double(corr(app.Orig_Val',app.Pred_Val));
                            else
                                app.GPR_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
                           end
        
                        SVR(app)
                           %MSE
                           app.SVR_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.SVR_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_PLS_Value == 1
                            app.SVR_cc = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                           else
                            if size(app.Orig_Val,1) == 1
                                app.SVR_cc = double(corr(app.Orig_Val',app.Pred_Val));
                            else
                                app.SVR_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
                           end
        
                        PLS(app)
                           %MSE
                           app.PLS_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.PLS_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_PLS_Value == 1
                            app.PLS_cc = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                           else
                            if size(app.Orig_Val,1) == 1
                                app.PLS_cc = double(corr(app.Orig_Val',app.Pred_Val));
                            else
                                app.PLS_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
                           end
        
                        RF(app)
                           %MSE
                           app.RF_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.RF_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_PLS_Value == 1
                            app.RF_cc = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                           else
                            if size(app.Orig_Val,1) == 1
                                app.RF_cc = double(corr(app.Orig_Val',app.Pred_Val));
                            else
                                app.RF_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
                           end
        
                        LSTM(app)
                           %MSE
                           app.LSTM_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.LSTM_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_PLS_Value == 1
                            app.LSTM_cc = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                           else
                            if size(app.Orig_Val,1) == 1
                                app.LSTM_cc = double(corr(app.Orig_Val',app.Pred_Val));
                            else
                                app.LSTM_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
                           end
        
                        CNN(app)
                           %MSE
                           app.CNN_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.CNN_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_PLS_Value == 1
                            app.CNN_cc = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                           else
                            if size(app.Orig_Val,1) == 1
                                app.CNN_cc = double(corr(app.Orig_Val',app.Pred_Val));
                            else
                                app.CNN_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
                           end
        
                        fullyconnected_NN(app)
                           %MSE
                           app.FullNN_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.FullNN_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                           if app.CheckBox_PLS_Value == 1
                            app.FullNN_cc = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                           else
                            if size(app.Orig_Val,1) == 1
                                app.FullNN_cc = double(corr(app.Orig_Val',app.Pred_Val));
                            else
                                app.FullNN_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
                           end

                elseif app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype_BoxValue == 1 || app.CheckBox_Genome_Metabolome_Phenotype_BoxValue == 1
                        RF(app)
                           %MSE
                           app.RF_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.RF_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                            if size(app.Orig_Val,1) == 1
                                app.RF_cc = double(corr(app.Orig_Val,app.Pred_Val'));
                            else
                                app.RF_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
        
                        LSTM(app)
                           %MSE
                           app.LSTM_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.LSTM_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                            if size(app.Orig_Val,1) == 1
                                app.LSTM_cc = double(corr(app.Orig_Val,app.Pred_Val'));
                            else
                                app.LSTM_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
        
                        CNN(app)
                           %MSE
                           app.CNN_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.CNN_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                            if size(app.Orig_Val,1) == 1
                                app.CNN_cc = double(corr(app.Orig_Val,app.Pred_Val'));
                            else
                                app.CNN_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end

        
                        fullyconnected_NN(app)
                           %MSE
                           app.FullNN_mse = double(mse(app.Orig_Val,app.Pred_Val));
                           %MAE
                           app.FullNN_mae = double(mae(app.Orig_Val,app.Pred_Val));
                           %CC
                            if size(app.Orig_Val,1) == 1
                                app.FullNN_cc = double(corr(app.Orig_Val,app.Pred_Val'));
                            else
                                app.FullNN_cc = double(corr(app.Orig_Val,app.Pred_Val));
                            end
                end
            end

        end           
        

        function updatePRS_hist_gauss(app)
           % PRS histogram for control and case group on one plot
            histogram(app.UIAxes_PRS, app.control_scores, 'Normalization', 'pdf', 'FaceColor', 'b', 'FaceAlpha', 0.5);
            hold(app.UIAxes_PRS, 'on');  % Use hold on specific to app.PRS_hist_gauss
            histogram(app.UIAxes_PRS, app.case_scores, 'Normalization', 'pdf', 'FaceColor', 'r', 'FaceAlpha', 0.5);
            
            % Fitting a normal distribution
            pd_control = fitdist(app.control_scores, 'Normal');
            x_values_control = linspace(min(app.control_scores), max(app.control_scores), 100);
            y_values_control = pdf(pd_control, x_values_control);
            plot(app.UIAxes_PRS, x_values_control, y_values_control, 'b', 'LineWidth', 2);
            
            pd_case = fitdist(app.case_scores, 'Normal');
            x_values_case = linspace(min(app.case_scores), max(app.case_scores), 100);
            y_values_case = pdf(pd_case, x_values_case);
            plot(app.UIAxes_PRS, x_values_case, y_values_case, 'r', 'LineWidth', 2);
            
            title(app.UIAxes_PRS, 'PRS (Control (South Sweden) vs case (North Sweden))');
            xlabel(app.UIAxes_PRS, 'PRS');
            ylabel(app.UIAxes_PRS, 'Density');
            legend(app.UIAxes_PRS, {'Control – histogram', 'case – histogram', 'Control – normal curve', 'case – normal curve'});
        
            hold(app.UIAxes_PRS, 'off');  % Use hold off specific to app.PRS_hist_gauss

        end
    end
    
    methods (Access = public)
        
        function update_parameters(app, number_of_decisionsTrees, max_epoches_LSTM, max_epoches_CNN, gradientTreshold_LSTM, gradientTreshold_CNN)
            app.number_of_decisionsTrees_input = number_of_decisionsTrees;
            app.max_epoches_LSTM_input = max_epoches_LSTM;
            app.max_epoches_CNN_input = max_epoches_CNN;
            app.gradientTreshold_LSTM_input = gradientTreshold_LSTM;
            app.gradientTreshold_CNN_input = gradientTreshold_CNN;  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Index initialization
            app.i_dotplotSample = 1;
            app.i_histplotSample = 1;
            app.i_UniVarSample = 1;
            app.CheckBox_LASSO_Value = 1;
            app.CheckBox_Genome_Metabolome_BoxValue = 1;
            app.already_preprocessed_value = 1;

            app.dataG = [];
            app.dataM = [];
            app.dataPhe = [];

            app.number_of_decisionsTrees_input = 250;
            app.max_epoches_LSTM_input = 20;
            app.max_epoches_CNN_input = 20;
            app.gradientTreshold_LSTM_input = 2;
            app.gradientTreshold_CNN_input = 2; 

            app.GenomicsInputTab.Parent = [];
            app.MetabolomicsInputTab.Parent = [];
            app.PhenotypeInputTab.Parent = [];
            app.OtherOMICsInputTab.Parent = [];
            app.GenomicsInputTab_preprocessed.Parent = [];
            app.MetabolomicsInputTab_preprocessed.Parent = [];
            app.PhenotypeInputTab_preprocessed.Parent = [];
            app.OtherOMICsInputTab_preprocessed.Parent = [];
            app.GWASTab.Parent = [];
            app.PolygenicRiskScoreTab.Parent = [];
            app.SectionVisualization_PCATab.Parent = [];
            app.SectionVisualization_HistogramTab.Parent = [];
            app.SectionVisualization_BoxPlotTab.Parent = [];

            app.GenomicsInputTab.Parent = [];
            app.MetabolomicsInputTab.Parent = [];
            app.PhenotypeInputTab.Parent = [];
            app.OtherOMICsInputTab.Parent = [];

            app.Parameters_LinearTab.Parent = [];
            app.Parameters_NonlinearTab.Parent = [];
            app.Parameters_DeepLearnTab.Parent = [];

        end

        % Button pushed function: Button_Load_Genomics_Info
        function Button_Load_Genomics_InfoPushed(app, event)
            [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                 app.dataG = readtable(strcat(app.rawFilePath,app.rawFileName));
                 app.UITable_Load_GenomicsInfo.Data = app.dataG;
                 varHeadTable = app.UITable_Load_GenomicsInfo.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITable_Load_GenomicsInfo.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = msgbox(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end  
        end

        % Button pushed function: Button_Example_data_Genomics
        function Button_Example_data_GenomicsPushed(app, event)
              try 
                 app.dataG = readtable('/data/webapps/input_example/input_G_M_Phe/Genotype_Ath.csv');
                 app.UITable_Load_GenomicsInfo.Data = app.dataG;
                 varHeadTable = app.UITable_Load_GenomicsInfo.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITable_Load_GenomicsInfo.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = msgbox(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
        end

        % Button pushed function: Button_Load_Metabolomics_Info
        function Button_Load_Metabolomics_InfoPushed(app, event)
            [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                 app.dataM = readtable(strcat(app.rawFilePath,app.rawFileName));
                 app.dataM = rows2vars(app.dataM);
                 app.dataM.Properties.VariableNames = cellstr(app.dataM{1,:});
                 app.dataM(1,:) = []; 
                 app.dataM_no = app.dataM(:, 2:end);
                 app.UITable_Load_MetabolomicsInfo.Data = app.dataM;
                 varHeadTable = app.UITable_Load_MetabolomicsInfo.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITable_Load_MetabolomicsInfo.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = msgbox(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end

            var_metabolite = app.dataM(1,1);
            var_metabolite_to_cell = var_metabolite{1,1};

            app.EditField_Metabolite_name.Value = var_metabolite_to_cell{1}; %Edit field visialization
            app.EditField_names_univ_prediction.Value = var_metabolite_to_cell{1}; %Edit field univ_prediction

        end

        % Button pushed function: Button_Example_data_Metabolomics
        function Button_Example_data_MetabolomicsPushed(app, event)
             try 
                 app.dataM = readtable('/data/webapps/input_example/input_G_M_Phe/Metabolites_Ath_6C.csv');
                 app.dataM = rows2vars(app.dataM);
                 app.dataM.Properties.VariableNames = cellstr(app.dataM{1,:});
                 app.dataM(1,:) = []; 
                 app.dataM_no = app.dataM(:, 2:end);
                 app.UITable_Load_MetabolomicsInfo.Data = app.dataM;
                 varHeadTable = app.UITable_Load_MetabolomicsInfo.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITable_Load_MetabolomicsInfo.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = msgbox(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            
            var_metabolite = app.dataM(1,1);
            var_metabolite_to_cell = var_metabolite{1,1};
            app.EditField_Metabolite_name.Value = var_metabolite_to_cell{1}; %Edit field visialization
            app.EditField_names_univ_prediction.Value = var_metabolite_to_cell{1}; %Edit field univ_prediction
        end

        % Button pushed function: Button_Load_Phenotype_Info
        function Button_Load_Phenotype_InfoPushed(app, event)
            [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                 app.dataPhe = readtable(strcat(app.rawFilePath,app.rawFileName));
                 app.UITable_Load_PhenotypeInfo.Data = app.dataPhe;
                 varHeadTable = app.UITable_Load_PhenotypeInfo.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 %app.dataPhe(1,:) = []; 
                 app.dataPhe_no = app.dataPhe(:, 2:end);
                 %app.dataPhe_no = app.dataPhe_no';
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITable_Load_PhenotypeInfo.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = msgbox(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end     
        end

        % Button pushed function: Button_Example_data_Phenotype
        function Button_Example_data_PhenotypePushed(app, event)
             try 
                 app.dataPhe = readtable('/data/webapps/input_example/input_G_M_Phe/Phenotype_Ath_RGR_6.csv');
                 app.UITable_Load_PhenotypeInfo.Data = app.dataPhe;
                 varHeadTable = app.UITable_Load_PhenotypeInfo.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 %app.dataPhe(1,:) = []; 
                 app.dataPhe_no = app.dataPhe(:, 2:end);
                 %app.dataPhe_no = app.dataPhe_no';
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITable_Load_PhenotypeInfo.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = msgbox(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
        end

        % Button pushed function: Button_Load_OthersOMICs
        function Button_Load_OthersOMICsPushed(app, event)
            [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                 app.dataOther = readtable(strcat(app.rawFilePath,app.rawFileName));
                 app.UITable_Load_OtherOMICsInfo.Data = app.dataOther;
                 varHeadTable = app.UITable_Load_OtherOMICsInfo.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 app.dataOther_no = app.dataOther(:, 2:end);
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITable_Load_OtherOMICsInfo.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = msgbox(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end     

        end

        % Button pushed function: Button_Example_data_OthersOMICs
        function Button_Example_data_OthersOMICsPushed(app, event)
             try 
                 app.dataOther = readtable('/data/webapps/input_example/input_G_M_Phe/Phenotype_Ath_RGR_6.csv'); %!!!
                 app.UITable_Load_OtherOMICsInfo.Data = app.dataOther;
                 varHeadTable = app.UITable_Load_OtherOMICsInfo.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 app.dataOther_no = app.dataOther(:, 2:end);
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITable_Load_OtherOMICsInfo.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = msgbox(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end            
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.UIFigure_Main.Visible = 'off'; % Open 2nd instance of app
            PanOMICs_app_dektop_Matlab2023b();    
            close(app.UIFigure_Main) % Delete old instance

        end

        % Button pushed function: HelpButton_1_1_Loading_data
        function HelpButton_1_1_Loading_dataPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'Input', ...
                'This section allows you to upload and specify the data files needed for analysis. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types: genomic information (SNPs), metabolomic data (metabolite concentrations), phenotypic data (trait measurements), and otherOMICs data in matrix format. Properly format your data according to the provided templates [see manual] to avoid errors during processing.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle), 1);
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    % msgbox(helpText{index + 1}, sectionTitle, 'help');
                    uialert(app.UIFigure_Main,'This section allows you to upload and specify the data files needed for analysis. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types: genomic information (SNPs), metabolomic data (metabolite concentrations), and phenotypic data (trait measurements). Properly format your data according to the provided templates to avoid errors during processing.','Input', 'Icon','info')
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            showHelp('Input'); 
        end

        % Button pushed function: Button_See_in_detail_GenomicsInput
        function Button_See_in_detail_GenomicsInputPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.GenomicsInputTab.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.GenomicsInputTab;                
                app.MainTab_PanOMICsApp.Parent = [];
                
                % Set the data of the detailedTable to be the same as app.UITable_Load_GenomicsInfo_Large
                app.UITable_Genomics_input_ZOOM_in.Data = app.UITable_Load_GenomicsInfo.Data;
                
                % Optionally, set column names if not automatically set
                app.UITable_Genomics_input_ZOOM_in.ColumnName = app.UITable_Load_GenomicsInfo.ColumnName;

        end

        % Button pushed function: Button_See_in_detail_MetabolomicsInfo
        function Button_See_in_detail_MetabolomicsInfoPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.MetabolomicsInputTab.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.MetabolomicsInputTab;                
                app.MainTab_PanOMICsApp.Parent = [];            
                
                % Set the data of the detailedTable to be the same as app.UITable_Load_MetabolomicsInfo_Large
                app.UITable_Metabolomics_input_ZOOM_in.Data = app.UITable_Load_MetabolomicsInfo.Data;
                
                % Optionally, set column names if not automatically set
                app.UITable_Metabolomics_input_ZOOM_in.ColumnName = app.UITable_Load_MetabolomicsInfo.ColumnName;
        end

        % Button pushed function: Button_Back_to_MAIN_Genomics
        function Button_Back_to_MAIN_GenomicsPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.MainTab_PanOMICsApp.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.MainTab_PanOMICsApp;                
                app.GenomicsInputTab.Parent = [];    
        end

        % Button pushed function: Button_Back_to_MAIN_Metabolomics
        function Button_Back_to_MAIN_MetabolomicsPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.MainTab_PanOMICsApp.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.MainTab_PanOMICsApp;                
                app.GenomicsInputTab_preprocessed.Parent = [];   
        end

        % Button pushed function: Button_Back_to_MAIN_Phenotype
        function Button_Back_to_MAIN_PhenotypePushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.MainTab_PanOMICsApp.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.MainTab_PanOMICsApp;                
                app.PhenotypeInputTab.Parent = []; 
        end

        % Button pushed function: Button_Back_to_MAIN_OtherOMICs
        function Button_Back_to_MAIN_OtherOMICsPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.MainTab_PanOMICsApp.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.MainTab_PanOMICsApp;                
                app.OtherOMICsInputTab.Parent = [];             
        end

        % Button pushed function: Button_See_in_detail_PhenotypeInfo
        function Button_See_in_detail_PhenotypeInfoPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.PhenotypeInputTab.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.PhenotypeInputTab;                
                app.MainTab_PanOMICsApp.Parent = [];            
                
                % Set the data of the detailedTable to be the same as app.UITable_Load_PhenotypeInfo_Large
                app.UITable_Phenotype_input_ZOOM_in.Data = app.UITable_Load_PhenotypeInfo.Data;
                
                % Optionally, set column names if not automatically set
                app.UITable_Phenotype_input_ZOOM_in.ColumnName = app.UITable_Load_PhenotypeInfo.ColumnName;            
        end

        % Button pushed function: Button_See_in_detail_OtherOMICs
        function Button_See_in_detail_OtherOMICsPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.OtherOMICsInputTab.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.OtherOMICsInputTab;                
                app.MainTab_PanOMICsApp.Parent = [];            
                
                % Set the data of the detailedTable to be the same as app.UITable_Load_OtherOMICsInfo_Large
                app.UITable_OtherOMICs_input_ZOOM_in.Data = app.UITable_Load_OtherOMICsInfo.Data;
                
                % Optionally, set column names if not automatically set
                app.UITable_OtherOMICs_input_ZOOM_in.ColumnName = app.UITable_Load_OtherOMICsInfo.ColumnName;        
        end

        % Value changed function: CheckBox_Automatic_preprocessing
        function CheckBox_Automatic_preprocessingValueChanged(app, event)
           app.automatic_preprocessed_value = app.CheckBox_Automatic_preprocessing.Value;

           if app.automatic_preprocessed_value == 1
                app.already_preprocessed_value = 0; app.CheckBox_Uploaded_preprocessed_data.Value = 0;
           end

           if size(app.dataG,2) == size(app.dataM,2) && (size(app.dataM,2)-1) == size(app.dataPhe,1)
           else 
            %Error - oprav data tak aby se shodovali počty sloupců apod.. 
            uialert(app.UIFigure,'Check your dataset size',"Invalid File","Interpreter","html");
           end

           % genomics data _______________________________________________      
            for i=2:size(app.dataG,2)
                for j=1:size(app.dataG,1)
                    if isnan(table2array(app.dataG(j,i)))
                        app.dataG(j,i)=0;
                    end
                end
            end

            app.UITable_Load_GenomicsInfo_preprocessed.Data = app.dataG;
            varHeadTable = app.UITable_Load_GenomicsInfo_preprocessed.Data;
            varHeadTable.Properties.VariableNames(1) = " ";
            Names_varHeadTable = varHeadTable.Properties.VariableNames;
            app.UITable_Load_GenomicsInfo_preprocessed.ColumnName = Names_varHeadTable;

            % metabolomics data __________________________________________
            for i=1:size(app.dataM,1)
                for j=2:size(app.dataM,2)
                    var1 = table2array(app.dataM(i,2:end));
                    var2 = cell2mat(var1);
                    cellVar = app.dataM{i,j};
                    cellVar{1}=min(var2)/2;
                    app.dataM{i,j} = cellVar;
                end
            end
            
            for j=1:size(app.dataM,1)
                met = app.dataM{j,2:end};
                met_log = log10(abs(cell2mat(met)));
                
                for i = 2:(size(app.dataM,2)-1)
                    cellVar_Met = app.dataM{j,i};
                    cellVar_Met{1}=met_log(1,i);
                    app.dataM{j,i} = cellVar_Met;
                end
            end
            
            % phenotype data _____________________________________________
            for i=1:size(app.dataPhe,1)
                for j=2:size(app.dataPhe,2)
                    if isnan(table2array(app.dataPhe(i,j)))
                        app.dataPhe(i,j)=0;
                    end
                end
            end

            % ____________________________________________________________
            % once pre-processing is done - data is loaded into tables:
            app.UITable_Load_GenomicsInfo_preprocessed.Data = app.dataG;
            varHeadTable = app.UITable_Load_GenomicsInfo_preprocessed.Data;
            varHeadTable.Properties.VariableNames(1) = " ";
            Names_varHeadTable = varHeadTable.Properties.VariableNames;
            app.UITable_Load_GenomicsInfo_preprocessed.ColumnName = Names_varHeadTable;

            app.UITable_Load_MetabolomicsInfo_preprocessed.Data = app.dataM;
            varHeadTable = app.UITable_Load_MetabolomicsInfo_preprocessed.Data;
            varHeadTable.Properties.VariableNames(1) = " ";
            Names_varHeadTable = varHeadTable.Properties.VariableNames;
            app.UITable_Load_MetabolomicsInfo_preprocessed.ColumnName = Names_varHeadTable;

            app.UITable_Load_PhenotypeInfo_preprocessed.Data = app.dataPhe;
            varHeadTable = app.UITable_Load_PhenotypeInfo_preprocessed.Data;
            varHeadTable.Properties.VariableNames(1) = " ";
            app.dataPhe_no = app.dataPhe(:, 2:end);
            Names_varHeadTable = varHeadTable.Properties.VariableNames;
            app.UITable_Load_PhenotypeInfo_preprocessed.ColumnName = Names_varHeadTable;

            app.UITable_Load_PhenotypeInfo_preprocessed.Data = app.dataPhe;
            varHeadTable = app.UITable_Load_PhenotypeInfo_preprocessed.Data;
            varHeadTable.Properties.VariableNames(1) = " ";
            app.dataPhe_no = app.dataPhe(:, 2:end);
            Names_varHeadTable = varHeadTable.Properties.VariableNames;
            app.UITable_Load_PhenotypeInfo_preprocessed.ColumnName = Names_varHeadTable;

            app.UITable_Load_OtherOMICsInfo_preprocessed.Data = app.dataOther;
            varHeadTable = app.UITable_Load_OtherOMICsInfo_preprocessed.Data;
            varHeadTable.Properties.VariableNames(1) = " ";
            app.dataOther_no = app.dataOther(:, 2:end);
            Names_varHeadTable = varHeadTable.Properties.VariableNames;
            app.UITable_Load_OtherOMICsInfo_preprocessed.ColumnName = Names_varHeadTable;
            
        end

        % Value changed function: CheckBox_Uploaded_preprocessed_data
        function CheckBox_Uploaded_preprocessed_dataValueChanged(app, event)
           app.already_preprocessed_value = app.CheckBox_Uploaded_preprocessed_data.Value;

           if app.already_preprocessed_value == 1
                app.automatic_preprocessed_value = 0; app.CheckBox_Automatic_preprocessing.Value = 0;
           end
            
        end

        % Value changed function: CheckBox_Genome_Metabolome
        function CheckBox_Genome_MetabolomeValueChanged(app, event)
            app.CheckBox_Genome_Metabolome_BoxValue = app.CheckBox_Genome_Metabolome.Value;

            if app.CheckBox_Genome_Metabolome_BoxValue == 1
                app.CheckBox_Metabolome_Phenotype_BoxValue = 0; app.CheckBox_Metabolome_Phenotype.Value = 0;
                app.CheckBox_Genome_Phenotype_BoxValue = 0; app.CheckBox_Genome_Phenotype.Value = 0;
                app.CheckBox_Genome_Metabolome_Phenotype_BoxValue = 0; app.CheckBox_Genome_Metabolome_Phenotype.Value = 0;
                app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype_BoxValue = 0; app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype.Value = 0;
                app.CheckBox_OthersOMICs_Phenotype_BoxValue = 0; app.CheckBox_OthersOMICs_Phenotype.Value = 0;
                
                app.CheckBox_LASSO_Value = 1; app.CheckBox_LASSO.Value = 1;
                app.CheckBox_LASSO.Enable ="on";
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable ="on";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable ="on";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable ="on";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable ="on";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable ="on";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable ="on";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable ="on";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable ="on";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0;
                app.CheckBox_CNN.Enable ="on";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable ="on";            
            end

            EditField_names_univ_predictionValueChanged(app)

        end

        % Value changed function: CheckBox_Metabolome_Phenotype
        function CheckBox_Metabolome_PhenotypeValueChanged(app, event)
            app.CheckBox_Metabolome_Phenotype_BoxValue = app.CheckBox_Metabolome_Phenotype.Value;

            if app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                app.CheckBox_Genome_Metabolome_BoxValue = 0; app.CheckBox_Genome_Metabolome.Value = 0;
                app.CheckBox_Genome_Phenotype_BoxValue = 0; app.CheckBox_Genome_Phenotype.Value = 0;
                app.CheckBox_Genome_Metabolome_Phenotype_BoxValue = 0; app.CheckBox_Genome_Metabolome_Phenotype.Value = 0;
                app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype_BoxValue = 0; app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype.Value = 0;
                app.CheckBox_OthersOMICs_Phenotype_BoxValue = 0; app.CheckBox_OthersOMICs_Phenotype.Value = 0;
                
                app.CheckBox_LASSO_Value = 1; app.CheckBox_LASSO.Value = 1;
                app.CheckBox_LASSO.Enable ="on";
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable ="on";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable ="on";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable ="on";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable ="on";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable ="on";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable ="on";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable ="on";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0;
                app.CheckBox_CNN.Enable ="on";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable ="on";            
            end

            EditField_names_univ_predictionValueChanged(app)
            
        end

        % Value changed function: CheckBox_Genome_Phenotype
        function CheckBox_Genome_PhenotypeValueChanged(app, event)
            app.CheckBox_Genome_Phenotype_BoxValue = app.CheckBox_Genome_Phenotype.Value;

            if app.CheckBox_Genome_Phenotype_BoxValue == 1
                app.CheckBox_Genome_Metabolome_BoxValue = 0; app.CheckBox_Genome_Metabolome.Value = 0;
                app.CheckBox_Metabolome_Phenotype_BoxValue = 0; app.CheckBox_Metabolome_Phenotype.Value = 0;
                app.CheckBox_Genome_Metabolome_Phenotype_BoxValue = 0; app.CheckBox_Genome_Metabolome_Phenotype.Value = 0;
                app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype_BoxValue = 0; app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype.Value = 0;
                app.CheckBox_OthersOMICs_Phenotype_BoxValue = 0; app.CheckBox_OthersOMICs_Phenotype.Value = 0;
                
                app.CheckBox_LASSO_Value = 1; app.CheckBox_LASSO.Value = 1;
                app.CheckBox_LASSO.Enable ="on";
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable ="on";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable ="on";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable ="on";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable ="on";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable ="on";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable ="on";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable ="on";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable ="on";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0;
                app.CheckBox_CNN.Enable ="on";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable ="on";            
            end

            EditField_names_univ_predictionValueChanged(app)

        end

        % Value changed function: CheckBox_Genome_Metabolome_Phenotype
        function CheckBox_Genome_Metabolome_PhenotypeValueChanged(app, event)
            app.CheckBox_Genome_Metabolome_Phenotype_BoxValue = app.CheckBox_Genome_Metabolome_Phenotype.Value;

            if app.CheckBox_Genome_Metabolome_Phenotype_BoxValue == 1
                app.CheckBox_Genome_Metabolome_BoxValue = 0; app.CheckBox_Genome_Metabolome.Value = 0;
                app.CheckBox_Metabolome_Phenotype_BoxValue = 0; app.CheckBox_Metabolome_Phenotype.Value = 0;
                app.CheckBox_Genome_Phenotype_BoxValue = 0; app.CheckBox_Genome_Phenotype.Value = 0;
                app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype_BoxValue = 0; app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype.Value = 0;
                app.CheckBox_OthersOMICs_Phenotype_BoxValue = 0; app.CheckBox_OthersOMICs_Phenotype.Value = 0;
                
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable ="off";
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable ="off";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable ="off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable ="off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable ="off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable ="off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable ="off";
                app.CheckBox_RandomForest_Value = 1; app.CheckBox_RandomForest.Value = 1;
                app.CheckBox_RandomForest.Enable ="on";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable ="on";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0;
                app.CheckBox_CNN.Enable ="on";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable ="on";            
            end

            EditField_names_univ_predictionValueChanged(app)

        end

        % Value changed function: 
        % CheckBox_Genome_Metabolome_OtherOMICs_Phenotype
        function CheckBox_Genome_Metabolome_OtherOMICs_PhenotypeValueChanged(app, event)
            app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype_BoxValue = app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype.Value;

            if app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype_BoxValue == 1
                app.CheckBox_Genome_Metabolome_BoxValue = 0; app.CheckBox_Genome_Metabolome.Value = 0;
                app.CheckBox_Metabolome_Phenotype_BoxValue = 0; app.CheckBox_Metabolome_Phenotype.Value = 0;
                app.CheckBox_Genome_Phenotype_BoxValue = 0; app.CheckBox_Genome_Phenotype.Value = 0;
                app.CheckBox_Genome_Metabolome_Phenotype_BoxValue = 0; app.CheckBox_Genome_Metabolome_Phenotype.Value = 0;
                app.CheckBox_OthersOMICs_Phenotype_BoxValue = 0; app.CheckBox_OthersOMICs_Phenotype.Value = 0;
                
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable ="off";
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable ="off";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable ="off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable ="off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable ="off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable ="off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable ="off";
                app.CheckBox_RandomForest_Value = 1; app.CheckBox_RandomForest.Value = 1;
                app.CheckBox_RandomForest.Enable ="on";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable ="on";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0;
                app.CheckBox_CNN.Enable ="on";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable ="on";   
            end

            EditField_names_univ_predictionValueChanged(app)
            
        end

        % Value changed function: CheckBox_OthersOMICs_Phenotype
        function CheckBox_OthersOMICs_PhenotypeValueChanged(app, event)
            app.CheckBox_OthersOMICs_Phenotype_BoxValue = app.CheckBox_OthersOMICs_Phenotype.Value;

            if app.CheckBox_OthersOMICs_Phenotype_BoxValue == 1
                app.CheckBox_Genome_Metabolome_BoxValue = 0; app.CheckBox_Genome_Metabolome.Value = 0;
                app.CheckBox_Metabolome_Phenotype_BoxValue = 0; app.CheckBox_Metabolome_Phenotype.Value = 0;
                app.CheckBox_Genome_Phenotype_BoxValue = 0; app.CheckBox_Genome_Phenotype.Value = 0;
                app.CheckBox_Genome_Metabolome_Phenotype_BoxValue = 0; app.CheckBox_Genome_Metabolome_Phenotype.Value = 0;
                app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype_BoxValue = 0; app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype.Value = 0;
                
                app.CheckBox_LASSO_Value = 1; app.CheckBox_LASSO.Value = 1;
                app.CheckBox_LASSO.Enable ="on";
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable ="on";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable ="on";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable ="on";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable ="on";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable ="on";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable ="on";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable ="on";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable ="on";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0;
                app.CheckBox_CNN.Enable ="on";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable ="on";            
            end

            EditField_names_univ_predictionValueChanged(app)
            
        end

        % Value changed function: EditField_names_univ_prediction
        function EditField_names_univ_predictionValueChanged(app, event)
                     
            if app.CheckBox_Genome_Metabolome_BoxValue == 1
                app.Label_EditField_names_univ_prediction.Text= 'Metabolite';

            elseif app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                app.Label_EditField_names_univ_prediction.Text= 'Phenotype';

            elseif app.CheckBox_Genome_Metabolome_Phenotype_BoxValue == 1               
                app.Label_EditField_names_univ_prediction.Text= 'Phenotype';

            end
           
        end

        % Button pushed function: GWAS_Button
        function GWAS_ButtonPushed(app, event)
            if isempty(app.dataG) || isempty(app.dataPhe)
    
            app.showHelp_GWAS('Warning GWAS');

               return; 
            end
            
            app.TabGroup_Main.Visible = "on";
            app.GWASTab.Parent = app.TabGroup_Main;
            app.TabGroup_Main.SelectedTab = app.GWASTab;                
            app.MainTab_PanOMICsApp.Parent = [];            
                
        end

        % Button pushed function: Button_Backto_to_MAIN_GWAS
        function Button_Backto_to_MAIN_GWASPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.MainTab_PanOMICsApp.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.MainTab_PanOMICsApp;                
                app.GWASTab.Parent = [];    
        end

        % Button pushed function: Button_Back_to_MAIN_PRS
        function Button_Back_to_MAIN_PRSPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.MainTab_PanOMICsApp.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.MainTab_PanOMICsApp;                
                app.PolygenicRiskScoreTab.Parent = []; 
        end

        % Button pushed function: Button_PolygenicRiskScore
        function Button_PolygenicRiskScorePushed(app, event)
                if isempty(app.dataG) || isempty(app.dataPhe)
                   app.showHelp_PRS('Warning PRS');
                   return; 
                end
           
                app.TabGroup_Main.Visible = "on";
                app.PolygenicRiskScoreTab.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.PolygenicRiskScoreTab;                
                app.MainTab_PanOMICsApp.Parent = [];    
        end

        % Value changed function: CheckBox_LASSO
        function CheckBox_LASSOValueChanged(app, event)
            app.CheckBox_LASSO_Value = app.CheckBox_LASSO.Value;

            if app.CheckBox_LASSO_Value == 1
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
            end
        end

        % Value changed function: CheckBox_RR
        function CheckBox_RRValueChanged(app, event)
            app.CheckBox_RR_Value = app.CheckBox_RR.Value;

            if app.CheckBox_RR_Value == 1
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
            end
            
        end

        % Value changed function: CheckBox_ENR
        function CheckBox_ENRValueChanged(app, event)
            app.CheckBox_ENR_Value = app.CheckBox_ENR.Value;

            if app.CheckBox_ENR_Value == 1
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
            end            
        end

        % Value changed function: CheckBox_GPR
        function CheckBox_GPRValueChanged(app, event)
            app.CheckBox_GPR_Value = app.CheckBox_GPR.Value;

            if app.CheckBox_GPR_Value == 1
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
            end
        end

        % Value changed function: CheckBox_SVR
        function CheckBox_SVRValueChanged(app, event)
            app.CheckBox_SVR_Value = app.CheckBox_SVR.Value;

            if app.CheckBox_SVR_Value == 1
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
            end
            
        end

        % Value changed function: CheckBox_PLS
        function CheckBox_PLSValueChanged(app, event)
            app.CheckBox_PLS_Value = app.CheckBox_PLS.Value;

            if app.CheckBox_PLS_Value == 1
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
            end
        end

        % Value changed function: CheckBox_RandomForest
        function CheckBox_RandomForestValueChanged(app, event)
            app.CheckBox_RandomForest_Value = app.CheckBox_RandomForest.Value;

            if app.CheckBox_RandomForest_Value == 1
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
            end
            
        end

        % Value changed function: CheckBox_fullyconnected_NN
        function CheckBox_fullyconnected_NNValueChanged(app, event)
            app.CheckBox_fullyconnected_NN_Value = app.CheckBox_fullyconnected_NN.Value;

            if app.CheckBox_fullyconnected_NN_Value == 1
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
            end
            
        end

        % Value changed function: CheckBox_CNN
        function CheckBox_CNNValueChanged(app, event)
            app.CheckBox_CNN_Value = app.CheckBox_CNN.Value;

            if app.CheckBox_CNN_Value == 1
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0; 
            end
            
        end

        % Value changed function: CheckBox_LSTM
        function CheckBox_LSTMValueChanged(app, event)
            app.CheckBox_LSTM_Value = app.CheckBox_LSTM.Value;

            if app.CheckBox_CNN_Value == 1
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0;
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0; 
            end
        end

        % Button pushed function: Button_perform_univariate_prediction
        function Button_perform_univariate_predictionPushed(app, event)
            app.univar = 1; app.multivar = 0;
            
            app.dataG_no = table2array(app.dataG(:, 2:end));
            app.dataM_no = table2array(app.dataM(:, 2:end));

            app.i_UniVarSample = app.Spinner_index_univariate_prediction.Value;
            UnivariatePrediction(app);

           %MSE
           app.EditField_MSE_univariate_prediction.Value = double(mse(app.Orig_Val,app.Pred_Val));
           %MAE
           app.EditField_MAE_univariate_prediction.Value = double(mae(app.Orig_Val,app.Pred_Val));
           %CC
           if app.CheckBox_Metabolome_Phenotype_BoxValue == 1
               if isnan(corr(app.Orig_Val,app.Pred_Val))
                app.EditField_cc_univariate_prediction.Value = 0;
               else
                app.EditField_cc_univariate_prediction.Value = double(corr(app.Orig_Val,app.Pred_Val));
               end
               app.Pred_Val= app.Pred_Val;
           else
            if app.CheckBox_PLS_Value == 1
                app.EditField_cc_univariate_prediction.Value = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
            else
                app.EditField_cc_univariate_prediction.Value = double(corr(app.Orig_Val,app.Pred_Val));
            end
           end
           %Boxplot
%           if app.MetabolomePhenotypeCheckBoxValue == 1
%            boxplot(app.UniBoxPlot, [app.Orig_Val',app.Pred_Val'],{'Original','Predicted'});
%           else
            boxplot(app.UIAxes_Univariate_prediction, [app.Orig_Val,app.Pred_Val],{'Original','Predicted'});
%           end


        end

        % Value changed function: Spinner_index_univariate_prediction
        function Spinner_index_univariate_predictionValueChanged(app, event)
 
            % -- input G->M (so far)
            if app.CheckBox_Genome_Metabolome_BoxValue == 1

                if app.Spinner_index_univariate_prediction.Value < 1
                    app.Spinner_index_univariate_prediction.Value = 1;
                    app.i_UniVarSample = app.Spinner_index_univariate_prediction.Value;
                    var_metabolite = app.dataM(app.i_UniVarSample,1);
                    var_metabolite_to_cell = var_metabolite{1,1};
                    app.EditField_names_univ_prediction.Value = var_metabolite_to_cell{1};
                else
                    app.i_UniVarSample = app.Spinner_index_univariate_prediction.Value;
                    var_metabolite = app.dataM(app.i_UniVarSample,1);
                    var_metabolite_to_cell = var_metabolite{1,1};
                    app.EditField_names_univ_prediction.Value = var_metabolite_to_cell{1};
                end
                
                app.Label_EditField_names_univ_prediction.Text = 'Metabolite';

            elseif app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                app.i_UniVarSample = app.Spinner_index_univariate_prediction.Value;
                var_pheno = app.dataPhe.Properties.VariableNames(app.i_UniVarSample+1);
                var_pheno_to_cell = var_pheno{1,1};
                app.EditField_names_univ_prediction.Value = var_pheno_to_cell;
                
                app.Label_EditField_names_univ_prediction.Text = 'Phenotype';

            elseif app.CheckBox_Genome_Metabolome_Phenotype_BoxValue == 1
                app.i_UniVarSample = app.Spinner_index_univariate_prediction.Value;
                var_pheno = app.dataPhe.Properties.VariableNames(app.i_UniVarSample+1);
                var_pheno_to_cell = var_pheno{1,1};
                app.EditField_names_univ_prediction.Value = var_pheno_to_cell;
                
                app.Label_EditField_names_univ_prediction.Text = 'Phenotype';


            end
        end

        % Button pushed function: Button_perform_multivariate_prediction
        function Button_perform_multivariate_predictionPushed(app, event)
            app.univar = 0; app.multivar = 1;
                  
            if ~isempty(app.dataG)
            app.dataG_no = table2array(app.dataG(:, 2:end));
            end

            if ~isempty(app.dataM) 
            app.dataM_no = table2array(app.dataM(:, 2:end));
            
            UniPred_M_var = app.dataM_no(:,:); %... add comment fixing outputs
            UniPred_M_var = cell2mat(UniPred_M_var);
            UniPred_M_var = UniPred_M_var';

            end

            if ~isempty(app.dataPhe)
            dataPhe_no_var = table2cell(app.dataPhe(:, 2:end));
            dataPhe_no_var = cell2mat(dataPhe_no_var);
            UniPred_P_var = dataPhe_no_var';
            end

            if app.CheckBox_Genome_Metabolome_BoxValue == 1
                app.y_output_var = UniPred_M_var;
            elseif app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                app.y_output_var = UniPred_P_var;
            elseif app.CheckBox_Genome_Phenotype_BoxValue == 1
                app.y_output_var = UniPred_P_var;
            elseif app.CheckBox_Genome_Metabolome_Phenotype_BoxValue == 1
                app.y_output_var = UniPred_P_var;
            elseif app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype_BoxValue == 1
                app.y_output_var = UniPred_P_var;
            elseif app.CheckBox_OthersOMICs_Phenotype_BoxValue == 1
                app.y_output_var = UniPred_P_var;
            end

           if size(app.y_output_var,1) == 1
                %If you have only one column in Phenotype – do UnivPred
                app.i_UniVarSample = app.Spinner_index_univariate_prediction.Value;
                UnivariatePrediction(app);
    
                %MSE
                app.EditField_MSE_multivariate_prediction.Value = double(mse(app.Orig_Val,app.Pred_Val));
                %MAE
                app.EditField_MAE_multivariate_prediction.Value = double(mae(app.Orig_Val,app.Pred_Val));
                %CC
                if app.CheckBox_Metabolome_Phenotype_BoxValue == 1
                    if isnan(corr(app.Orig_Val,app.Pred_Val))
                        app.EditField_cc_multivariate_prediction.Value = 0;
                    else
                        app.EditField_cc_multivariate_prediction.Value = double(corr(app.Orig_Val,app.Pred_Val));
                    end
                    app.Pred_Val= app.Pred_Val;
                else
                    if app.CheckBox_PLS_Value == 1
                        app.EditField_cc_multivariate_prediction.Value = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
                    else
                        app.EditField_cc_multivariate_prediction.Value = double(corr(app.Orig_Val,app.Pred_Val));
                    end
                end
                %Boxplot
                %if app.MetabolomePhenotypeCheckBoxValue == 1
                %    boxplot(app.MultiBoxPlot, [app.Orig_Val',app.Pred_Val'],{'Original','Predicted'});
                %else
                    boxplot(app.UIAxes_Multivariate_prediction, [app.Orig_Val,app.Pred_Val],{'Original','Predicted'});
                %end

         else
               MultivariatePrediction(app);
    
               %MSE
               app.EditField_MSE_multivariate_prediction.Value = double(mse(app.Orig_Val,app.Pred_Val));
               %MAE
               app.EditField_MAE_multivariate_prediction.Value = double(mae(app.Orig_Val,app.Pred_Val));
               %CC
               if app.CheckBox_PLS_Value == 1
                app.EditField_cc_multivariate_prediction.Value = double((mean(mean(corr(app.Orig_Val,app.Pred_Val)))));
               else
                if size(app.Orig_Val,1) == 1
                    app.EditField_cc_multivariate_prediction.Value = double(corr(app.Orig_Val',app.Pred_Val));
                else
                    app.EditField_cc_multivariate_prediction.Value = double(corr(app.Orig_Val,app.Pred_Val));
                end
               end
               %Boxplot
               if size(app.Orig_Val,2) == 1
                  boxplot(app.UIAxes_Multivariate_prediction, [app.Orig_Val,app.Pred_Val],{'Original','Predicted'});
               else
                  boxplot(app.UIAxes_Multivariate_prediction, [mean(app.Orig_Val)',mean(app.Pred_Val)'],{'Original','Predicted'});
               end
           end
        end

        % Button pushed function: Button_Histogram
        function Button_HistogramPushed(app, event)
            app.dataM_no = table2array(app.dataM(:, 2:end));
            updateHistPlot(app);
        end

        % Value changed function: Spinner_Metabolites_index
        function Spinner_Metabolites_indexValueChanged(app, event)
            if app.Spinner_Metabolites_index.Value < 1
                app.Spinner_Metabolites_index.Value = 1;
                app.i_histplotSample = app.Spinner_Metabolites_index.Value;
                var_metabolite = app.dataM(app.i_histplotSample,1);
                var_metabolite_to_cell = var_metabolite{1,1};
                app.EditField_Metabolite_name.Value = var_metabolite_to_cell{1};
                updateHistPlot(app);
            else
                app.i_histplotSample = app.Spinner_Metabolites_index.Value;
                var_metabolite = app.dataM(app.i_histplotSample,1);
                var_metabolite_to_cell = var_metabolite{1,1};
                app.EditField_Metabolite_name.Value = var_metabolite_to_cell{1};
                updateHistPlot(app);
            end

            EditField_Metabolite_nameValueChanged(app)
        end

        % Button pushed function: Button_PCA
        function Button_PCAPushed(app, event)
            updatePCAPlot(app);

        end

        % Button pushed function: Button_BoxPlots
        function Button_BoxPlotsPushed(app, event)
            updateBoxPlot(app);

        end

        % Button pushed function: Button_Load_SNPs_data
        function Button_Load_SNPs_dataPushed(app, event)
            [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                 %toto je zde divne%%%
                 app.InfoSNP = readtable(strcat(app.rawFilePath,app.rawFileName));
                 %toto je zde divne%%%
                 app.UITable_Input_SNPsINFO_data.Data = app.InfoSNP;
                 varHeadTable = app.UITable_Input_SNPsINFO_data.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITable_Input_SNPsINFO_data.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = msgbox(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end
            
        end

        % Button pushed function: Button_Example_data_GWAS
        function Button_Example_data_GWASPushed(app, event)
              try 
                 app.InfoSNP = readtable('/data/webapps/input_example/GWAS_Ath/snp_data_with_locus_tag_FINAL.csv');
                 app.UITable_Input_SNPsINFO_data.Data = app.InfoSNP;
                 varHeadTable = app.UITable_Input_SNPsINFO_data.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITable_Input_SNPsINFO_data.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = msgbox(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
        end

        % Button pushed function: Button_Calculate
        function Button_CalculatePushed(app, event)
            app.Geno = app.dataG; % Genomic data
            app.Phe = app.dataPhe; % Phenotype data

            %% treatment of the transposition matrix
             % Extracting column names from the app.Geno table and
             % preprocessed
            column_names = app.Geno.Properties.VariableNames;
            column_names(1) = [];
            text_column = app.Geno{:,1};
            text_column = cellstr(text_column);

            numeric_columns = app.Geno{:,2:end};
            transposed_numeric_data = numeric_columns';
            transposed_data = array2table(transposed_numeric_data);
            transposed_data.Properties.VariableNames = text_column';
            transposed_column_names = column_names';
            combined_data = [transposed_column_names, num2cell(transposed_numeric_data)];
            new = cell2table(combined_data);
            new.Properties.VariableNames = ['ColumnNames', text_column'];

            % Setting column names
            new.Properties.VariableNames{1} = app.Phe.Properties.VariableNames{1};
            app.Phe.Properties.VariableNames{2} = 'phenotype';
            data = outerjoin(new, app.Phe, 'MergeKeys', true);
            
            % data pre-process/formatting
            genotype_data_columns = data.Properties.VariableNames;
            genotype_data_columns = genotype_data_columns(~strcmp(genotype_data_columns, data.Properties.VariableNames{1}));
            
            % inicialization of results table
            results = table();
            
            for i = 1:length(genotype_data_columns)
                snp = genotype_data_columns{i};
                x = data.(snp);
                y = data.phenotype;
                
                % add intercept into matrix
                data.Intercept = ones(size(x));
                data.SNP = x;
                
                % Fit mixed linear model
                formula = 'phenotype ~ SNP + (1|Intercept)';
                lme = fitlme(data, formula);
                
                % Získání odhadnutých koeficientů
                coef = fixedEffects(lme);
                beta = coef(2); % Odhadnutý koeficient pro SNP
                
                % Získání p-hodnoty
                stats = anova(lme, 'DFMethod', 'Satterthwaite');
                p_value = stats.pValue(2); % p-value pro efekt SNP
                
                % Uložení výsledků
                results = [results; table({snp}, p_value, beta, 'VariableNames', {'SNP', 'p_value', 'beta'})];
            end
            
            % Přidání informací o SNP a výpočty pro Manhattan plot
            app.InfoSNP.Properties.VariableNames{1} = 'SNP';
            results(end, :) = [];
            results.SNP = app.InfoSNP.SNP;
            manhattan_data = innerjoin(results(1:size(app.InfoSNP, 1), :), app.InfoSNP(:, {'SNP', 'Chromosome', 'Position'}), 'Keys', 'SNP');
            
            % Vypsání výsledků do UITable
            app.UITable_Results_GWAS.Data = results;
            app.UITable_Results_GWAS.ColumnName = results.Properties.VariableNames;

            % Výpočet -log10(p-value)
            manhattan_data.NegLogP = -log10(manhattan_data.p_value);
            
            % Výpočet Bonferroniho korekce
            num_tests = length(genotype_data_columns);
            bonferroni_threshold = 0.05 / num_tests;
            bonferroni_threshold_log = -log10(bonferroni_threshold);
            
            % Plot Manhattan plot
            colors = hsv(length(unique(manhattan_data.Chromosome)));  % Define colors for each chromosome
            cumulative_max_position = 0;  % Initialize cumulative maximum position
            hold(app.UIAxes_GWAS, 'on');
            for chr = unique(manhattan_data.Chromosome)'
                chr_data = manhattan_data(manhattan_data.Chromosome == chr, :);
                scatter(app.UIAxes_GWAS, chr_data.Position + cumulative_max_position, chr_data.NegLogP, 20, colors(chr, :), 'filled', 'DisplayName', ['Chromosome ' num2str(chr)]);
                cumulative_max_position = cumulative_max_position + max(chr_data.Position);  % Update cumulative maximum position
            end
            
            % Plot Bonferroni correction line
            yline(app.UIAxes_GWAS, bonferroni_threshold_log, 'r--', 'LineWidth', 2, 'DisplayName', 'Bonferroni Threshold');
            
            hold(app.UIAxes_GWAS, 'off');
            xlabel(app.UIAxes_GWAS, 'Genomic Position');
            ylabel(app.UIAxes_GWAS, '-log10(P-Value)');
            title(app.UIAxes_GWAS, 'Manhattan Plot for GWAS');
            legend(app.UIAxes_GWAS, 'Location', 'bestoutside');

        end

        % Button pushed function: Button_Load_control_group
        function Button_Load_control_groupPushed(app, event)
             [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                 app.control_data = readtable(strcat(app.rawFilePath,app.rawFileName));
                 app.UITable_Input_control_group.Data = app.control_data;
                 varHeadTable = app.UITable_Input_control_group.Data;
                 varHeadTable.Properties.VariableNames(1) = "ID";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITable_Input_control_group.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = msgbox(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end               

        end

        % Button pushed function: Button_Load_case_group
        function Button_Load_case_groupPushed(app, event)
            [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                  app.case_data = readtable(strcat(app.rawFilePath,app.rawFileName));
                  app.UITable_Input_case_group.Data = app.case_data;
                 varHeadTable = app.UITable_Input_case_group.Data;
                 varHeadTable.Properties.VariableNames(1) = "ID";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITable_Input_case_group.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = msgbox(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end  
        end

        % Button pushed function: 
        % Button_See_in_detail_GenomicsInput_preprocessed
        function Button_See_in_detail_GenomicsInput_preprocessedPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.GenomicsInputTab_preprocessed.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.GenomicsInputTab_preprocessed;                
                app.MainTab_PanOMICsApp.Parent = [];
                
                % Set the data of the detailedTable to be the same as app.UITable_Load_GenomicsInfo_preprocessed_Large
                app.UITable_Genomics_input_ZOOM_in_preprocessed.Data = app.UITable_Load_GenomicsInfo_preprocessed.Data;
                
                % Optionally, set column names if not automatically set
                app.UITable_Genomics_input_ZOOM_in_preprocessed.ColumnName = app.UITable_Load_GenomicsInfo_preprocessed.ColumnName;
        end

        % Button pushed function: 
        % Button_See_in_detail_MetabolomicsInfo_preprocessed
        function Button_See_in_detail_MetabolomicsInfo_preprocessedPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.MetabolomicsInputTab_preprocessed.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.MetabolomicsInputTab_preprocessed;                
                app.MainTab_PanOMICsApp.Parent = [];            
                
                % Set the data of the detailedTable to be the same as app.UITable_Load_MetabolomicsInfo_preprocessed_Large
                app.UITable_Metabolomics_input_ZOOM_in_preprocessed.Data = app.UITable_Load_MetabolomicsInfo_preprocessed.Data;
                
                % Optionally, set column names if not automatically set
                app.UITable_Metabolomics_input_ZOOM_in_preprocessed.ColumnName = app.UITable_Load_MetabolomicsInfo_preprocessed.ColumnName;
        end

        % Button pushed function: 
        % Button_See_in_detail_PhenotypeInfo_preprocessed
        function Button_See_in_detail_PhenotypeInfo_preprocessedPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.PhenotypeInputTab_preprocessed.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.PhenotypeInputTab_preprocessed;                
                app.MainTab_PanOMICsApp.Parent = [];            
                
                % Set the data of the detailedTable to be the same as app.UITable_Load_PhenotypeInfo_preprocessed_Large
                app.UITable_Phenotype_input_ZOOM_in_preprocessed.Data = app.UITable_Load_PhenotypeInfo_preprocessed.Data;
                
                % Optionally, set column names if not automatically set
                app.UITable_Phenotype_input_ZOOM_in_preprocessed.ColumnName = app.UITable_Load_PhenotypeInfo_preprocessed.ColumnName;  
        end

        % Button pushed function: 
        % Button_See_in_detail_OtherOMICs_preprocessed
        function Button_See_in_detail_OtherOMICs_preprocessedPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.OtherOMICsInputTab_preprocessed.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.OtherOMICsInputTab_preprocessed;                
                app.MainTab_PanOMICsApp.Parent = [];            
                
                % Set the data of the detailedTable to be the same as app.UITable_Load_OtherOMICsInfo_preprocessed_Large
                app.UITable_OtherOMICs_input_ZOOM_in_preprocessed.Data = app.UITable_Load_OtherOMICsInfo_preprocessed.Data;
                
                % Optionally, set column names if not automatically set
                app.UITable_OtherOMICs_input_ZOOM_in_preprocessed.ColumnName = app.UITable_Load_OtherOMICsInfo_preprocessed.ColumnName;  
        end

        % Button pushed function: Button_Back_to_MAIN_Genomics_preprocessed
        function Button_Back_to_MAIN_Genomics_preprocessedPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.MainTab_PanOMICsApp.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.MainTab_PanOMICsApp;                
                app.GenomicsInputTab_preprocessed.Parent = [];   
        end

        % Button pushed function: 
        % Button_Back_to_MAIN_Metabolomics_preprocessed
        function Button_Back_to_MAIN_Metabolomics_preprocessedPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.MainTab_PanOMICsApp.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.MainTab_PanOMICsApp;                
                app.MetabolomicsInputTab_preprocessed.Parent = [];   
        end

        % Button pushed function: 
        % Button_Back_to_MAIN_Phenotype_preprocessed
        function Button_Back_to_MAIN_Phenotype_preprocessedPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.MainTab_PanOMICsApp.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.MainTab_PanOMICsApp;                
                app.PhenotypeInputTab_preprocessed.Parent = [];               
        end

        % Button pushed function: 
        % Button_Back_to_MAIN_OtherOMICs_preprocessed
        function Button_Back_to_MAIN_OtherOMICs_preprocessedPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.MainTab_PanOMICsApp.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.MainTab_PanOMICsApp;                
                app.OtherOMICsInputTab_preprocessed.Parent = [];                  
        end

        % Button pushed function: HelpButton_1_1_Loading_data_preprocessed
        function HelpButton_1_1_Loading_data_preprocessedPushed(app, event)
           % Create a cell array to store the help text for each section
            helpText = {
                'Input', ...
                'This section performs automatic preprocessing of the uploaded data. During processing, missing values are handled, metabolomics data are adjusted (missing values replaced and log-transformed), and consistency across genomics, metabolomics, and phenotypic datasets is ensured. Once complete, all data are loaded into organized tables ready for further analysis.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle), 1);
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    % msgbox(helpText{index + 1}, sectionTitle, 'help');
                    uialert(app.UIFigure_Main,'This section performs automatic preprocessing of the uploaded data. During processing, missing values are handled, metabolomics data are adjusted (missing values replaced and log-transformed), and consistency across genomics, metabolomics, and phenotypic datasets is ensured. Once complete, all data are loaded into organized tables ready for further analysis.','Input', 'Icon','info')
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            showHelp('Input');
        end

        % Value changed function: EditField_Metabolite_name
        function EditField_Metabolite_nameValueChanged(app, event)
            %value = app.EditField_Metabolite_name.Value;
            %app.EditField_Metabolite_name.Text = 'Metabolite';

        end

        % Button pushed function: HelpButton_Choose_data
        function HelpButton_Choose_dataPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'Choose data', ...
                'This section allows you to choose the type of data (Genome, Metabolome, Phenotype, or Other) that you want to use as input for PCA and box plot visualizations.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle), 1);
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    % msgbox(helpText{index + 1}, sectionTitle, 'help');
                    uialert(app.UIFigure_Main,'This section allows you to choose the type of data (Genome, Metabolome, Phenotype, or Other) that you want to use as input for PCA and box plot visualizations.','Visualization – choose data', 'Icon','info')
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            % Example of how to use the function for a specific section
            showHelp('Choose data');  % Replace 'Input' with the title of the section you want help for 
        end

        % Button pushed function: HELPButton_Panel_Genomics_input
        function HELPButton_Panel_Genomics_inputPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'Genome info', ...
                'You can see your uploaded dataset. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types: genomic information (SNPs), as SNP data matrix.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle), 1);
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    % msgbox(helpText{index + 1}, sectionTitle, 'help');
                    uialert(app.UIFigure_Main,'You can see your uploaded dataset. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types: genomic information (SNPs), as SNP data matrix.','Input', 'Icon','info')
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            showHelp('Genome info');         
        end

        % Button pushed function: HELPButton_Panel_Metabolomics_input
        function HELPButton_Panel_Metabolomics_inputPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'Metabolome info', ...
                'You can see your uploaded dataset. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types: metabolite information, as data matrix.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle), 1);
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    % msgbox(helpText{index + 1}, sectionTitle, 'help');
                    uialert(app.UIFigure_Main,'You can see your uploaded dataset. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types: metabolite information, as data matrix.','Input', 'Icon','info')
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            showHelp('Metabolome info');         
        end

        % Button pushed function: HELPButton_Panel_Phenotype_input
        function HELPButton_Panel_Phenotype_inputPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'Phenotype info', ...
                'You can see your uploaded dataset. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types: phenotype information, as vector or data matrix.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle), 1);
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    % msgbox(helpText{index + 1}, sectionTitle, 'help');
                    uialert(app.UIFigure_Main,'You can see your uploaded dataset. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types: phenotype information, as vector or data matrix.','Input', 'Icon','info')
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end

            showHelp('Phenotype info');     
        end

        % Button pushed function: HELPButton_Panel_OtherOMICs_input
        function HELPButton_Panel_OtherOMICs_inputPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'OtherOMICs info', ...
                'You can see your uploaded dataset. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types:otherOMICs information, as vector or data matrix.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle), 1);
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    % msgbox(helpText{index + 1}, sectionTitle, 'help');
                    uialert(app.UIFigure_Main,'You can see your uploaded dataset. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types: otherOMICs information, as vector or data matrix.','Input', 'Icon','info')
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end

            showHelp('OtherOMICs info');                 
        end

        % Button pushed function: 
        % HELPButton_Panel_Genomics_input_preprocessed
        function HELPButton_Panel_Genomics_input_preprocessedPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'Processed genome info', ...
                'You can see your processed dataset. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types: genomic information (SNPs), as SNP data matrix.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle), 1);
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    % msgbox(helpText{index + 1}, sectionTitle, 'help');
                    uialert(app.UIFigure_Main,'You can see your processed uploaded dataset. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types: genomic information (SNPs), as SNP data matrix.','Input', 'Icon','info')
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            showHelp('Processed genome info'); 
        end

        % Button pushed function: 
        % HELPButton_Panel_Metabolomics_input_preprocessed
        function HELPButton_Panel_Metabolomics_input_preprocessedPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'Processed metabolome info', ...
                'You can see your uploaded processed dataset. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types: metabolite information, as data matrix.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle), 1);
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    % msgbox(helpText{index + 1}, sectionTitle, 'help');
                    uialert(app.UIFigure_Main,'You can see your uploaded processed dataset. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types: metabolite information, as data matrix.','Input', 'Icon','info')
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            showHelp('Processed metabolome info');   
        end

        % Button pushed function: 
        % HELPButton_Panel_Phenotype_input_preprocessed
        function HELPButton_Panel_Phenotype_input_preprocessedPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'Processed phenotype info', ...
                'You can see your uploaded processed dataset. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types: processed phenotype information, as vector or data matrix.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle), 1);
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    % msgbox(helpText{index + 1}, sectionTitle, 'help');
                    uialert(app.UIFigure_Main,'You can see your uploaded processed dataset. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types: processed phenotype information, as vector or data matrix.','Input', 'Icon','info')
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end

            showHelp('Processed phenotype info'); 
        end

        % Button pushed function: 
        % HELPButton_Panel_OtherOMICs_input_preprocessed
        function HELPButton_Panel_OtherOMICs_input_preprocessedPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'OtherOMICs info', ...
                'You can see your uploaded processed dataset. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types:otherOMICs information, as vector or data matrix.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle), 1);
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    % msgbox(helpText{index + 1}, sectionTitle, 'help');
                    uialert(app.UIFigure_Main,'You can see your uploaded processed dataset. Supported file formats include CSV and Excel. Ensure that your files contain the necessary data types: otherOMICs information, as vector or data matrix.','Input', 'Icon','info')
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end

            showHelp('OtherOMICs info');    
        end

        % Button pushed function: Button_See_in_detail_Histogram
        function Button_See_in_detail_HistogramPushed(app, event)
            app.TabGroup_Main.Visible = "on";
            app.SectionVisualization_HistogramTab.Parent = app.TabGroup_Main;
            app.TabGroup_Main.SelectedTab = app.SectionVisualization_HistogramTab;                
            app.MainTab_PanOMICsApp.Parent = [];
                
            h = app.UIAxes_Histogram.Children;
            cla(app.UIAxes_HistogramTab);
            histogram(app.UIAxes_HistogramTab, h.Data, ...
                'BinEdges', h.BinEdges);
                
           app.UIAxes_HistogramTab.XLabel.String = app.UIAxes_Histogram.XLabel.String;
           app.UIAxes_HistogramTab.YLabel.String = app.UIAxes_Histogram.YLabel.String;


        end

        % Button pushed function: Button_Back_to_MAIN_HistogramTab
        function Button_Back_to_MAIN_HistogramTabPushed(app, event)
            app.TabGroup_Main.Visible = "on";
            app.MainTab_PanOMICsApp.Parent = app.TabGroup_Main;
            app.TabGroup_Main.SelectedTab = app.MainTab_PanOMICsApp;                
            app.SectionVisualization_HistogramTab.Parent = [];  

        end

        % Button pushed function: Button_Back_to_MAIN_PCATab
        function Button_Back_to_MAIN_PCATabPushed(app, event)
            app.TabGroup_Main.Visible = "on";
            app.MainTab_PanOMICsApp.Parent = app.TabGroup_Main;
            app.TabGroup_Main.SelectedTab = app.MainTab_PanOMICsApp;                
            app.SectionVisualization_PCATab.Parent = [];  
        end

        % Callback function
        function Button_Back_to_MAIN_OtherOMICs_BoxPlotTabPushed(app, event)
            app.TabGroup_Main.Visible = "on";
            app.MainTab_PanOMICsApp.Parent = app.TabGroup_Main;
            app.TabGroup_Main.SelectedTab = app.MainTab_PanOMICsApp;                
            app.SectionVisualization_BoxPlotTab.Parent = [];
        end

        % Button pushed function: Button_See_in_detail_PCA
        function Button_See_in_detail_PCAPushed(app, event)
            app.TabGroup_Main.Visible = "on";
            app.SectionVisualization_PCATab.Parent = app.TabGroup_Main;
            app.TabGroup_Main.SelectedTab = app.SectionVisualization_PCATab;                
            app.MainTab_PanOMICsApp.Parent = [];
                
            h = findobj(app.UIAxes_PCA, 'Type', 'scatter');

            cla(app.UIAxes_PCATab);
            
            scatter(app.UIAxes_PCATab, h.XData, h.YData, ...
                h.SizeData, h.CData, 'filled');
            
            % axies:
            app.UIAxes_PCATab.XLabel.String = app.UIAxes_PCA.XLabel.String;
            app.UIAxes_PCATab.YLabel.String = app.UIAxes_PCA.YLabel.String;
        end

        % Button pushed function: Button_See_in_detail_BoxPlots
        function Button_See_in_detail_BoxPlotsPushed(app, event)
            app.TabGroup_Main.Visible = "on";
            app.SectionVisualization_BoxPlotTab.Parent = app.TabGroup_Main;
            app.TabGroup_Main.SelectedTab = app.SectionVisualization_BoxPlotTab;                
            app.MainTab_PanOMICsApp.Parent = [];


            if app.ButtonGenome.Value == 1
            app.dataG_no = table2array(app.dataG(:, 2:end));
            boxplot(app.UIAxes_BoxPlotTab, cell2mat({app.dataG_no}));
            elseif  app.ButtonMetabolome.Value == 1
            app.dataM_no = table2array(app.dataM(:, 2:end));
            boxplot(app.UIAxes_BoxPlotTab, cell2mat(app.dataM_no)');
            elseif  app.ButtonPhenotype.Value == 1
            app.dataPhe_no = table2array(app.dataPhe(:, 2:end));
                if any(size(app.dataPhe_no) == 1)
                    boxplot(app.UIAxes_BoxPlotTab, [app.dataPhe_no]);
                else
                    boxplot(app.UIAxes_BoxPlotTab, cell2mat(app.dataPhe_no));
                end  

            elseif  app.ButtonOtherOMICs.Value == 1
            app.dataOther_no = table2array(app.dataOther(:, 2:end));

                if any(size(app.dataOther_no) == 1)
                    boxplot(app.UIAxes_BoxPlotTab, [app.dataOther_no]);
                else
                    boxplot(app.UIAxes_BoxPlotTab, cell2mat(app.dataOther_no));
                end          
            end

        end

        % Button pushed function: HelpButton_Insight_into_metabolites
        function HelpButton_Insight_into_metabolitesPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'Histogram', ...
                'This section displays a histogram of metabolite concentration distributions. The histogram provides a quick overview of the value distribution, helping to identify skewness, outliers, and the overall spread of metabolite concentrations. It also allows users to visually assess the effect of preprocessing steps, such as normalization or log transformation, before proceeding with further analyses.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle), 1);
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    % msgbox(helpText{index + 1}, sectionTitle, 'help');
                    uialert(app.UIFigure_Main,'This section displays a histogram of metabolite concentration distributions. The histogram provides a quick overview of the value distribution, helping to identify skewness, outliers, and the overall spread of metabolite concentrations. It also allows users to visually assess the effect of preprocessing steps, such as normalization or log transformation, before proceeding with further analyses.','Histogram', 'Icon','info')
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            showHelp('Histogram');
        end

        % Button pushed function: HelpButton_Visualization_PCA_BoxPlots
        function HelpButton_Visualization_PCA_BoxPlotsPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'PCA_BoxPlot', ...
                'This section presents PCA and boxplot visualizations to support exploratory data analysis across different omics layers. Principal Component Analysis (PCA) is particularly suitable for genomic data, where it helps reduce dimensionality and reveal global patterns, sample clustering, and sources of variability. In contrast, boxplots are more appropriate for metabolomics data, as they effectively illustrate the distribution, variability, and outliers of metabolite concentrations. Users are advised to select the visualization method according to the data type to ensure meaningful interpretation of results.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle), 1);
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    % msgbox(helpText{index + 1}, sectionTitle, 'help');
                    uialert(app.UIFigure_Main,'This section presents PCA and boxplot visualizations to support exploratory data analysis across different omics layers. Principal Component Analysis (PCA) is particularly suitable for genomic data, where it helps reduce dimensionality and reveal global patterns, sample clustering, and sources of variability. In contrast, boxplots are more appropriate for metabolomics data, as they effectively illustrate the distribution, variability, and outliers of metabolite concentrations. Users are advised to select the visualization method according to the data type to ensure meaningful interpretation of results.','PCA_BoxPlot', 'Icon','info')
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            showHelp('PCA_BoxPlot');
        end

        % Button pushed function: HELPButton_Panel_HistogramTab
        function HELPButton_Panel_HistogramTabPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'Histogram_ZOOM_in', ...
                'This section provides a zoomed-in view of the histogram of metabolite concentrations. The enlarged (zoom-in) visualization allows users to examine finer details of the distribution, such as subtle changes in frequency, local peaks, and the presence of low-abundance or high-abundance metabolites. This detailed view supports a more precise assessment of data distribution and the impact of preprocessing steps before downstream analysis.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle), 1);
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    % msgbox(helpText{index + 1}, sectionTitle, 'help');
                    uialert(app.UIFigure_Main,'This section provides a zoomed-in view of the histogram of metabolite concentrations. The enlarged (zoom-in) visualization allows users to examine finer details of the distribution, such as subtle changes in frequency, local peaks, and the presence of low-abundance or high-abundance metabolites. This detailed view supports a more precise assessment of data distribution and the impact of preprocessing steps before downstream analysis.','Histogram_ZOOM_in', 'Icon','info')
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            showHelp('Histogram_ZOOM_in');            
        end

        % Button pushed function: HELPButton_Panel_PCATab
        function HELPButton_Panel_PCATabPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'PCA_ZOOM_in', ...
                'This section focuses on Principal Component Analysis (PCA) as a tool for exploring high-dimensional data. The visualization helps identify global patterns, sample clustering, and potential outliers, enabling users to gain insight into the underlying structure of the data before applying more advanced analyses.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle), 1);
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    % msgbox(helpText{index + 1}, sectionTitle, 'help');
                    uialert(app.UIFigure_Main,'This section focuses on Principal Component Analysis (PCA) as a tool for exploring high-dimensional data. The visualization helps identify global patterns, sample clustering, and potential outliers, enabling users to gain insight into the underlying structure of the data before applying more advanced analyses.','PCA_ZOOM_in', 'Icon','info')
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            showHelp('PCA_ZOOM_in');  
        end

        % Button pushed function: HELPButton_Panel_BoxPlotTab
        function HELPButton_Panel_BoxPlotTabPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'BoxPlot_ZOOM_in', ...
                'This section presents a zoomed-in view of the boxplot. The enlarged visualization enables a more detailed inspection of data distribution, including median values, interquartile ranges, and potential outliers. This zoomed perspective is particularly useful for metabolomics data, where subtle differences in concentration levels and variability across samples can be more clearly identified and interpreted.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle), 1);
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    % msgbox(helpText{index + 1}, sectionTitle, 'help');
                    uialert(app.UIFigure_Main,'This section presents a zoomed-in view of the boxplot. The enlarged visualization enables a more detailed inspection of data distribution, including median values, interquartile ranges, and potential outliers. This zoomed perspective is particularly useful for metabolomics data, where subtle differences in concentration levels and variability across samples can be more clearly identified and interpreted.','BoxPlot_ZOOM_in', 'Icon','info')
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            showHelp('BoxPlot_ZOOM_in'); 
        end

        % Button pushed function: Button_Back_to_MAIN_BoxPlotTab
        function Button_Back_to_MAIN_BoxPlotTabPushed(app, event)
            app.TabGroup_Main.Visible = "on";
            app.MainTab_PanOMICsApp.Parent = app.TabGroup_Main;
            app.TabGroup_Main.SelectedTab = app.MainTab_PanOMICsApp;                
            app.SectionVisualization_BoxPlotTab.Parent = [];  
        end

        % Button pushed function: HELPButton_Panel_GWAS
        function HELPButton_Panel_GWASPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'GWAS', ...
                'This section presents a GWAS visualization for exploring associations between genetic variants and phenotypic traits. The plot allows users to identify genomic loci that show significant associations, highlighting potential candidate variants or regions of interest. This visualization is particularly relevant for genomic data, as it supports the detection of meaningful genetic signals and provides insight into the genetic architecture underlying the studied traits.', ...
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
                % Find the index of the section title
                index = find(strcmp(helpText, sectionTitle), 1);
                if ~isempty(index)
                    % Display the corresponding help text in a message box
                    % msgbox(helpText{index + 1}, sectionTitle, 'help');
                    uialert(app.UIFigure_Main,'This section presents a GWAS visualization for exploring associations between genetic variants and phenotypic traits. The plot allows users to identify genomic loci that show significant associations, highlighting potential candidate variants or regions of interest. This visualization is particularly relevant for genomic data, as it supports the detection of meaningful genetic signals and provides insight into the genetic architecture underlying the studied traits.','GWAS', 'Icon','info')
                else
                    % Display an error if the section title is not found
                    msgbox('Help text not found for the selected section.', 'Error', 'error');
                end
            end
            
            showHelp('GWAS'); 
        end

        % Button pushed function: SaveResultsascsvButton
        function SaveResultsascsvButtonPushed(app, event)

            if isempty(app.UITable_Results_GWAS.Data)
                uialert(app.UIFigure, 'No results to save.', 'Warning');
                return;
            end
            
            [file, path] = uiputfile('GWAS_results.csv', 'Save Results As');
            
            if isequal(file,0)
                return;
            end
            
            results = app.UITable_Results_GWAS.Data;
            
            if ~istable(results)
                results = cell2table(results);
            end
            
            writetable(results, fullfile(path, file));
        end

        % Button pushed function: Button_Example_data_control_group
        function Button_Example_data_control_groupPushed(app, event)

           try 
             app.control_data = readtable('/data/webapps/input_example/PRS_control_CentralEurope_vs_case_WesternEurope_6/ID_control_Ath_europe.csv');
             app.UITable_Input_control_group.Data = app.control_data;
             varHeadTable = app.UITable_Input_control_group.Data;
             varHeadTable.Properties.VariableNames(1) = "ID";
             Names_varHeadTable = varHeadTable.Properties.VariableNames;
             app.UITable_Input_control_group.ColumnName = Names_varHeadTable;
             catch err
                 hErrIdx = warndlg(['Fails to open file: ',err.message]);
                 uiwait(hErrIdx);
             return;
          end

        end

        % Button pushed function: Button_Example_data_case_group
        function Button_Example_data_case_groupPushed(app, event)

           try 
             app.case_data = readtable('/data/webapps/input_example/PRS_control_CentralEurope_vs_case_WesternEurope_6/ID_case_Ath_europe.csv');
             app.UITable_Input_case_group.Data = app.case_data;
             varHeadTable = app.UITable_Input_case_group.Data;
             varHeadTable.Properties.VariableNames(1) = "ID";
             Names_varHeadTable = varHeadTable.Properties.VariableNames;
             app.UITable_Input_case_group.ColumnName = Names_varHeadTable;
             catch err
                 hErrIdx = warndlg(['Fails to open file: ',err.message]);
                 uiwait(hErrIdx);
             return;
          end            
        end

        % Button pushed function: CalculatePRSButton
        function CalculatePRSButtonPushed(app, event)
            app.Geno = rows2vars(app.dataG);
            app.Phe = app.dataPhe;

            sample_id_col = "ID";
                       
            app.Geno.Properties.VariableNames = cellstr(app.Geno{1,:});
            app.Geno(1,:) = []; 
            app.Geno.Properties.VariableNames(1) = "ID";
            app.Phe.Properties.VariableNames(1) = "ID";

            app.control_data.Properties.VariableNames(1) = "ID";
            app.case_data.Properties.VariableNames(1) = "ID";
                 % app.dataM = rows2vars(app.dataM);
                 % app.dataM.Properties.VariableNames = cellstr(app.dataM{1,:});
                 % app.dataM(1,:) = []; 
                 % app.dataM_no = app.dataM(:, 2:end);
                 % app.UITableMet.Data = app.dataM;
                 % varHeadTable = app.UITableMet.Data;
                 % varHeadTable.Properties.VariableNames(1) = " ";

            % Merge genotype and phenotype data according ID (identifiers)
            data = outerjoin(app.Geno, app.Phe, 'MergeKeys', true);
            
            % data = rows2vars(data);
            % data.Properties.VariableNames = cellstr(data{1,:});
            % data(1,:) = []; 
            % data.Properties.VariableNames(1) = "ID";

            % Identificators –  control & case groups
            control_samples = ismember(data.(sample_id_col), app.control_data.(sample_id_col));
            case_samples = ismember(data.(sample_id_col), app.case_data.(sample_id_col));
            
            % Splitting the data into control and case group
            app.control_data = data(control_samples, :);
            app.case_data = data(case_samples, :);
            
            % Initialize table for results
            results_control = table();
            results_case = table();
            
            % Analysis for the control group
            for i = 1:size(app.Geno,2)
                snp = app.Geno.Properties.VariableNames{i};
                if ~strcmp(snp, sample_id_col)
                    x_control = app.control_data.(snp);
                    x_control = cell2mat(x_control);
                    x_control = [ones(size(x_control)), x_control];
                    y_control = app.control_data.phenotype;
                    [beta,~,~,~,stats] = regress(y_control, x_control);
                    beta = beta(2);
                    p_value = stats(3);
                    results_control = [results_control; table({snp}, p_value, beta)];
                end
            end
            
            % Analysis for the case group
            for i = 1:size(app.Geno,2)
                snp = app.Geno.Properties.VariableNames{i};
                if ~strcmp(snp, sample_id_col)
                    x_case = app.case_data.(snp);
                    x_case = cell2mat(x_case);
                    x_case = [ones(size(x_case)), x_case];
                    y_case = app.case_data.phenotype;
                    [beta,~,~,~,stats] = regress(y_case, x_case);
                    beta = beta(2);
                    p_value = stats(3);
                    results_case = [results_case; table({snp}, p_value, beta)];
                end
            end
            
            % Calculation of PRS for the control group
            control_genotypes = app.control_data(:, setdiff(app.Geno.Properties.VariableNames, sample_id_col));
            app.control_scores = zeros(size(app.control_data, 1), 1);
            for i = 1:height(results_control)
                snp = results_control.Var1{i};
                beta = results_control.beta(i);
                app.control_scores = app.control_scores + beta * cell2mat(control_genotypes.(snp));
            end
            
            % Calculation of PRS for the case group
            case_genotypes = app.case_data(:, setdiff(app.Geno.Properties.VariableNames, sample_id_col));
            app.case_scores = zeros(size(app.case_data, 1), 1);
            for i = 1:height(results_case)
                snp = results_case.Var1{i};
                beta = results_case.beta(i);
                app.case_scores = app.case_scores + beta * cell2mat(case_genotypes.(snp));
            end
        
             app.UITable_Results_Control_group.Data = results_control;
             varHeadTable = app.UITable_Results_Control_group.Data;
             varHeadTable.Properties.VariableNames(1) = " ";
             Names_varHeadTable = varHeadTable.Properties.VariableNames;
             app.UITable_Results_Control_group.ColumnName = Names_varHeadTable;
             
             app.UITable_Results_Case_group.Data = results_case;
             varHeadTable_2 = app.UITable_Results_Case_group.Data;
             varHeadTable_2.Properties.VariableNames(1) = " ";
             Names_varHeadTable_2 = varHeadTable_2.Properties.VariableNames;
             app.UITable_Results_Case_group.ColumnName = Names_varHeadTable_2;

            updatePRS_hist_gauss(app);

        end

        % Button pushed function: HelpButton_Panel_3_Prediction_approches
        function HelpButton_Panel_3_Prediction_approchesPushed(app, event)
        % Create a cell array to store the help text for each section
        helpText = {
            'Prediction Approaches', ...
            ['This section allows you to select and configure OMICs-based prediction approaches. ' ...
             'You can choose which OMICs datasets to include in the predictive model, such as genomic (SNPs), metabolomic, phenotypic, or other OMICs layers. ' ...
             'Different integration strategies may be available, including single-OMICs models or multi-OMICs combinations. ' ...
             'Select the desired datasets to combine and define the prediction method according to your analytical objectives. ' ...
             'Proper selection of OMICs layers and modeling strategy is essential for improving prediction accuracy and biological interpretability.']
        };
        
        % Function to show help for a specific section
        function showHelp(sectionTitle)
        
            index = find(strcmp(helpText, sectionTitle), 1);
        
            if ~isempty(index)
                uialert(app.UIFigure_Main, ...
                    helpText{index + 1}, ...
                    'Prediction Approaches', ...
                    'Icon','info');
            else
                uialert(app.UIFigure_Main, ...
                    'Help text not found for the selected section.', ...
                    'Error', ...
                    'Icon','error');
            end
        
        end
        
        showHelp('Prediction Approaches');

        end

        % Button pushed function: Button_Downloandresultscontrolgroup
        function Button_DownloandresultscontrolgroupPushed(app, event)
            
            if isempty(app.UITable_Results_Control_group.Data)
                uialert(app.UIFigure, 'No results to save.', 'Warning');
                return;
            end
            
            [file, path] = uiputfile('PRS_Results_Control_group_results.csv', 'Save Results As');
            
            if isequal(file,0)
                return;
            end
            
            results = app.UITable_Results_Control_group.Data;
            
            if ~istable(results)
                results = cell2table(results);
            end
            
            writetable(results, fullfile(path, file));
        end

        % Button pushed function: Button_Downloadresultscasegroup
        function Button_DownloadresultscasegroupPushed(app, event)
                        
            if isempty(app.UITable_Results_Case_group.Data)
                uialert(app.UIFigure, 'No results to save.', 'Warning');
                return;
            end
            
            [file, path] = uiputfile('PRS_Results_Case_group_results.csv', 'Save Results As');
            
            if isequal(file,0)
                return;
            end
            
            results = app.UITable_Results_Case_group.Data;
            
            if ~istable(results)
                results = cell2table(results);
            end
            
            writetable(results, fullfile(path, file));
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'Polygenic Risk Score', ...
                ['This section enables the calculation and evaluation of Polygenic Risk Scores (PRS) based on genomic data. ' ...
                 'PRS aggregates the effects of multiple genetic variants (SNPs) across the genome to estimate an individual’s genetic predisposition to a specific trait or disease. ' ...
                 'You can obtain parameters such as p-value thresholds, SNP selection criteria, and effect size weighting derived from GWAS results. ']
            };
            
            % Function to show help for a specific section
            function showHelp(sectionTitle)
            
                index = find(strcmp(helpText, sectionTitle), 1);
            
                if ~isempty(index)
                    uialert(app.UIFigure_Main, ...
                        helpText{index + 1}, ...
                        'Polygenic Risk Score', ...
                        'Icon','info');
                else
                    uialert(app.UIFigure_Main, ...
                        'Help text not found for the selected section.', ...
                        'Error', ...
                        'Icon','error');
                end
            
            end
            
            showHelp('Polygenic Risk Score');

        end

        % Value changed function: CheckBox_Default
        function CheckBox_DefaultValueChanged(app, event)
            app.CheckBox_Default_BoxValue = app.CheckBox_Default.Value;

            if app.CheckBox_Default_BoxValue == 1
                app.CheckBox_Upload_own_model_BoxValue = 0;

                app.CheckBox_Default_Value = 1; app.CheckBox_Default.Value = 1;
                app.CheckBox_Upload_own_model_Value = 0; app.CheckBox_Upload_own_model.Value = 0; 

                app.Upload_pretrained_models.Enable = "off";
            end
                            
            
        end

        % Value changed function: CheckBox_Upload_own_model
        function CheckBox_Upload_own_modelValueChanged(app, event)
            app.CheckBox_Upload_own_model_BoxValue = app.CheckBox_Upload_own_model.Value;


            if app.CheckBox_Upload_own_model_BoxValue == 1
                app.CheckBox_Default_BoxValue = 0;

                app.CheckBox_Upload_own_model_Value = 1; app.CheckBox_Upload_own_model.Value = 1;
                app.CheckBox_Default_Value = 0; app.CheckBox_Default.Value = 0;

                app.Upload_pretrained_models.Enable = "on";
            end
            
        end

        % Button pushed function: HelpButton_Loading_pretrained_models
        function HelpButton_Loading_pretrained_modelsPushed(app, event)
        % Create a cell array to store the help text for each section
        helpText = {
            'Prediction Methods - Loading Pre-trained Models', ...
            ['This section allows you to load and manage pre-trained prediction models. ' ...
             'You can choose to use a default pre-trained model provided within the application, ' ...
             'which is ready for immediate use and optimized for standard analytical scenarios. ' ...
             'Alternatively, you can upload your own custom-trained model in *.mat format. ' ...
             'The uploaded file should contain a properly trained and saved MATLAB model object ' ...
             'that is compatible with the selected prediction workflow. ' ...
             'Using custom models enables flexibility and adaptation to specific datasets or research objectives. ' ...
             'Please ensure that the structure and variables stored in the *.mat file follow the required format ' ...
             'to guarantee correct loading and prediction performance.']
        };
        
        % Function to show help for a specific section
        function showHelp(sectionTitle)
        
            index = find(strcmp(helpText, sectionTitle), 1);
        
            if ~isempty(index)
                uialert(app.UIFigure_Main, ...
                    helpText{index + 1}, ...
                    'Prediction Methods - Loading Pre-trained Models', ...
                    'Icon','info');
            else
                uialert(app.UIFigure_Main, ...
                    'Help text not found for the selected section.', ...
                    'Error', ...
                    'Icon','error');
            end
        
        end
        
        showHelp('Prediction Methods - Loading Pre-trained Models');
        end

        % Button pushed function: Upload_pretrained_models
        function Upload_pretrained_modelsButtonPushed(app, event)
        [app.modelFileName, app.modelFilePath] = uigetfile( ...
            {'*.mat','MAT-files (*.mat)'}, ...
            'Load a pre-trained prediction model');
        
        if app.modelFileName ~= 0
            
            loadedData = load(fullfile(app.modelFilePath, app.modelFileName));
            
            % LASSO model
            if strcmp(app.modelFileName,'model_LASSO_univar.mat')
                
                app.lasso_univar_mat = loadedData;

                app.ENR_univar_mat = []; app.RR_univar_mat = []; app.GPR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.lasso_multivar_mat = [];
                app.ENR_multivar_mat = []; app.RR_multivar_mat = []; app.GPR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

                app.CheckBox_LASSO_Value = 1; app.CheckBox_LASSO.Value = 1;
                app.CheckBox_LASSO.Enable = "on";                
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
                app.CheckBox_CNN.Enable = "off";
                
            elseif strcmp(app.modelFileName,'model_LASSO_multivar.mat')
                
                app.lasso_multivar_mat = loadedData;

                app.ENR_univar_mat = []; app.RR_univar_mat = []; app.GPR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.lasso_univar_mat = [];
                app.ENR_multivar_mat = []; app.RR_multivar_mat = []; app.GPR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

                app.CheckBox_LASSO_Value = 1; app.CheckBox_LASSO.Value = 1;
                app.CheckBox_LASSO.Enable = "on";                
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
                app.CheckBox_CNN.Enable = "off"; 

            elseif strcmp(app.modelFileName,'model_RR_univar.mat')

                app.RR_univar_mat = loadedData;

                app.lasso_univar_mat = []; app.ENR_univar_mat = []; app.GPR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.RR_multivar_mat = [];
                app.lasso_multivar_mat = []; app.ENR_multivar_mat = []; app.GPR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

                app.CheckBox_RR_Value = 1; app.CheckBox_RR.Value = 1;
                app.CheckBox_RR.Enable = "on";                
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
                app.CheckBox_CNN.Enable = "off";

            elseif strcmp(app.modelFileName,'model_RR_multivar.mat')

                app.RR_multivar_mat = loadedData;

                app.lasso_univar_mat = []; app.ENR_univar_mat = []; app.GPR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.RR_univar_mat = [];
                app.lasso_multivar_mat = []; app.ENR_multivar_mat = []; app.GPR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

                app.CheckBox_RR_Value = 1; app.CheckBox_RR.Value = 1;
                app.CheckBox_RR.Enable = "on";                
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
                app.CheckBox_CNN.Enable = "off";

             elseif strcmp(app.modelFileName,'model_ENR_univar.mat')

                app.ENR_univar_mat = loadedData;

                app.lasso_univar_mat = []; app.RR_univar_mat = []; app.GPR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.ENR_multivar_mat = [];
                app.lasso_multivar_mat = []; app.RR_multivar_mat = []; app.GPR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

                app.CheckBox_ENR_Value = 1; app.CheckBox_ENR.Value = 1;
                app.CheckBox_ENR.Enable = "on";                
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
                app.CheckBox_CNN.Enable = "off";
 
            elseif strcmp(app.modelFileName,'model_ENR_multivar.mat')

                app.ENR_multivar_mat = loadedData;

                app.lasso_univar_mat = []; app.RR_univar_mat = []; app.GPR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.ENR_univar_mat = [];
                app.lasso_multivar_mat = []; app.RR_multivar_mat = []; app.GPR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

                app.CheckBox_ENR_Value = 1; app.CheckBox_ENR.Value = 1;
                app.CheckBox_ENR.Enable = "on";                
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
                app.CheckBox_CNN.Enable = "off";
             
            % GPR model
             elseif strcmp(app.modelFileName,'model_GPR_univar.mat')
                
                app.GPR_univar_mat = loadedData;

                app.lasso_univar_mat = []; app.RR_univar_mat = []; app.ENR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.GPR_multivar_mat = [];
                app.lasso_multivar_mat = []; app.RR_multivar_mat = []; app.ENR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

                app.CheckBox_GPR_Value = 1; app.CheckBox_GPR.Value = 1;
                app.CheckBox_GPR.Enable = "on";                
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
                app.CheckBox_CNN.Enable = "off";

             elseif strcmp(app.modelFileName,'model_GPR_multivar.mat')
                
                app.GPR_multivar_mat = loadedData;

                app.lasso_univar_mat = []; app.RR_univar_mat = []; app.ENR_univar_mat = []; 
                app.SVR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.GPR_univar_mat = [];
                app.lasso_multivar_mat = []; app.RR_multivar_mat = []; app.ENR_multivar_mat = []; 
                app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

                app.CheckBox_GPR_Value = 1; app.CheckBox_GPR.Value = 1;
                app.CheckBox_GPR.Enable = "on";                
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
                app.CheckBox_CNN.Enable = "off";
  
            % SVR model
            elseif strcmp(app.modelFileName,'model_SVR_univar.mat')
                
                app.SVR_univar_mat = loadedData;

                app.lasso_univar_mat = []; app.RR_univar_mat = []; app.ENR_univar_mat = []; 
                app.GPR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.SVR_multivar_mat = [];
                app.lasso_multivar_mat = []; app.RR_multivar_mat = []; app.ENR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

                app.CheckBox_SVR_Value = 1; app.CheckBox_SVR.Value = 1;
                app.CheckBox_SVR.Enable = "on";                
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
                app.CheckBox_CNN.Enable = "off";

             elseif strcmp(app.modelFileName,'model_SVR_multivar.mat')
                
                app.SVR_multivar_mat = loadedData;

                app.lasso_univar_mat = []; app.RR_univar_mat = []; app.ENR_univar_mat = []; 
                app.GPR_univar_mat = []; app.PLS_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.SVR_univar_mat = [];
                app.lasso_multivar_mat = []; app.RR_multivar_mat = []; app.ENR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.PLS_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

                app.CheckBox_SVR_Value = 1; app.CheckBox_SVR.Value = 1;
                app.CheckBox_SVR.Enable = "on";                
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
                app.CheckBox_CNN.Enable = "off";

            % PLS model
            elseif strcmp(app.modelFileName,'model_PLS_univar.mat')
                
                app.PLS_univar_mat = loadedData;

                app.lasso_univar_mat = []; app.RR_univar_mat = []; app.ENR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.PLS_multivar_mat = [];
                app.lasso_multivar_mat = []; app.RR_multivar_mat = []; app.ENR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

                app.CheckBox_PLS_Value = 1; app.CheckBox_PLS.Value = 1;
                app.CheckBox_PLS.Enable = "on";                
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
                app.CheckBox_CNN.Enable = "off";

            elseif strcmp(app.modelFileName,'model_PLS_multivar.mat')
                
                app.PLS_multivar_mat = loadedData;

                app.lasso_univar_mat = []; app.RR_univar_mat = []; app.ENR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.RF_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];
                app.PLS_univar_mat = [];
                app.lasso_multivar_mat = []; app.RR_multivar_mat = []; app.ENR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.RF_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

                app.CheckBox_PLS_Value = 1; app.CheckBox_PLS.Value = 1;
                app.CheckBox_PLS.Enable = "on";                
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
                app.CheckBox_CNN.Enable = "off";
                           
            % RF model - prepis
            elseif strcmp(app.modelFileName,'model_RF_univar.mat')
                
                app.RF_univar_mat = loadedData;

                app.lasso_univar_mat = []; app.RR_univar_mat = []; app.ENR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = []; 
                app.RF_multivar_mat = [];
                app.lasso_multivar_mat = []; app.RR_multivar_mat = []; app.ENR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = []; 

                app.CheckBox_RandomForest_Value = 1; app.CheckBox_RandomForest.Value = 1;
                app.CheckBox_RandomForest.Enable = "on";                
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
                app.CheckBox_CNN.Enable = "off";

            elseif strcmp(app.modelFileName,'model_RF_multivar.mat')
                
                app.RF_multivar_mat = loadedData;

                app.lasso_univar_mat = []; app.RR_univar_mat = []; app.ENR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.LSTM_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = []; 
                app.RF_univar_mat = [];
                app.lasso_multivar_mat = []; app.RR_multivar_mat = []; app.ENR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.LSTM_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = []; 

                app.CheckBox_RandomForest_Value = 1; app.CheckBox_RandomForest.Value = 1;
                app.CheckBox_RandomForest.Enable = "on";                
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0;
                app.CheckBox_LSTM.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
                app.CheckBox_CNN.Enable = "off";

            % LSTM model - prepis
            elseif strcmp(app.modelFileName,'model_LSTM_univar.mat')
                
                app.LSTM_univar_mat = loadedData;

                app.lasso_univar_mat = []; app.RR_univar_mat = []; app.ENR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];  
                app.LSTM_multivar_mat = [];
                app.lasso_multivar_mat = []; app.RR_multivar_mat = []; app.ENR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

                app.CheckBox_LSTM_Value = 1; app.CheckBox_LSTM.Value = 1;
                app.CheckBox_LSTM.Enable = "on";                
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
                app.CheckBox_CNN.Enable = "off";

            % LSTM model
            elseif strcmp(app.modelFileName,'model_LSTM_multivar.mat')
                
                app.LSTM_multivar_mat = loadedData;

                app.lasso_univar_mat = []; app.RR_univar_mat = []; app.ENR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.CNN_univar_mat = []; app.fullNN_univar_mat = [];  
                app.LSTM_univar_mat = [];
                app.lasso_multivar_mat = []; app.RR_multivar_mat = []; app.ENR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.CNN_multivar_mat = []; app.fullNN_multivar_mat = [];

                app.CheckBox_LSTM_Value = 1; app.CheckBox_LSTM.Value = 1;
                app.CheckBox_LSTM.Enable = "on";                
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0; 
                app.CheckBox_CNN.Enable = "off";

            % CNN model - prepis
            elseif strcmp(app.modelFileName,'model_CNN_univar.mat')
                
                app.CNN_univar_mat = loadedData;

                app.lasso_univar_mat = []; app.RR_univar_mat = []; app.ENR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.LSTM_univar_mat = []; app.fullNN_univar_mat = [];  
                app.CNN_multivar_mat = [];
                app.lasso_multivar_mat = []; app.RR_multivar_mat = []; app.ENR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.LSTM_multivar_mat = []; app.fullNN_multivar_mat = [];  

                app.CheckBox_CNN_Value = 1; app.CheckBox_CNN.Value = 1;
                app.CheckBox_CNN.Enable = "on";                
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0; 
                app.CheckBox_LSTM.Enable = "off";

            elseif strcmp(app.modelFileName,'model_CNN_multivar.mat')
                
                app.CNN_multivar_mat = loadedData;

                app.lasso_univar_mat = []; app.RR_univar_mat = []; app.ENR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.LSTM_univar_mat = []; app.fullNN_univar_mat = [];  
                app.CNN_univar_mat = [];
                app.lasso_multivar_mat = []; app.RR_multivar_mat = []; app.ENR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.LSTM_multivar_mat = []; app.fullNN_multivar_mat = [];  

                app.CheckBox_CNN_Value = 1; app.CheckBox_CNN.Value = 1;
                app.CheckBox_CNN.Enable = "on";                
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_fullyconnected_NN_Value = 0; app.CheckBox_fullyconnected_NN.Value = 0;
                app.CheckBox_fullyconnected_NN.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0; 
                app.CheckBox_LSTM.Enable = "off";
                
            % fullNN model
            elseif strcmp(app.modelFileName,'model_fullNN_univar.mat')
                
                app.fullNN_univar_mat = loadedData;

                app.lasso_univar_mat = []; app.RR_univar_mat = []; app.ENR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.LSTM_univar_mat = []; app.CNN_univar_mat = [];
                app.fullNN_multivar_mat = [];
                app.lasso_multivar_mat = []; app.RR_multivar_mat = []; app.ENR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.LSTM_multivar_mat = []; app.CNN_multivar_mat = [];

                app.CheckBox_fullyconnected_NN_Value = 1; app.CheckBox_fullyconnected_NN.Value = 1;
                app.CheckBox_fullyconnected_NN.Enable = "on";                
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0;
                app.CheckBox_CNN.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0; 
                app.CheckBox_LSTM.Enable = "off";
 
            elseif strcmp(app.modelFileName,'model_fullNN_multivar.mat')
                
                app.fullNN_multivar_mat = loadedData;

                app.lasso_univar_mat = []; app.RR_univar_mat = []; app.ENR_univar_mat = []; 
                app.GPR_univar_mat = []; app.SVR_univar_mat = []; app.PLS_univar_mat = []; 
                app.RF_univar_mat = []; app.LSTM_univar_mat = []; app.CNN_univar_mat = [];
                app.fullNN_univar_mat = [];
                app.lasso_multivar_mat = []; app.RR_multivar_mat = []; app.ENR_multivar_mat = []; 
                app.GPR_multivar_mat = []; app.SVR_multivar_mat = []; app.PLS_multivar_mat = []; 
                app.RF_multivar_mat = []; app.LSTM_multivar_mat = []; app.CNN_multivar_mat = [];

                app.CheckBox_fullyconnected_NN_Value = 1; app.CheckBox_fullyconnected_NN.Value = 1;
                app.CheckBox_fullyconnected_NN.Enable = "on";                
                app.CheckBox_RR_Value = 0; app.CheckBox_RR.Value = 0;
                app.CheckBox_RR.Enable = "off";
                app.CheckBox_LASSO_Value = 0; app.CheckBox_LASSO.Value = 0;
                app.CheckBox_LASSO.Enable = "off";
                app.CheckBox_ENR_Value = 0; app.CheckBox_ENR.Value = 0;
                app.CheckBox_ENR.Enable = "off";
                app.CheckBox_GPR_Value = 0; app.CheckBox_GPR.Value = 0;
                app.CheckBox_GPR.Enable = "off";
                app.CheckBox_SVR_Value = 0; app.CheckBox_SVR.Value = 0;
                app.CheckBox_SVR.Enable = "off";
                app.CheckBox_PLS_Value = 0; app.CheckBox_PLS.Value = 0;
                app.CheckBox_PLS.Enable = "off";
                app.CheckBox_CNN_Value = 0; app.CheckBox_CNN.Value = 0;
                app.CheckBox_CNN.Enable = "off";
                app.CheckBox_RandomForest_Value = 0; app.CheckBox_RandomForest.Value = 0;
                app.CheckBox_RandomForest.Enable = "off";
                app.CheckBox_LSTM_Value = 0; app.CheckBox_LSTM.Value = 0; 
                app.CheckBox_LSTM.Enable = "off";
            end
        end

        app.CheckBox_Keep_for_further_predictions.Value = 0;
        end

        % Button pushed function: 
        % HelpButton_Prediction_methods_Linear_approaches
        function HelpButton_Prediction_methods_Linear_approachesPushed(app, event)
        % Create a cell array to store the help text for each section
        helpText = {
            'Prediction Methods - Linear Models', ...
            ['This section allows you to configure and apply linear-based prediction models. ' ...
             'You can select from several commonly used predictive modeling approaches, including: ' ...
             'LASSO (Least Absolute Shrinkage and Selection Operator), ' ...
             'ENR (Elastic Net Regression), ' ...
             'SVR (Support Vector Regression), ' ...
             'RR (Ridge Regression), ' ...
             'GPR (Gaussian Process Regression), and ' ...
             'PLS (Partial Least Squares Regression). ' ...
             'Each method differs in regularization strategy, model complexity, and suitability for high-dimensional data. ' ...
             'Choose the appropriate algorithm based on your dataset characteristics, feature dimensionality, and prediction objectives. ' ...
             'Proper model selection and parameter tuning are essential for achieving robust and biologically meaningful predictions.']
        };
        
        % Function to show help for a specific section
        function showHelp(sectionTitle)
        
            index = find(strcmp(helpText, sectionTitle), 1);
        
            if ~isempty(index)
                uialert(app.UIFigure_Main, ...
                    helpText{index + 1}, ...
                    'Prediction Methods - Linear Models', ...
                    'Icon','info');
            else
                uialert(app.UIFigure_Main, ...
                    'Help text not found for the selected section.', ...
                    'Error', ...
                    'Icon','error');
            end
        
        end
        
        showHelp('Prediction Methods - Linear Models');            
        end

        % Button pushed function: 
        % HelpButton_Prediction_methods_Nonlinear_approaches
        function HelpButton_Prediction_methods_Nonlinear_approachesPushed(app, event)
                    % Create a cell array to store the help text for each section
        helpText = {
            'Prediction Methods - Non-linear Approaches', ...
            ['This section allows you to apply non-linear prediction models. ' ...
             'Currently, the available method in this category is Random Forest. ' ...
             'Random Forest is an ensemble-based machine learning algorithm that builds multiple decision trees ' ...
             'and combines their predictions to improve accuracy and robustness. ' ...
             'It is particularly suitable for complex, high-dimensional datasets and can capture non-linear relationships ' ...
             'between predictors and response variables. ' ...
             'Random Forest also provides measures of variable importance, which can support biological interpretation. ' ...
             'Use this approach when linear models are insufficient to describe the underlying data structure.']
        };
        
        % Function to show help for a specific section
        function showHelp(sectionTitle)
        
            index = find(strcmp(helpText, sectionTitle), 1);
        
            if ~isempty(index)
                uialert(app.UIFigure_Main, ...
                    helpText{index + 1}, ...
                    'Prediction Methods - Non-linear Approaches', ...
                    'Icon','info');
            else
                uialert(app.UIFigure_Main, ...
                    'Help text not found for the selected section.', ...
                    'Error', ...
                    'Icon','error');
            end
        
        end
        
        showHelp('Prediction Methods - Non-linear Approaches');
        end

        % Button pushed function: 
        % HelpButton_Prediction_methods_Deep_learning_approaches
        function HelpButton_Prediction_methods_Deep_learning_approachesPushed(app, event)
        % Create a cell array to store the help text for each section
        helpText = {
            'Prediction Methods - Deep Learning Approaches', ...
            ['This section allows you to configure and apply deep learning-based prediction models. ' ...
             'You can select from the following architectures: ' ...
             'Fully Connected Neural Network (Feedforward Neural Network), ' ...
             'LSTM (Long Short-Term Memory networks), and ' ...
             'CNN (Convolutional Neural Networks). ' ...
             'Fully Connected Neural Networks are suitable for general-purpose modeling of complex nonlinear relationships. ' ...
             'LSTM networks are particularly effective for sequential or time-series data due to their memory capabilities. ' ...
             'CNN models are designed to automatically extract hierarchical features and are suitable for structured or spatially organized data. ' ...
             'Deep learning models typically require larger datasets and careful parameter tuning to achieve optimal performance. ' ...
             'Select the architecture that best matches your data structure and prediction objectives.']
        };
        
        % Function to show help for a specific section
        function showHelp(sectionTitle)
        
            index = find(strcmp(helpText, sectionTitle), 1);
        
            if ~isempty(index)
                uialert(app.UIFigure_Main, ...
                    helpText{index + 1}, ...
                    'Prediction Methods - Deep Learning Approaches', ...
                    'Icon','info');
            else
                uialert(app.UIFigure_Main, ...
                    'Help text not found for the selected section.', ...
                    'Error', ...
                    'Icon','error');
            end
        
        end
        
        showHelp('Prediction Methods - Deep Learning Approaches');
        end

        % Button pushed function: Button_Linear_approaches_Parameters
        function Button_Linear_approaches_ParametersPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.Parameters_LinearTab.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.Parameters_LinearTab;                
                app.MainTab_PanOMICsApp.Parent = [];

        end

        % Button pushed function: Button_Nonlinear_approaches_Parameters
        function Button_Nonlinear_approaches_ParametersPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.Parameters_NonlinearTab.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.Parameters_NonlinearTab;                
                app.MainTab_PanOMICsApp.Parent = [];

        end

        % Button pushed function: 
        % Button_Deep_learning_approaches_Parameters
        function Button_Deep_learning_approaches_ParametersPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.Parameters_DeepLearnTab.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.Parameters_DeepLearnTab;                
                app.MainTab_PanOMICsApp.Parent = [];
                
        end

        % Button pushed function: BacktoMAINButton
        function BacktoMAINButtonPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.MainTab_PanOMICsApp.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.MainTab_PanOMICsApp;                
                app.Parameters_LinearTab.Parent = []; 
        end

        % Button pushed function: BacktoMAINButton_nonLinearParameters
        function BacktoMAINButton_nonLinearParametersPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.MainTab_PanOMICsApp.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.MainTab_PanOMICsApp;                
                app.Parameters_NonlinearTab.Parent = []; 
        end

        % Button pushed function: BacktoMAINButton_DeepLearn
        function BacktoMAINButton_DeepLearnPushed(app, event)
                app.TabGroup_Main.Visible = "on";
                app.MainTab_PanOMICsApp.Parent = app.TabGroup_Main;
                app.TabGroup_Main.SelectedTab = app.MainTab_PanOMICsApp;                
                app.Parameters_DeepLearnTab.Parent = []; 
        end

        % Value changed function: AlfaEditField_LASSOalfa
        function AlfaEditField_LASSOalfaValueChanged(app, event)
            app.LASSO_alfa = app.AlfaEditField_LASSOalfa.Value;
        end

        % Value changed function: CrossvalidationEditField_LASSOcv
        function CrossvalidationEditField_LASSOcvValueChanged(app, event)
            app.LASSO_cv = app.CrossvalidationEditField_LASSOcv.Value;
        end

        % Button pushed function: ResetparametersButton_LASSOparameters
        function ResetparametersButton_LASSOparametersPushed(app, event)
            app.LASSO_alfa = 1;
            app.AlfaEditField_LASSOalfa.Value = 1;
            app.LASSO_cv = 5;
            app.CrossvalidationEditField_LASSOcv.Value = 5;
        end

        % Value changed function: AlfaEditField_RRalfa
        function AlfaEditField_RRalfaValueChanged(app, event)
            app.RR_alfa = app.AlfaEditField_RRalfa.Value;
        end

        % Value changed function: CrossvalidationEditField_RRcv
        function CrossvalidationEditField_RRcvValueChanged(app, event)
            app.RR_cv = app.CrossvalidationEditField_RRcv.Value;
        end

        % Value changed function: AlfaEditField_ENRalfa
        function AlfaEditField_ENRalfaValueChanged(app, event)
            app.ENR_alfa = app.AlfaEditField_ENRalfa.Value;
        end

        % Button pushed function: ResetparametersButton_RRparameters
        function ResetparametersButton_RRparametersPushed(app, event)
            app.RR_alfa = 0.000001;
            app.AlfaEditField_RRalfa.Value = 0.000001;
            app.RR_cv = 5;
            app.CrossvalidationEditField_RRcv.Value = 5;            
        end

        % Value changed function: CrossvalidationEditField_ENRcv
        function CrossvalidationEditField_ENRcvValueChanged(app, event)
            app.ENR_cv = app.CrossvalidationEditField_ENRcv.Value;
        end

        % Button pushed function: ResetparametersButton_ENRparameters
        function ResetparametersButton_ENRparametersPushed(app, event)
            app.ENR_alfa = 0.75;
            app.AlfaEditField_ENRalfa.Value = 0.75;
            app.ENR_cv = 5;
            app.CrossvalidationEditField_ENRcv.Value = 5;               
        end

        % Value changed function: NumberofdecisiontreesEditField
        function NumberofdecisiontreesEditFieldValueChanged(app, event)
            app.number_of_decisionsTrees_input = app.NumberofdecisiontreesEditField.Value;            
        end

        % Value changed function: EditField_LSTMlayer_LSTM_2
        function EditField_LSTMlayer_LSTM_2ValueChanged(app, event)
            app.lstmlayer_LSTM_1 = app.EditField_LSTMlayer_LSTM_2.Value;            
        end

        % Value changed function: EditField_fullyconnected_LSTM_3
        function EditField_fullyconnected_LSTM_3ValueChanged(app, event)
            app.fullyconnectedlayer_LSTM_2 = app.EditField_fullyconnected_LSTM_3.Value;            
        end

        % Value changed function: EditField_dropout_LSTM_4
        function EditField_dropout_LSTM_4ValueChanged(app, event)
            app.dropoutlayer_LSTM_3 = app.EditField_dropout_LSTM_4.Value;
        end

        % Value changed function: EditField_Minibatchsize_LSTM
        function EditField_Minibatchsize_LSTMValueChanged(app, event)
            app.miniBatchSize_LSTM = app.EditField_Minibatchsize_LSTM.Value;            
        end

        % Value changed function: EditField_convolutionlayer_CNN_2
        function EditField_convolutionlayer_CNN_2ValueChanged(app, event)
            app.convolutionlayer_CNN_1 = app.EditField_convolutionlayer_CNN_2.Value;            
        end

        % Value changed function: EditField_convolutionlayer_CNN_4
        function EditField_convolutionlayer_CNN_4ValueChanged(app, event)
            app.convolutionlayer_CNN_2 = app.EditField_convolutionlayer_CNN_4.Value;            
        end

        % Value changed function: EditField_convolutionlayer_CNN_6
        function EditField_convolutionlayer_CNN_6ValueChanged(app, event)
            app.convolutionlayer_CNN_3 = app.EditField_convolutionlayer_CNN_6.Value;            
        end

        % Value changed function: EditField_fullyconnected_CNN_8
        function EditField_fullyconnected_CNN_8ValueChanged(app, event)
            app.fullyconnectedlayer_CNN_4 = app.EditField_fullyconnected_CNN_8.Value;            
        end

        % Value changed function: EditField_fullyconnected_CNN_9
        function EditField_fullyconnected_CNN_9ValueChanged(app, event)
            app.fullyconnectedlayer_CNN_5 = app.EditField_fullyconnected_CNN_9.Value;
        end

        % Value changed function: EditField_Minibatchsize_CNN
        function EditField_Minibatchsize_CNNValueChanged(app, event)
            app.miniBatchSize_CNN = app.EditField_Minibatchsize_CNN.Value;            
        end

        % Value changed function: 
        % EditField_fullyconnected_FullyconnectedNN_2
        function EditField_fullyconnected_FullyconnectedNN_2ValueChanged(app, event)
            app.fullyconnectedlayer_fullNN_1 = app.EditField_fullyconnected_FullyconnectedNN_2.Value;
        end

        % Value changed function: 
        % EditField_fullyconnected_FullyconnectedNN_4
        function EditField_fullyconnected_FullyconnectedNN_4ValueChanged(app, event)
            app.fullyconnectedlayer_fullNN_2 = app.EditField_fullyconnected_FullyconnectedNN_4.Value;            
        end

        % Value changed function: 
        % EditField_fullyconnected_FullyconnectedNN_6
        function EditField_fullyconnected_FullyconnectedNN_6ValueChanged(app, event)
            app.fullyconnectedlayer_fullNN_3 = app.EditField_fullyconnected_FullyconnectedNN_6.Value;            
        end

        % Value changed function: EditField_Minibatchsize_FullyconnectedNN
        function EditField_Minibatchsize_FullyconnectedNNValueChanged(app, event)
            app.miniBatchSize_fullNN = app.EditField_Minibatchsize_FullyconnectedNN.Value;
        end

        % Button pushed function: Button_Keep_as_Pretrained_yourself
        function Button_Keep_as_Pretrained_yourselfPushed(app, event)

            if ~isempty(app.lasso_univar_mat)
                [file, path] = uiputfile('model_LASSO_univar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.lasso_univar_mat;
                save(fullfile(path, file), 'var_results');

            elseif ~isempty(app.ENR_univar_mat)
                [file, path] = uiputfile('model_ENR_univar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.ENR_univar_mat;
                save(fullfile(path, file), 'var_results');


            elseif ~isempty(app.RR_univar_mat)
                [file, path] = uiputfile('model_RR_univar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.RR_univar_mat;
                save(fullfile(path, file), 'var_results');

            elseif ~isempty(app.GPR_univar_mat)
                [file, path] = uiputfile('model_GPR_univar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.GPR_univar_mat;
                save(fullfile(path, file), 'var_results');

            elseif ~isempty(app.SVR_univar_mat)
                [file, path] = uiputfile('model_SVR_univar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.SVR_univar_mat;
                save(fullfile(path, file), 'var_results');

            elseif ~isempty(app.PLS_univar_mat)
                [file, path] = uiputfile('model_PLS_univar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.PLS_univar_mat;
                save(fullfile(path, file), 'var_results');

            elseif ~isempty(app.RF_univar_mat)
                [file, path] = uiputfile('model_RF_univar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.RF_univar_mat;
                save(fullfile(path, file), 'var_results');

            elseif ~isempty(app.LSTM_univar_mat)
                [file, path] = uiputfile('model_LSTM_univar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.LSTM_univar_mat;
                save(fullfile(path, file), 'var_results');
            
            elseif ~isempty(app.CNN_univar_mat)
                [file, path] = uiputfile('model_CNN_univar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.CNN_univar_mat;
                save(fullfile(path, file), 'var_results');
            
            elseif ~isempty(app.fullNN_univar_mat)
                [file, path] = uiputfile('model_fullNN_univar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.fullNN_univar_mat;
                save(fullfile(path, file), 'var_results');

            elseif ~isempty(app.lasso_multivar_mat)
                [file, path] = uiputfile('model_LASSO_multivar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.lasso_multivar_mat;
                save(fullfile(path, file), 'var_results');

            elseif ~isempty(app.ENR_multivar_mat)
                [file, path] = uiputfile('model_ENR_multivar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.ENR_multivar_mat;
                save(fullfile(path, file), 'var_results');


            elseif ~isempty(app.RR_multivar_mat)
                [file, path] = uiputfile('model_RR_multivar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.RR_multivar_mat;
                save(fullfile(path, file), 'var_results');

            elseif ~isempty(app.GPR_multivar_mat)
                [file, path] = uiputfile('model_GPR_multivar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.GPR_multivar_mat;
                save(fullfile(path, file), 'var_results');

            elseif ~isempty(app.SVR_multivar_mat)
                [file, path] = uiputfile('model_SVR_multivar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.SVR_multivar_mat;
                save(fullfile(path, file), 'var_results');

            elseif ~isempty(app.PLS_multivar_mat)
                [file, path] = uiputfile('model_PLS_multivar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.PLS_multivar_mat;
                save(fullfile(path, file), 'var_results');

            elseif ~isempty(app.RF_multivar_mat)
                [file, path] = uiputfile('model_RF_multivar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.RF_multivar_mat;
                save(fullfile(path, file), 'var_results');

            elseif ~isempty(app.LSTM_multivar_mat)
                [file, path] = uiputfile('model_LSTM_multivar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.LSTM_multivar_mat;
                save(fullfile(path, file), 'var_results');
            
            elseif ~isempty(app.CNN_multivar_mat)
                [file, path] = uiputfile('model_CNN_multivar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.CNN_multivar_mat;
                save(fullfile(path, file), 'var_results');
            
            elseif ~isempty(app.fullNN_multivar_mat)
                [file, path] = uiputfile('model_fullNN_multivar.mat', 'Save Results As');
                
                if isequal(file,0)
                    return;
                end
                
                var_results = app.fullNN_multivar_mat;
                save(fullfile(path, file), 'var_results');
            end

        end

        % Button pushed function: HelpButton_LASSOparameters
        function HelpButton_LASSOparametersPushed(app, event)
        % Create a cell array to store the help text for each section
            helpText = {
                'LASSO Parameters Customization', ...
                ['This section allows customization of parameters for the LASSO (Least Absolute Shrinkage and Selection Operator) linear model. ' ...
                 'LASSO is a regularized regression method that performs both variable selection and coefficient shrinkage, ' ...
                 'which helps improve model interpretability and prevent overfitting when working with high-dimensional data. ' ...
                 'Users can modify the alpha coefficient that controls the strength of the regularization penalty. ' ...
                 'Higher values increase the penalty applied to model coefficients, leading to stronger feature selection. ' ...
                 'The number of folds used in cross-validation can also be adjusted. Cross-validation is used to determine the optimal regularization parameter ' ...
                 'by evaluating model performance on multiple data partitions. ' ...
                 'It is recommended to use an odd number of folds for cross-validation to ensure balanced data splitting and more stable performance estimates.']
            };
        
            % Function to show help for a specific section
            function showHelp(sectionTitle)
        
                index = find(strcmp(helpText, sectionTitle), 1);
        
                if ~isempty(index)
                    uialert(app.UIFigure_Main, ...
                        helpText{index + 1}, ...
                        'LASSO Parameters Customization', ...
                        'Icon','info');
                else
                    uialert(app.UIFigure_Main, ...
                        'Help text not found for the selected section.', ...
                        'Error', ...
                        'Icon','error');
                end
        
            end
        
            showHelp('LASSO Parameters Customization');
        end

        % Button pushed function: HelpButton_RRparameters
        function HelpButton_RRparametersPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'Ridge Regression Parameters Customization', ...
                ['This section allows customization of parameters for the Ridge Regression (RR) linear model. ' ...
                 'Ridge Regression is a regularized regression technique that applies an L2 penalty to model coefficients ' ...
                 'in order to reduce overfitting and improve model stability, particularly when predictors are highly correlated. ' ...
                 'Unlike LASSO, Ridge Regression does not perform variable selection but instead shrinks coefficients towards zero. ' ...
                 'Users can modify the regularization strength (alpha parameter), which controls the magnitude of the penalty applied to the regression coefficients. ' ...
                 'Higher values of alpha increase coefficient shrinkage and can improve model generalization. ' ...
                 'The number of folds used in cross-validation can also be specified. Cross-validation evaluates model performance across multiple data splits ' ...
                 'to determine the optimal level of regularization. ' ...
                 'It is recommended to use an odd number of folds for cross-validation to achieve balanced partitioning of the dataset and more stable evaluation results.']
            };
        
            % Function to show help for a specific section
            function showHelp(sectionTitle)
        
                index = find(strcmp(helpText, sectionTitle), 1);
        
                if ~isempty(index)
                    uialert(app.UIFigure_Main, ...
                        helpText{index + 1}, ...
                        'Ridge Regression Parameters Customization', ...
                        'Icon','info');
                else
                    uialert(app.UIFigure_Main, ...
                        'Help text not found for the selected section.', ...
                        'Error', ...
                        'Icon','error');
                end
        
            end
        
            showHelp('Ridge Regression Parameters Customization');

        end

        % Button pushed function: HelpButton_ENRparameters
        function HelpButton_ENRparametersPushed(app, event)
        % Create a cell array to store the help text for each section
            helpText = {
                'Elastic Net Regression Parameters Customization', ...
                ['This section allows customization of parameters for the Elastic Net Regression (ENR) linear model. ' ...
                 'Elastic Net is a regularized regression method that combines both L1 (LASSO) and L2 (Ridge) penalties. ' ...
                 'This approach is particularly useful when working with high-dimensional datasets where predictors may be highly correlated. ' ...
                 'Elastic Net can perform variable selection like LASSO while maintaining the stability of Ridge Regression. ' ...
                 'Users can adjust the alpha parameter that controls the overall strength of regularization applied to the model coefficients. ' ...
                 'Additionally, the mixing parameter (often referred to as the L1 ratio) determines the balance between LASSO and Ridge penalties. ' ...
                 'A value closer to 1 emphasizes LASSO-like behavior, while a value closer to 0 behaves more like Ridge Regression. ' ...
                 'The number of folds used in cross-validation can also be specified. Cross-validation evaluates model performance across multiple data splits ' ...
                 'to determine the optimal regularization settings. ' ...
                 'It is recommended to use an odd number of folds for cross-validation to ensure balanced partitioning of the dataset and more stable evaluation results.']
            };
        
            % Function to show help for a specific section
            function showHelp(sectionTitle)
        
                index = find(strcmp(helpText, sectionTitle), 1);
        
                if ~isempty(index)
                    uialert(app.UIFigure_Main, ...
                        helpText{index + 1}, ...
                        'Elastic Net Regression Parameters Customization', ...
                        'Icon','info');
                else
                    uialert(app.UIFigure_Main, ...
                        'Help text not found for the selected section.', ...
                        'Error', ...
                        'Icon','error');
                end
        
            end
        
            showHelp('Elastic Net Regression Parameters Customization');
        end

        % Button pushed function: HelpButton_2
        function HelpButton_2Pushed(app, event)
            
        % Create a cell array to store the help text for each section
        helpText = {
            'Random Forest Parameters Customization', ...
            ['This section allows customization of parameters for the Random Forest model. ' ...
             'Random Forest is an ensemble machine learning method that constructs multiple decision trees ' ...
             'and combines their predictions to improve accuracy and reduce overfitting. ' ...
             'Each tree is trained on a random subset of the data and predictors, which increases model robustness ' ...
             'and helps capture complex relationships within the dataset. ' ...
             'In this section, users can define the number of decision trees used in the ensemble. ' ...
             'A larger number of trees generally improves prediction stability and model performance, ' ...
             'but it may also increase computational time. ' ...
             'Selecting an appropriate number of trees helps balance model accuracy and computational efficiency.']
        };
    
        % Function to show help for a specific section
        function showHelp(sectionTitle)
    
            index = find(strcmp(helpText, sectionTitle), 1);
    
            if ~isempty(index)
                uialert(app.UIFigure_Main, ...
                    helpText{index + 1}, ...
                    'Random Forest Parameters Customization', ...
                    'Icon','info');
            else
                uialert(app.UIFigure_Main, ...
                    'Help text not found for the selected section.', ...
                    'Error', ...
                    'Icon','error');
            end
    
        end
    
        showHelp('Random Forest Parameters Customization');

        end

        % Button pushed function: HelpButton_FullyconnectedNN
        function HelpButton_FullyconnectedNNPushed(app, event)
            % Create a cell array to store the help text for each section
            helpText = {
                'Fully-connected Neural Network Parameters Customization', ...
                ['This section allows customization of parameters for the Fully-connected Neural Network (NN) model. ' ...
                 'A fully-connected neural network consists of multiple layers of neurons where each neuron in one layer ' ...
                 'is connected to every neuron in the next layer. This architecture enables the model to learn complex ' ...
                 'nonlinear relationships within the data. ' ...
                 'Users can modify selected network parameters such as the number of hidden layers, the number of neurons ' ...
                 'in each layer, training settings, and other configuration options that influence the learning process. ' ...
                 'Adjusting these parameters allows the model capacity and training behavior to be adapted to the ' ...
                 'specific dataset and prediction task. ' ...
                 'Proper parameter selection can improve prediction performance, but more complex networks may also ' ...
                 'increase training time and risk of overfitting.']
            };
        
            % Function to show help for a specific section
            function showHelp(sectionTitle)
        
                index = find(strcmp(helpText, sectionTitle), 1);
        
                if ~isempty(index)
                    uialert(app.UIFigure_Main, ...
                        helpText{index + 1}, ...
                        'Fully-connected Neural Network Parameters Customization', ...
                        'Icon','info');
                else
                    uialert(app.UIFigure_Main, ...
                        'Help text not found for the selected section.', ...
                        'Error', ...
                        'Icon','error');
                end
        
            end
        
            showHelp('Fully-connected Neural Network Parameters Customization');

        end

        % Button pushed function: HelpButton_LSTM
        function HelpButton_LSTMPushed(app, event)
        % Create a cell array to store the help text for each section
        helpText = {
            'LSTM Neural Network Parameters Customization', ...
            ['This section allows customization of parameters for the Long Short-Term Memory (LSTM) neural network model. ' ...
             'LSTM is a specialized type of recurrent neural network designed to learn patterns and dependencies in sequential or time-dependent data. ' ...
             'It uses memory cells and gating mechanisms to retain relevant information over longer sequences while discarding less important data. ' ...
             'Users can modify selected network parameters such as the number of LSTM units, training settings, and other configuration options ' ...
             'that influence the learning process and model performance. ' ...
             'Adjusting these parameters allows the network architecture and training behavior to be adapted to the specific dataset and prediction task. ' ...
             'More complex configurations may improve predictive capability but can also increase training time and the risk of overfitting.']
        };
    
        % Function to show help for a specific section
        function showHelp(sectionTitle)
    
            index = find(strcmp(helpText, sectionTitle), 1);
    
            if ~isempty(index)
                uialert(app.UIFigure_Main, ...
                    helpText{index + 1}, ...
                    'LSTM Neural Network Parameters Customization', ...
                    'Icon','info');
            else
                uialert(app.UIFigure_Main, ...
                    'Help text not found for the selected section.', ...
                    'Error', ...
                    'Icon','error');
            end
    
        end
    
        showHelp('LSTM Neural Network Parameters Customization');
        end

        % Button pushed function: HelpButton_CNN
        function HelpButton_CNNPushed(app, event)
        % Create a cell array to store the help text for each section
        helpText = {
            'Convolutional Neural Network Parameters Customization', ...
            ['This section allows customization of parameters for the Convolutional Neural Network (CNN) model. ' ...
             'CNN is a type of deep neural network that uses convolutional layers to automatically extract important features from input data. ' ...
             'It is commonly used for structured or spatial data where local patterns and hierarchical feature representations are important. ' ...
             'Users can modify selected network parameters such as the number of convolutional layers, filter sizes, number of filters, ' ...
             'pooling operations, and training settings that influence the learning process. ' ...
             'Adjusting these parameters allows the network architecture to be adapted to the complexity of the dataset and prediction task. ' ...
             'More complex configurations may improve feature extraction and prediction accuracy but can also increase computational cost ' ...
             'and training time.']
        };
    
        % Function to show help for a specific section
        function showHelp(sectionTitle)
    
            index = find(strcmp(helpText, sectionTitle), 1);
    
            if ~isempty(index)
                uialert(app.UIFigure_Main, ...
                    helpText{index + 1}, ...
                    'Convolutional Neural Network Parameters Customization', ...
                    'Icon','info');
            else
                uialert(app.UIFigure_Main, ...
                    'Help text not found for the selected section.', ...
                    'Error', ...
                    'Icon','error');
            end
    
        end
    
        showHelp('Convolutional Neural Network Parameters Customization');
        end

        % Value changed function: CheckBox_Keep_for_further_predictions
        function CheckBox_Keep_for_further_predictionsValueChanged(app, event)
            value = app.CheckBox_Keep_for_further_predictions.Value;
            
        end

        % Button pushed function: Button_Generate_HTML_reports
        function Button_Generate_HTML_reportsPushed(app, event)
        app.generate_HTML_report = 1;

        if ~isempty(app.dataG)
        app.dataG_no = table2array(app.dataG(:, 2:end));
        end

        if ~isempty(app.dataM) 
        app.dataM_no = table2array(app.dataM(:, 2:end));
        
        UniPred_M_var = app.dataM_no(:,:); %... add comment fixing outputs
        UniPred_M_var = cell2mat(UniPred_M_var);
        UniPred_M_var = UniPred_M_var';

        end

        if ~isempty(app.dataPhe)
        dataPhe_no_var = table2cell(app.dataPhe(:, 2:end));
        dataPhe_no_var = cell2mat(dataPhe_no_var);
        UniPred_P_var = dataPhe_no_var';
        end

        if app.CheckBox_Genome_Metabolome_BoxValue == 1
            app.y_output_var = UniPred_M_var;
        elseif app.CheckBox_Metabolome_Phenotype_BoxValue == 1
            app.y_output_var = UniPred_P_var;
        elseif app.CheckBox_Genome_Phenotype_BoxValue == 1
            app.y_output_var = UniPred_P_var;
        elseif app.CheckBox_Genome_Metabolome_Phenotype_BoxValue == 1
            app.y_output_var = UniPred_P_var;
        elseif app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype_BoxValue == 1
            app.y_output_var = UniPred_P_var;
        elseif app.CheckBox_OthersOMICs_Phenotype_BoxValue == 1
            app.y_output_var = UniPred_P_var;
        end

        % SELECT FILE LOCATION ================================
        if app.CheckBox_Genome_Metabolome_BoxValue == 1

            [file, path] = uiputfile('Genome_to_Metabolome_models_report.html', 'Save HTML Report As');              
            if isequal(file,0)
                return;
            end
            filename = fullfile(path,file);
            
        elseif  app.CheckBox_Metabolome_Phenotype_BoxValue == 1

            [file, path] = uiputfile('Metabolome_to_Phenotype_models_report.html', 'Save HTML Report As');              
            if isequal(file,0)
                return;
            end
            filename = fullfile(path,file);

        elseif  app.CheckBox_Genome_Phenotype_BoxValue == 1

            [file, path] = uiputfile('Genome_to_Phenotype_models_report.html', 'Save HTML Report As');              
            if isequal(file,0)
                return;
            end
            filename = fullfile(path,file);

        elseif  app.CheckBox_Genome_Metabolome_Phenotype_BoxValue == 1

            [file, path] = uiputfile('Genome_Metabolome_to_Phenotype_models_report.html', 'Save HTML Report As');              
            if isequal(file,0)
                return;
            end
            filename = fullfile(path,file);

        elseif  app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype_BoxValue == 1

            [file, path] = uiputfile('Genome_Metabolome_OtherOMICs_to_Phenotype_models_report.html', 'Save HTML Report As');              
            if isequal(file,0)
                return;
            end
            filename = fullfile(path,file);

        elseif  app.CheckBox_OthersOMICs_Phenotype_BoxValue == 1

            [file, path] = uiputfile('OtherOMICs_to_Phenotype_models_report.html', 'Save HTML Report As');              
            if isequal(file,0)
                return;
            end
            filename = fullfile(path,file);

        end

        if app.CheckBox_Genome_Metabolome_BoxValue == 1 || app.CheckBox_Metabolome_Phenotype_BoxValue == 1 || app.CheckBox_Genome_Phenotype_BoxValue == 1 || app.CheckBox_OthersOMICs_Phenotype_BoxValue == 1

                % RUN UNIVARIATE PREDICTION ==============================
                app.univar = 1; app.multivar = 0;          
                app.dataG_no = table2array(app.dataG(:, 2:end));
                app.dataM_no = table2array(app.dataM(:, 2:end));
                app.i_UniVarSample = app.Spinner_index_univariate_prediction.Value;
        
                UnivariatePrediction(app)
                
                LASSO_mse = app.LASSO_mse; LASSO_mae = app.LASSO_mae; LASSO_cc = app.LASSO_cc;
                RR_mse    = app.RR_mse; RR_mae    = app.RR_mae; RR_cc    = app.RR_cc;
                ENR_mse   = app.ENR_mse; ENR_mae   = app.ENR_mae; ENR_cc   = app.ENR_cc;
                GPR_mse   = app.GPR_mse; GPR_mae   = app.GPR_mae; GPR_cc   = app.GPR_cc;
                SVR_mse   = app.SVR_mse; SVR_mae   = app.SVR_mae; SVR_cc   = app.SVR_cc;
                PLS_mse   = app.PLS_mse; PLS_mae   = app.PLS_mae; PLS_cc   = app.PLS_cc;
                RF_mse    = app.RF_mse; RF_mae    = app.RF_mae; RF_cc    = app.RF_cc;
                FullNN_mse  = app.FullNN_mse; FullNN_mae  = app.FullNN_mae; FullNN_cc  = app.FullNN_cc;
                CNN_mse   = app.CNN_mse; CNN_mae   = app.CNN_mae; CNN_cc = app.CNN_cc;
                LSTM_mse  = app.LSTM_mse; LSTM_mae  = app.LSTM_mae; LSTM_cc  = app.LSTM_cc;
                
                models = {'LASSO','RR','ENR','GPR','SVR','PLS','RF','FullNN','CNN','LSTM'};
                
                for i = 1:length(models)
                
                    name = models{i};
                
                    results_uni(i).Model = name;
                    results_uni(i).MSE = eval([name '_mse']);
                    results_uni(i).MAE = eval([name '_mae']);
                    results_uni(i).CC  = eval([name '_cc']);
                
                end
                
                % RANKING (BASED ON MAX CC) ==============================               
                cc_values = [results_uni.CC];
                [~, idx] = sort(cc_values,'descend');
                
                N = length(results_uni);
                
                top_limit = ceil(N/3);
                mid_limit = ceil(2*N/3);
                
                rank_category_uni = strings(1,N);
                
                for r = 1:N
                
                    if r <= top_limit
                        rank_category_uni(idx(r)) = "top";
                
                    elseif r <= mid_limit
                        rank_category_uni(idx(r)) = "middle";
                
                    else
                        rank_category_uni(idx(r)) = "bottom";
                    end
                
                end
                
                % CREATE HTML FILE ================================                
                fid = fopen(filename,'w');
                
                fprintf(fid,'<html><head><meta charset="utf-8">');
                fprintf(fid,'<style>');
                fprintf(fid,'body {font-family: Arial;}');
                fprintf(fid,'table {border-collapse: collapse; width: 90%%; margin-bottom:40px;}');
                fprintf(fid,'th,td {border:1px solid #444; padding:8px; text-align:center;}');
                fprintf(fid,'th {background:#eaeaea;}');                
                fprintf(fid,'.top {background:#c7f5d9;}');
                fprintf(fid,'.middle {background:#ffe8b3;}');
                fprintf(fid,'.bottom {background:#ffd6d6;}');                
                fprintf(fid,'.icon {font-size:18px;}');                
                fprintf(fid,'</style></head><body>');
                
                % UNIVARIATE TABLE =====================================                
                fprintf(fid,'<h2>Univariate Prediction Models</h2>');
                fprintf(fid,['<table><tr>' ...
                '<th>Model</th>' ...
                '<th>MSE</th>' ...
                '<th>MAE</th>' ...
                '<th>Correlation Coefficient</th>' ...
                '<th>Evaluation</th>' ...
                '</tr>']);
                
                for i = 1:N
                
                    category = rank_category_uni(i);
                
                    if category == "top"
                        icon = '&#10004;';
                        class = 'top';
                
                    elseif category == "middle"
                        icon = '&#9888;';
                        class = 'middle';
                
                    else
                        icon = '&#10060;';
                        class = 'bottom';
                    end
                
                    fprintf(fid,['<tr class="%s">' ...
                        '<td>%s</td>' ...
                        '<td>%.3f</td>' ...
                        '<td>%.3f</td>' ...
                        '<td>%.3f</td>' ...
                        '<td class="icon">%s</td>' ...
                        '</tr>'], ...
                        class, ...
                        results_uni(i).Model, ...
                        results_uni(i).MSE, ...
                        results_uni(i).MAE, ...
                        results_uni(i).CC, ...
                        icon);
                
                end
                
                fprintf(fid,'</table>');
                
                % RUN MULTIVARIATE PREDICTION ===========================
                app.univar = 0; app.multivar = 1;  

                if size(app.y_output_var,1) == 1
                    UnivariatePrediction(app)
                else
                    MultivariatePrediction(app)
                end
                
                LASSO_mse = app.LASSO_mse; LASSO_mae = app.LASSO_mae; LASSO_cc = app.LASSO_cc;
                RR_mse    = app.RR_mse; RR_mae    = app.RR_mae; RR_cc    = app.RR_cc;
                ENR_mse   = app.ENR_mse; ENR_mae   = app.ENR_mae; ENR_cc   = app.ENR_cc;
                GPR_mse   = app.GPR_mse; GPR_mae   = app.GPR_mae; GPR_cc   = app.GPR_cc;
                SVR_mse   = app.SVR_mse; SVR_mae   = app.SVR_mae; SVR_cc   = app.SVR_cc;
                PLS_mse   = app.PLS_mse; PLS_mae   = app.PLS_mae; PLS_cc   = mean(mean(app.PLS_cc));
                RF_mse    = app.RF_mse; RF_mae    = app.RF_mae; RF_cc    = app.RF_cc;
                FullNN_mse  = app.FullNN_mse; FullNN_mae  = app.FullNN_mae; FullNN_cc  = app.FullNN_cc;
                CNN_mse   = app.CNN_mse; CNN_mae   = app.CNN_mae; CNN_cc = app.CNN_cc;
                LSTM_mse  = app.LSTM_mse; LSTM_mae  = app.LSTM_mae; LSTM_cc  = app.LSTM_cc;
                
                
                for i = 1:length(models)
                
                    name = models{i};
                
                    results_multi(i).Model = name;
                    results_multi(i).MSE = eval([name '_mse']);
                    results_multi(i).MAE = eval([name '_mae']);
                    results_multi(i).CC  = eval([name '_cc']);
                
                end
                
                cc_values = [results_multi.CC];
                [~, idx] = sort(cc_values,'descend');
                
                rank_category_multi = strings(1,N);
                
                for r = 1:N
                
                    if r <= top_limit
                        rank_category_multi(idx(r)) = "top";
                
                    elseif r <= mid_limit
                        rank_category_multi(idx(r)) = "middle";
                
                    else
                        rank_category_multi(idx(r)) = "bottom";
                    end
                
                end
                
                
                % MULTIVARIATE TABLE =====================================              
                fprintf(fid,'<h2>Multivariate Prediction Models</h2>');
                
                fprintf(fid,['<table><tr>' ...
                '<th>Model</th>' ...
                '<th>MSE</th>' ...
                '<th>MAE</th>' ...
                '<th>Correlation Coefficient</th>' ...
                '<th>Evaluation</th>' ...
                '</tr>']);
                
                for i = 1:N
                
                    category = rank_category_multi(i);
                
                    if category == "top"
                        icon = '&#10004;';
                        class = 'top';
                
                    elseif category == "middle"
                        icon = '&#9888;';
                        class = 'middle';
                
                    else
                        icon = '&#10060;';
                        class = 'bottom';
                    end
                
                    fprintf(fid,['<tr class="%s">' ...
                        '<td>%s</td>' ...
                        '<td>%.3f</td>' ...
                        '<td>%.3f</td>' ...
                        '<td>%.3f</td>' ...
                        '<td class="icon">%s</td>' ...
                        '</tr>'], ...
                        class, ...
                        results_multi(i).Model, ...
                        results_multi(i).MSE, ...
                        results_multi(i).MAE, ...
                        results_multi(i).CC, ...
                        icon);
                
                end
                
                fprintf(fid,'</table>');                              
                fprintf(fid,'<p>✔ Top third of models &nbsp;&nbsp; ⚠ Middle third &nbsp;&nbsp; ✖ Lowest performing models</p>');            
                fprintf(fid,'</body></html>');               
                fclose(fid);

        elseif app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype_BoxValue == 1 || app.CheckBox_Genome_Metabolome_Phenotype_BoxValue == 1

                % RUN UNIVARIATE PREDICTION ==============================
                app.univar = 1; app.multivar = 0;          
                app.dataG_no = table2array(app.dataG(:, 2:end));
                app.dataM_no = table2array(app.dataM(:, 2:end));
                app.i_UniVarSample = app.Spinner_index_univariate_prediction.Value;
        
                UnivariatePrediction(app)
                
                RF_mse    = app.RF_mse; RF_mae    = app.RF_mae; RF_cc    = app.RF_cc;
                FullNN_mse  = app.FullNN_mse; FullNN_mae  = app.FullNN_mae; FullNN_cc  = app.FullNN_cc;
                CNN_mse   = app.CNN_mse; CNN_mae   = app.CNN_mae; CNN_cc = app.CNN_cc;
                LSTM_mse  = app.LSTM_mse; LSTM_mae  = app.LSTM_mae; LSTM_cc  = app.LSTM_cc;
                
                models = {'RF','FullNN','CNN','LSTM'};
                
                for i = 1:length(models)
                
                    name = models{i};
                
                    results_uni(i).Model = name;
                    results_uni(i).MSE = eval([name '_mse']);
                    results_uni(i).MAE = eval([name '_mae']);
                    results_uni(i).CC  = eval([name '_cc']);
                
                end
                
                % RANKING (BASED ON MAX CC) ==============================               
                cc_values = [results_uni.CC];
                [~, idx] = sort(cc_values,'descend');
                
                N = length(results_uni);
                
                top_limit = ceil(N/3);
                mid_limit = ceil(2*N/3);
                
                rank_category_uni = strings(1,N);
                
                for r = 1:N
                
                    if r <= top_limit
                        rank_category_uni(idx(r)) = "top";
                
                    elseif r <= mid_limit
                        rank_category_uni(idx(r)) = "middle";
                
                    else
                        rank_category_uni(idx(r)) = "bottom";
                    end
                
                end
                

                % CREATE HTML FILE =====================================               
                fid = fopen(filename,'w');
                
                fprintf(fid,'<html><head><meta charset="utf-8">');
                
                fprintf(fid,'<style>');
                fprintf(fid,'body {font-family: Arial;}');
                fprintf(fid,'table {border-collapse: collapse; width: 90%%; margin-bottom:40px;}');
                fprintf(fid,'th,td {border:1px solid #444; padding:8px; text-align:center;}');
                fprintf(fid,'th {background:#eaeaea;}');
                
                fprintf(fid,'.top {background:#c7f5d9;}');
                fprintf(fid,'.middle {background:#ffe8b3;}');
                fprintf(fid,'.bottom {background:#ffd6d6;}');
                
                fprintf(fid,'.icon {font-size:18px;}');
                
                fprintf(fid,'</style></head><body>');

                % UNIVARIATE TABLE =====================================               
                fprintf(fid,'<h2>Univariate Prediction Models</h2>');
                
                fprintf(fid,['<table><tr>' ...
                '<th>Model</th>' ...
                '<th>MSE</th>' ...
                '<th>MAE</th>' ...
                '<th>Correlation Coefficient</th>' ...
                '<th>Evaluation</th>' ...
                '</tr>']);
                
                for i = 1:N
                
                    category = rank_category_uni(i);
                
                    if category == "top"
                        icon = '&#10004;';
                        class = 'top';
                
                    elseif category == "middle"
                        icon = '&#9888;';
                        class = 'middle';
                
                    else
                        icon = '&#10060;';
                        class = 'bottom';
                    end
                
                    fprintf(fid,['<tr class="%s">' ...
                        '<td>%s</td>' ...
                        '<td>%.3f</td>' ...
                        '<td>%.3f</td>' ...
                        '<td>%.3f</td>' ...
                        '<td class="icon">%s</td>' ...
                        '</tr>'], ...
                        class, ...
                        results_uni(i).Model, ...
                        results_uni(i).MSE, ...
                        results_uni(i).MAE, ...
                        results_uni(i).CC, ...
                        icon);
                
                end
                
                fprintf(fid,'</table>');
                
                
                % =====================================
                % RUN MULTIVARIATE PREDICTION =============================
                app.univar = 0; app.multivar = 1;  

                if size(app.y_output_var,1) == 1
                    UnivariatePrediction(app)
                else
                    MultivariatePrediction(app)
                end

                RF_mse    = app.RF_mse; RF_mae    = app.RF_mae; RF_cc    = app.RF_cc;
                FullNN_mse  = app.FullNN_mse; FullNN_mae  = app.FullNN_mae; FullNN_cc  = app.FullNN_cc;
                CNN_mse   = app.CNN_mse; CNN_mae   = app.CNN_mae; CNN_cc = app.CNN_cc;
                LSTM_mse  = app.LSTM_mse; LSTM_mae  = app.LSTM_mae; LSTM_cc  = app.LSTM_cc;
                
                
                for i = 1:length(models)
                
                    name = models{i};
                
                    results_multi(i).Model = name;
                    results_multi(i).MSE = eval([name '_mse']);
                    results_multi(i).MAE = eval([name '_mae']);
                    results_multi(i).CC  = eval([name '_cc']);
                
                end
                
                cc_values = [results_multi.CC];
                [~, idx] = sort(cc_values,'descend');
                
                rank_category_multi = strings(1,N);
                
                for r = 1:N
                
                    if r <= top_limit
                        rank_category_multi(idx(r)) = "top";
                
                    elseif r <= mid_limit
                        rank_category_multi(idx(r)) = "middle";
                
                    else
                        rank_category_multi(idx(r)) = "bottom";
                    end
                
                end
                
                
                % =====================================
                % MULTIVARIATE TABLE
                % =====================================
                
                fprintf(fid,'<h2>Multivariate Prediction Models</h2>');
                
                fprintf(fid,['<table><tr>' ...
                '<th>Model</th>' ...
                '<th>MSE</th>' ...
                '<th>MAE</th>' ...
                '<th>Correlation Coefficient</th>' ...
                '<th>Evaluation</th>' ...
                '</tr>']);
                
                for i = 1:N
                
                    category = rank_category_multi(i);
                
                    if category == "top"
                        icon = '&#10004;';
                        class = 'top';
                
                    elseif category == "middle"
                        icon = '&#9888;';
                        class = 'middle';
                
                    else
                        icon = '&#10060;';
                        class = 'bottom';
                    end
                
                    fprintf(fid,['<tr class="%s">' ...
                        '<td>%s</td>' ...
                        '<td>%.3f</td>' ...
                        '<td>%.3f</td>' ...
                        '<td>%.3f</td>' ...
                        '<td class="icon">%s</td>' ...
                        '</tr>'], ...
                        class, ...
                        results_multi(i).Model, ...
                        results_multi(i).MSE, ...
                        results_multi(i).MAE, ...
                        results_multi(i).CC, ...
                        icon);
                
                end
                
                fprintf(fid,'</table>');
                
                
                fprintf(fid,'<p>✔ Top third of models &nbsp;&nbsp; ⚠ Middle third &nbsp;&nbsp; ✖ Lowest performing models</p>');
                
                fprintf(fid,'</body></html>');
                
                fclose(fid);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure_Main = uifigure('Visible', 'off');
            app.UIFigure_Main.Name = 'MATLAB App';
            app.GridLayout_Main = uigridlayout(app.UIFigure_Main);
            app.TabGroup_Main = uitabgroup(app.GridLayout_Main);
            app.MainTab_PanOMICsApp = uitab(app.TabGroup_Main);
            app.MainTab_PanOMICsApp.Title = 'Main';
            app.GridLayout_PanOMICsApp = uigridlayout(app.MainTab_PanOMICsApp);
            app.Subtitle_UniWien_logo_image = uiimage(app.GridLayout_PanOMICsApp);
            app.Subtitle_UniWien_logo_image.ImageSource = fullfile(pathToMLAPP, 'logos', 'Uni_wien_logo.png');
            app.Subtitle_Mosys_logo_image = uiimage(app.GridLayout_PanOMICsApp);
            app.Subtitle_Mosys_logo_image.ImageSource = fullfile(pathToMLAPP, 'logos', 'MoSys_logo.png');
            app.Subtitle_Vime_logo_image = uiimage(app.GridLayout_PanOMICsApp);
            app.Subtitle_Vime_logo_image.ImageSource = fullfile(pathToMLAPP, 'logos', 'Vime_logo.png');
            app.Subtitle_FEEC_logo_image = uiimage(app.GridLayout_PanOMICsApp);
            app.Subtitle_FEEC_logo_image.ImageSource = fullfile(pathToMLAPP, 'logos', 'Feec.png');
            app.Subtitle_BUT_logo_image = uiimage(app.GridLayout_PanOMICsApp);
            app.Subtitle_BUT_logo_image.ImageSource = fullfile(pathToMLAPP, 'logos', 'BUT_logo_T.png');
            app.RESETButton = uibutton(app.GridLayout_PanOMICsApp, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.Title_PanOMICs_app = uilabel(app.GridLayout_PanOMICsApp);
            app.Title_PanOMICs_app.Text = 'PanOMICs App (Computer-aided approach from Genomics to panOMICs predictions)';
            app.Title_BUT_logo_image = uiimage(app.GridLayout_PanOMICsApp);
            app.Title_BUT_logo_image.ImageSource = fullfile(pathToMLAPP, 'logos', 'BUT_logo_T.png');
            app.Title_Mosys_logo_image = uiimage(app.GridLayout_PanOMICsApp);
            app.Title_Mosys_logo_image.ImageSource = fullfile(pathToMLAPP, 'logos', 'MoSys_logo.png');
            app.Panel_5_Predictive_modeling = uipanel(app.GridLayout_PanOMICsApp);
            app.GridLayout_Panel_5_Predictive_modeling = uigridlayout(app.Panel_5_Predictive_modeling);
            app.Label_Panel_5_Predictive_modeling = uilabel(app.GridLayout_Panel_5_Predictive_modeling);
            app.Label_Panel_5_Predictive_modeling.Text = '  4] Predictive modelling';
            app.Panel_Multivariate_prediction = uipanel(app.GridLayout_Panel_5_Predictive_modeling);
            app.Panel_Multivariate_prediction.Title = 'Multivariate prediction';
            app.GridLayout_Panel_Multivariate_prediction = uigridlayout(app.Panel_Multivariate_prediction);
            app.UIAxes_Multivariate_prediction = uiaxes(app.GridLayout_Panel_Multivariate_prediction);
            app.UIAxes_Multivariate_prediction.XTick = [];
            app.UIAxes_Multivariate_prediction.YTick = [];
            app.MSEEditField_2Label = uilabel(app.GridLayout_Panel_Multivariate_prediction);
            app.MSEEditField_2Label.Text = 'MSE';
            app.EditField_MSE_multivariate_prediction = uieditfield(app.GridLayout_Panel_Multivariate_prediction, 'numeric');
            app.MAEEditField_2Label = uilabel(app.GridLayout_Panel_Multivariate_prediction);
            app.MAEEditField_2Label.Text = 'MAE';
            app.EditField_MAE_multivariate_prediction = uieditfield(app.GridLayout_Panel_Multivariate_prediction, 'numeric');
            app.EditField_cc_multivariate_prediction = uieditfield(app.GridLayout_Panel_Multivariate_prediction, 'numeric');
            app.CorrelationcoefEditField_2Label = uilabel(app.GridLayout_Panel_Multivariate_prediction);
            app.CorrelationcoefEditField_2Label.Text = 'Correlation coef';
            app.Button_perform_multivariate_prediction = uibutton(app.GridLayout_Panel_Multivariate_prediction, 'push');
            app.Button_perform_multivariate_prediction.ButtonPushedFcn = createCallbackFcn(app, @Button_perform_multivariate_predictionPushed, true);
            app.Button_perform_multivariate_prediction.Text = {'Perform '; 'multivariate'; 'prediction'};
            app.Panel_Univariate_prediction = uipanel(app.GridLayout_Panel_5_Predictive_modeling);
            app.Panel_Univariate_prediction.Title = 'Univariate prediction';
            app.GridLayout_Panel_Univariate_prediction = uigridlayout(app.Panel_Univariate_prediction);
            app.UIAxes_Univariate_prediction = uiaxes(app.GridLayout_Panel_Univariate_prediction);
            app.UIAxes_Univariate_prediction.XTick = [];
            app.UIAxes_Univariate_prediction.YTick = [];
            app.SamplenumberLabel = uilabel(app.GridLayout_Panel_Univariate_prediction);
            app.SamplenumberLabel.Text = 'Index';
            app.Spinner_index_univariate_prediction = uispinner(app.GridLayout_Panel_Univariate_prediction);
            app.Spinner_index_univariate_prediction.ValueChangedFcn = createCallbackFcn(app, @Spinner_index_univariate_predictionValueChanged, true);
            app.Spinner_index_univariate_prediction.Value = 1;
            app.Label = uilabel(app.GridLayout_Panel_Univariate_prediction);
            app.Label.Text = '';
            app.EditField_names_univ_prediction = uieditfield(app.GridLayout_Panel_Univariate_prediction, 'text');
            app.EditField_names_univ_prediction.ValueChangedFcn = createCallbackFcn(app, @EditField_names_univ_predictionValueChanged, true);
            app.Label_EditField_names_univ_prediction = uilabel(app.GridLayout_Panel_Univariate_prediction);
            app.Label_EditField_names_univ_prediction.Text = 'Metabolite:';
            app.MAEEditFieldLabel = uilabel(app.GridLayout_Panel_Univariate_prediction);
            app.MAEEditFieldLabel.Text = 'MAE';
            app.EditField_MAE_univariate_prediction = uieditfield(app.GridLayout_Panel_Univariate_prediction, 'numeric');
            app.CorrelationcoefEditFieldLabel = uilabel(app.GridLayout_Panel_Univariate_prediction);
            app.CorrelationcoefEditFieldLabel.Text = 'Correlation coef';
            app.EditField_cc_univariate_prediction = uieditfield(app.GridLayout_Panel_Univariate_prediction, 'numeric');
            app.Button_perform_univariate_prediction = uibutton(app.GridLayout_Panel_Univariate_prediction, 'push');
            app.Button_perform_univariate_prediction.ButtonPushedFcn = createCallbackFcn(app, @Button_perform_univariate_predictionPushed, true);
            app.Button_perform_univariate_prediction.Text = {'Perform '; 'univariate'; 'prediction'};
            app.MSEEditFieldLabel = uilabel(app.GridLayout_Panel_Univariate_prediction);
            app.MSEEditFieldLabel.Text = 'MSE';
            app.EditField_MSE_univariate_prediction = uieditfield(app.GridLayout_Panel_Univariate_prediction, 'numeric');
            app.HelpButton_Panel_5_Predictive_modelling = uibutton(app.GridLayout_Panel_5_Predictive_modeling, 'push');
            app.HelpButton_Panel_5_Predictive_modelling.Text = 'Help';
            app.CheckBox_Keep_for_further_predictions = uicheckbox(app.GridLayout_Panel_5_Predictive_modeling);
            app.CheckBox_Keep_for_further_predictions.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Keep_for_further_predictionsValueChanged, true);
            app.CheckBox_Keep_for_further_predictions.Text = 'Keep for further predictions';
            app.Button_Keep_as_Pretrained_yourself = uibutton(app.GridLayout_Panel_5_Predictive_modeling, 'push');
            app.Button_Keep_as_Pretrained_yourself.ButtonPushedFcn = createCallbackFcn(app, @Button_Keep_as_Pretrained_yourselfPushed, true);
            app.Button_Keep_as_Pretrained_yourself.Text = 'Save Pre-trained models as (*.mat)';
            app.Button_Generate_HTML_reports = uibutton(app.GridLayout_Panel_5_Predictive_modeling, 'push');
            app.Button_Generate_HTML_reports.ButtonPushedFcn = createCallbackFcn(app, @Button_Generate_HTML_reportsPushed, true);
            app.Button_Generate_HTML_reports.Text = 'Generate HTML reports for model optimization';
            app.Panel_4_Prediction_methods = uipanel(app.GridLayout_PanOMICsApp);
            app.GridLayout_Panel_4_Prediction_methods = uigridlayout(app.Panel_4_Prediction_methods);
            app.Panel_Deep_learning_approaches = uipanel(app.GridLayout_Panel_4_Prediction_methods);
            app.Panel_Deep_learning_approaches.Title = 'Deep learning approaches';
            app.GridLayout_Panel_Deep_learning_approaches = uigridlayout(app.Panel_Deep_learning_approaches);
            app.Button_Deep_learning_approaches_Parameters = uibutton(app.GridLayout_Panel_Deep_learning_approaches, 'push');
            app.Button_Deep_learning_approaches_Parameters.ButtonPushedFcn = createCallbackFcn(app, @Button_Deep_learning_approaches_ParametersPushed, true);
            app.Button_Deep_learning_approaches_Parameters.Text = 'Parameters';
            app.HelpButton_Prediction_methods_Deep_learning_approaches = uibutton(app.GridLayout_Panel_Deep_learning_approaches, 'push');
            app.HelpButton_Prediction_methods_Deep_learning_approaches.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_Prediction_methods_Deep_learning_approachesPushed, true);
            app.HelpButton_Prediction_methods_Deep_learning_approaches.Text = 'Help';
            app.CheckBox_LSTM = uicheckbox(app.GridLayout_Panel_Deep_learning_approaches);
            app.CheckBox_LSTM.ValueChangedFcn = createCallbackFcn(app, @CheckBox_LSTMValueChanged, true);
            app.CheckBox_LSTM.Text = 'LSTM';
            app.CheckBox_CNN = uicheckbox(app.GridLayout_Panel_Deep_learning_approaches);
            app.CheckBox_CNN.ValueChangedFcn = createCallbackFcn(app, @CheckBox_CNNValueChanged, true);
            app.CheckBox_CNN.Text = 'CNN';
            app.CheckBox_fullyconnected_NN = uicheckbox(app.GridLayout_Panel_Deep_learning_approaches);
            app.CheckBox_fullyconnected_NN.ValueChangedFcn = createCallbackFcn(app, @CheckBox_fullyconnected_NNValueChanged, true);
            app.CheckBox_fullyconnected_NN.Text = 'fully-connected NN';
            app.Panel_Nonlinear_approaches = uipanel(app.GridLayout_Panel_4_Prediction_methods);
            app.Panel_Nonlinear_approaches.Title = 'Non-linear approaches';
            app.GridLayout_Panel_Nonlinear_approaches = uigridlayout(app.Panel_Nonlinear_approaches);
            app.Button_Nonlinear_approaches_Parameters = uibutton(app.GridLayout_Panel_Nonlinear_approaches, 'push');
            app.Button_Nonlinear_approaches_Parameters.ButtonPushedFcn = createCallbackFcn(app, @Button_Nonlinear_approaches_ParametersPushed, true);
            app.Button_Nonlinear_approaches_Parameters.Text = 'Parameters';
            app.HelpButton_Prediction_methods_Nonlinear_approaches = uibutton(app.GridLayout_Panel_Nonlinear_approaches, 'push');
            app.HelpButton_Prediction_methods_Nonlinear_approaches.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_Prediction_methods_Nonlinear_approachesPushed, true);
            app.HelpButton_Prediction_methods_Nonlinear_approaches.Text = 'Help';
            app.CheckBox_RandomForest = uicheckbox(app.GridLayout_Panel_Nonlinear_approaches);
            app.CheckBox_RandomForest.ValueChangedFcn = createCallbackFcn(app, @CheckBox_RandomForestValueChanged, true);
            app.CheckBox_RandomForest.Text = 'Random Forest';
            app.Panel_Linear_approches = uipanel(app.GridLayout_Panel_4_Prediction_methods);
            app.Panel_Linear_approches.Title = 'Linear approches';
            app.GridLayout_Panel_Linear_approaches = uigridlayout(app.Panel_Linear_approches);
            app.Button_Linear_approaches_Parameters = uibutton(app.GridLayout_Panel_Linear_approaches, 'push');
            app.Button_Linear_approaches_Parameters.ButtonPushedFcn = createCallbackFcn(app, @Button_Linear_approaches_ParametersPushed, true);
            app.Button_Linear_approaches_Parameters.Text = 'Parameters';
            app.HelpButton_Prediction_methods_Linear_approaches = uibutton(app.GridLayout_Panel_Linear_approaches, 'push');
            app.HelpButton_Prediction_methods_Linear_approaches.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_Prediction_methods_Linear_approachesPushed, true);
            app.HelpButton_Prediction_methods_Linear_approaches.Text = 'Help';
            app.CheckBox_PLS = uicheckbox(app.GridLayout_Panel_Linear_approaches);
            app.CheckBox_PLS.ValueChangedFcn = createCallbackFcn(app, @CheckBox_PLSValueChanged, true);
            app.CheckBox_PLS.Text = 'PLS';
            app.CheckBox_SVR = uicheckbox(app.GridLayout_Panel_Linear_approaches);
            app.CheckBox_SVR.ValueChangedFcn = createCallbackFcn(app, @CheckBox_SVRValueChanged, true);
            app.CheckBox_SVR.Text = 'SVR';
            app.CheckBox_GPR = uicheckbox(app.GridLayout_Panel_Linear_approaches);
            app.CheckBox_GPR.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GPRValueChanged, true);
            app.CheckBox_GPR.Text = 'GPR';
            app.CheckBox_ENR = uicheckbox(app.GridLayout_Panel_Linear_approaches);
            app.CheckBox_ENR.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ENRValueChanged, true);
            app.CheckBox_ENR.Text = 'ENR';
            app.CheckBox_RR = uicheckbox(app.GridLayout_Panel_Linear_approaches);
            app.CheckBox_RR.ValueChangedFcn = createCallbackFcn(app, @CheckBox_RRValueChanged, true);
            app.CheckBox_RR.Text = 'RR';
            app.CheckBox_LASSO = uicheckbox(app.GridLayout_Panel_Linear_approaches);
            app.CheckBox_LASSO.ValueChangedFcn = createCallbackFcn(app, @CheckBox_LASSOValueChanged, true);
            app.CheckBox_LASSO.Text = 'LASSO';
            app.CheckBox_LASSO.Value = true;
            app.Label_4_Prediction_methods = uilabel(app.GridLayout_Panel_4_Prediction_methods);
            app.Label_4_Prediction_methods.Text = ' 3] Prediction methods';
            app.LoadingpretrainedmodelsPanel = uipanel(app.GridLayout_Panel_4_Prediction_methods);
            app.LoadingpretrainedmodelsPanel.Title = 'Loading pre-trained models';
            app.GridLayout_LoadingpretrainedmodelsPanel = uigridlayout(app.LoadingpretrainedmodelsPanel);
            app.HelpButton_Loading_pretrained_models = uibutton(app.GridLayout_LoadingpretrainedmodelsPanel, 'push');
            app.HelpButton_Loading_pretrained_models.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_Loading_pretrained_modelsPushed, true);
            app.HelpButton_Loading_pretrained_models.Text = 'Help';
            app.Upload_pretrained_models = uibutton(app.GridLayout_LoadingpretrainedmodelsPanel, 'push');
            app.Upload_pretrained_models.ButtonPushedFcn = createCallbackFcn(app, @Upload_pretrained_modelsButtonPushed, true);
            app.Upload_pretrained_models.Enable = 'off';
            app.Upload_pretrained_models.Text = {'Upload '; '(*.mat)'};
            app.CheckBox_Upload_own_model = uicheckbox(app.GridLayout_LoadingpretrainedmodelsPanel);
            app.CheckBox_Upload_own_model.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Upload_own_modelValueChanged, true);
            app.CheckBox_Upload_own_model.Text = 'Upload own model';
            app.CheckBox_Default = uicheckbox(app.GridLayout_LoadingpretrainedmodelsPanel);
            app.CheckBox_Default.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DefaultValueChanged, true);
            app.CheckBox_Default.Text = 'Default';
            app.CheckBox_Default.Value = true;
            app.Panel_3_Prediction_approches = uipanel(app.GridLayout_PanOMICsApp);
            app.GridLayout_Panel_3_Prediction_approches = uigridlayout(app.Panel_3_Prediction_approches);
            app.Label_3_Prediction_approches = uilabel(app.GridLayout_Panel_3_Prediction_approches);
            app.Label_3_Prediction_approches.Text = '2] Panome predictions';
            app.GenomicmetabolomicandpanOMICpredictionPanel = uipanel(app.GridLayout_Panel_3_Prediction_approches);
            app.GenomicmetabolomicandpanOMICpredictionPanel.Title = 'Genomic, metabolomic and panOMIC prediction';
            app.GridLayout = uigridlayout(app.GenomicmetabolomicandpanOMICpredictionPanel);
            app.CheckBox_OthersOMICs_Phenotype = uicheckbox(app.GridLayout);
            app.CheckBox_OthersOMICs_Phenotype.ValueChangedFcn = createCallbackFcn(app, @CheckBox_OthersOMICs_PhenotypeValueChanged, true);
            app.CheckBox_OthersOMICs_Phenotype.Text = 'OtherOMICs–> Phenotype';
            app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype = uicheckbox(app.GridLayout);
            app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Genome_Metabolome_OtherOMICs_PhenotypeValueChanged, true);
            app.CheckBox_Genome_Metabolome_OtherOMICs_Phenotype.Text = 'Genome + Metabolome +  OtherOMICs –> Phenotype';
            app.CheckBox_Genome_Metabolome_Phenotype = uicheckbox(app.GridLayout);
            app.CheckBox_Genome_Metabolome_Phenotype.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Genome_Metabolome_PhenotypeValueChanged, true);
            app.CheckBox_Genome_Metabolome_Phenotype.Text = 'Genome +  Metabolome  –> Phenotype';
            app.CheckBox_Genome_Phenotype = uicheckbox(app.GridLayout);
            app.CheckBox_Genome_Phenotype.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Genome_PhenotypeValueChanged, true);
            app.CheckBox_Genome_Phenotype.Text = 'Genome–> Phenotype';
            app.CheckBox_Metabolome_Phenotype = uicheckbox(app.GridLayout);
            app.CheckBox_Metabolome_Phenotype.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Metabolome_PhenotypeValueChanged, true);
            app.CheckBox_Metabolome_Phenotype.Text = 'Metabolome –> Phenotype';
            app.CheckBox_Genome_Metabolome = uicheckbox(app.GridLayout);
            app.CheckBox_Genome_Metabolome.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Genome_MetabolomeValueChanged, true);
            app.CheckBox_Genome_Metabolome.Text = 'Genome -> Metabolome';
            app.CheckBox_Genome_Metabolome.Value = true;
            app.PolygenicRiskScorePanel = uipanel(app.GridLayout_Panel_3_Prediction_approches);
            app.PolygenicRiskScorePanel.Title = 'Polygenic Risk Score';
            app.GridLayout_Panel_PolygenicRiskScore = uigridlayout(app.PolygenicRiskScorePanel);
            app.Label_Genotype_Phenotype = uilabel(app.GridLayout_Panel_PolygenicRiskScore);
            app.Label_Genotype_Phenotype.Text = 'Genotype –> Phenotype';
            app.Button_PolygenicRiskScore = uibutton(app.GridLayout_Panel_PolygenicRiskScore, 'push');
            app.Button_PolygenicRiskScore.ButtonPushedFcn = createCallbackFcn(app, @Button_PolygenicRiskScorePushed, true);
            app.Button_PolygenicRiskScore.Text = 'Perform Polygenic Risk Calculation';
            app.HelpButton_Panel_3_Prediction_approches = uibutton(app.GridLayout_Panel_3_Prediction_approches, 'push');
            app.HelpButton_Panel_3_Prediction_approches.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_Panel_3_Prediction_approchesPushed, true);
            app.HelpButton_Panel_3_Prediction_approches.Text = 'Help';
            app.Panel_2_Visualization = uipanel(app.GridLayout_PanOMICsApp);
            app.GridLayout_Panel_2_Visualization_of_input_data = uigridlayout(app.Panel_2_Visualization);
            app.Panel_PCA_BoxPlots = uipanel(app.GridLayout_Panel_2_Visualization_of_input_data);
            app.GridLayout_Panel_2_3_PCA_BoxPlots = uigridlayout(app.Panel_PCA_BoxPlots);
            app.UIAxes_PCA = uiaxes(app.GridLayout_Panel_2_3_PCA_BoxPlots);
            xlabel(app.UIAxes_PCA, 'PC1')
            ylabel(app.UIAxes_PCA, 'PC2')
            app.UIAxes_PCA.XTick = [];
            app.UIAxes_PCA.YTick = [];
            app.UIAxes_Box_plots = uiaxes(app.GridLayout_Panel_2_3_PCA_BoxPlots);
            xlabel(app.UIAxes_Box_plots, 'Accessions [–]')
            app.UIAxes_Box_plots.XTick = [];
            app.UIAxes_Box_plots.YTick = [];
            app.HelpButton_Visualization_PCA_BoxPlots = uibutton(app.GridLayout_Panel_2_3_PCA_BoxPlots, 'push');
            app.HelpButton_Visualization_PCA_BoxPlots.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_Visualization_PCA_BoxPlotsPushed, true);
            app.HelpButton_Visualization_PCA_BoxPlots.Text = 'Help';
            app.Button_See_in_detail_BoxPlots = uibutton(app.GridLayout_Panel_2_3_PCA_BoxPlots, 'push');
            app.Button_See_in_detail_BoxPlots.ButtonPushedFcn = createCallbackFcn(app, @Button_See_in_detail_BoxPlotsPushed, true);
            app.Button_See_in_detail_BoxPlots.Text = 'See detail';
            app.Button_BoxPlots = uibutton(app.GridLayout_Panel_2_3_PCA_BoxPlots, 'push');
            app.Button_BoxPlots.ButtonPushedFcn = createCallbackFcn(app, @Button_BoxPlotsPushed, true);
            app.Button_BoxPlots.Text = 'Box plots';
            app.Button_See_in_detail_PCA = uibutton(app.GridLayout_Panel_2_3_PCA_BoxPlots, 'push');
            app.Button_See_in_detail_PCA.ButtonPushedFcn = createCallbackFcn(app, @Button_See_in_detail_PCAPushed, true);
            app.Button_See_in_detail_PCA.Text = 'See detail';
            app.Button_PCA = uibutton(app.GridLayout_Panel_2_3_PCA_BoxPlots, 'push');
            app.Button_PCA.ButtonPushedFcn = createCallbackFcn(app, @Button_PCAPushed, true);
            app.Button_PCA.Text = 'PCA';
            app.Panel_Insights_into_metabolites = uipanel(app.GridLayout_Panel_2_Visualization_of_input_data);
            app.Panel_Insights_into_metabolites.Title = 'Insights into individual metabolites distribution';
            app.GridLayout_Panel_Insights_into_metabolites = uigridlayout(app.Panel_Insights_into_metabolites);
            app.UIAxes_Histogram = uiaxes(app.GridLayout_Panel_Insights_into_metabolites);
            xlabel(app.UIAxes_Histogram, 'Accessions [–]')
            app.UIAxes_Histogram.XTick = [];
            app.UIAxes_Histogram.YTick = [];
            app.Button_See_in_detail_Histogram = uibutton(app.GridLayout_Panel_Insights_into_metabolites, 'push');
            app.Button_See_in_detail_Histogram.ButtonPushedFcn = createCallbackFcn(app, @Button_See_in_detail_HistogramPushed, true);
            app.Button_See_in_detail_Histogram.Text = 'See detail';
            app.HelpButton_Insight_into_metabolites = uibutton(app.GridLayout_Panel_Insights_into_metabolites, 'push');
            app.HelpButton_Insight_into_metabolites.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_Insight_into_metabolitesPushed, true);
            app.HelpButton_Insight_into_metabolites.Text = 'Help';
            app.MetabolitesindexLabel = uilabel(app.GridLayout_Panel_Insights_into_metabolites);
            app.MetabolitesindexLabel.Text = 'Metabolites index:';
            app.MetaboliteLabel = uilabel(app.GridLayout_Panel_Insights_into_metabolites);
            app.MetaboliteLabel.Text = 'Metabolite:';
            app.Button_Histogram = uibutton(app.GridLayout_Panel_Insights_into_metabolites, 'push');
            app.Button_Histogram.ButtonPushedFcn = createCallbackFcn(app, @Button_HistogramPushed, true);
            app.Button_Histogram.Text = 'Perform Histogram';
            app.EditField_Metabolite_name = uieditfield(app.GridLayout_Panel_Insights_into_metabolites, 'text');
            app.EditField_Metabolite_name.ValueChangedFcn = createCallbackFcn(app, @EditField_Metabolite_nameValueChanged, true);
            app.Spinner_Metabolites_index = uispinner(app.GridLayout_Panel_Insights_into_metabolites);
            app.Spinner_Metabolites_index.ValueChangedFcn = createCallbackFcn(app, @Spinner_Metabolites_indexValueChanged, true);
            app.Spinner_Metabolites_index.Value = 1;
            app.GWAS_Button = uibutton(app.GridLayout_Panel_2_Visualization_of_input_data, 'push');
            app.GWAS_Button.ButtonPushedFcn = createCallbackFcn(app, @GWAS_ButtonPushed, true);
            app.GWAS_Button.Text = 'GWAS';
            app.ChoosedataButtonGroup = uibuttongroup(app.GridLayout_Panel_2_Visualization_of_input_data);
            app.ChoosedataButtonGroup.Title = 'Choose data:';
            app.ButtonOtherOMICs = uiradiobutton(app.ChoosedataButtonGroup);
            app.ButtonOtherOMICs.Text = 'OtherOMICs';
            app.ButtonPhenotype = uiradiobutton(app.ChoosedataButtonGroup);
            app.ButtonPhenotype.Text = 'Phenotype';
            app.ButtonMetabolome = uiradiobutton(app.ChoosedataButtonGroup);
            app.ButtonMetabolome.Text = 'Metabolome';
            app.ButtonGenome = uiradiobutton(app.ChoosedataButtonGroup);
            app.ButtonGenome.Text = 'Genome';
            app.ButtonGenome.Value = true;
            app.HelpButton_Choose_data = uibutton(app.ChoosedataButtonGroup, 'push');
            app.HelpButton_Choose_data.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_Choose_dataPushed, true);
            app.HelpButton_Choose_data.Text = 'Help';
            app.Label_2_Visualization_of_input_data = uilabel(app.GridLayout_Panel_2_Visualization_of_input_data);
            app.Label_2_Visualization_of_input_data.Text = '  Visualization of input data';
            app.Panel_1_Input = uipanel(app.GridLayout_PanOMICsApp);
            app.GridLayout_Input = uigridlayout(app.Panel_1_Input);
            app.Label_1_Input = uilabel(app.GridLayout_Input);
            app.Label_1_Input.Text = ' 1] Input';
            app.Panel_1_1_Loading_data_preprocessed = uipanel(app.GridLayout_Input);
            app.Panel_1_1_Loading_data_preprocessed.Title = '1.2] Data processing';
            app.GridLayout_Panel_1_1_Loading_data_preprocessed = uigridlayout(app.Panel_1_1_Loading_data_preprocessed);
            app.HelpButton_1_1_Loading_data_preprocessed = uibutton(app.GridLayout_Panel_1_1_Loading_data_preprocessed, 'push');
            app.HelpButton_1_1_Loading_data_preprocessed.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_1_1_Loading_data_preprocessedPushed, true);
            app.HelpButton_1_1_Loading_data_preprocessed.Text = 'Help';
            app.Button_See_in_detail_OtherOMICs_preprocessed = uibutton(app.GridLayout_Panel_1_1_Loading_data_preprocessed, 'push');
            app.Button_See_in_detail_OtherOMICs_preprocessed.ButtonPushedFcn = createCallbackFcn(app, @Button_See_in_detail_OtherOMICs_preprocessedPushed, true);
            app.Button_See_in_detail_OtherOMICs_preprocessed.Text = 'See detail';
            app.UITable_Load_OtherOMICsInfo_preprocessed = uitable(app.GridLayout_Panel_1_1_Loading_data_preprocessed);
            app.UITable_Load_OtherOMICsInfo_preprocessed.ColumnName = '';
            app.UITable_Load_OtherOMICsInfo_preprocessed.RowName = {};
            app.UITable_Load_OtherOMICsInfo_preprocessed.ColumnSortable = true;
            app.UITable_Load_OtherOMICsInfo_preprocessed.ColumnEditable = true;
            app.Button_See_in_detail_PhenotypeInfo_preprocessed = uibutton(app.GridLayout_Panel_1_1_Loading_data_preprocessed, 'push');
            app.Button_See_in_detail_PhenotypeInfo_preprocessed.ButtonPushedFcn = createCallbackFcn(app, @Button_See_in_detail_PhenotypeInfo_preprocessedPushed, true);
            app.Button_See_in_detail_PhenotypeInfo_preprocessed.Text = 'See detail';
            app.UITable_Load_PhenotypeInfo_preprocessed = uitable(app.GridLayout_Panel_1_1_Loading_data_preprocessed);
            app.UITable_Load_PhenotypeInfo_preprocessed.ColumnName = '';
            app.UITable_Load_PhenotypeInfo_preprocessed.RowName = {};
            app.UITable_Load_PhenotypeInfo_preprocessed.ColumnEditable = true;
            app.Button_See_in_detail_MetabolomicsInfo_preprocessed = uibutton(app.GridLayout_Panel_1_1_Loading_data_preprocessed, 'push');
            app.Button_See_in_detail_MetabolomicsInfo_preprocessed.ButtonPushedFcn = createCallbackFcn(app, @Button_See_in_detail_MetabolomicsInfo_preprocessedPushed, true);
            app.Button_See_in_detail_MetabolomicsInfo_preprocessed.Text = 'See detail';
            app.UITable_Load_MetabolomicsInfo_preprocessed = uitable(app.GridLayout_Panel_1_1_Loading_data_preprocessed);
            app.UITable_Load_MetabolomicsInfo_preprocessed.ColumnName = '';
            app.UITable_Load_MetabolomicsInfo_preprocessed.RowName = {};
            app.UITable_Load_MetabolomicsInfo_preprocessed.ColumnSortable = true;
            app.UITable_Load_MetabolomicsInfo_preprocessed.ColumnEditable = true;
            app.Button_See_in_detail_GenomicsInput_preprocessed = uibutton(app.GridLayout_Panel_1_1_Loading_data_preprocessed, 'push');
            app.Button_See_in_detail_GenomicsInput_preprocessed.ButtonPushedFcn = createCallbackFcn(app, @Button_See_in_detail_GenomicsInput_preprocessedPushed, true);
            app.Button_See_in_detail_GenomicsInput_preprocessed.Text = 'See detail';
            app.UITable_Load_GenomicsInfo_preprocessed = uitable(app.GridLayout_Panel_1_1_Loading_data_preprocessed);
            app.UITable_Load_GenomicsInfo_preprocessed.ColumnName = '';
            app.UITable_Load_GenomicsInfo_preprocessed.RowName = {};
            app.UITable_Load_GenomicsInfo_preprocessed.ColumnSortable = true;
            app.UITable_Load_GenomicsInfo_preprocessed.ColumnEditable = true;
            app.CheckBox_Uploaded_preprocessed_data = uicheckbox(app.GridLayout_Panel_1_1_Loading_data_preprocessed);
            app.CheckBox_Uploaded_preprocessed_data.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Uploaded_preprocessed_dataValueChanged, true);
            app.CheckBox_Uploaded_preprocessed_data.Text = 'Uploaded pre-processed data';
            app.CheckBox_Uploaded_preprocessed_data.Value = true;
            app.CheckBox_Automatic_preprocessing = uicheckbox(app.GridLayout_Panel_1_1_Loading_data_preprocessed);
            app.CheckBox_Automatic_preprocessing.ValueChangedFcn = createCallbackFcn(app, @CheckBox_Automatic_preprocessingValueChanged, true);
            app.CheckBox_Automatic_preprocessing.Text = 'Automated processing ';
            app.Panel_1_1_Loading_data = uipanel(app.GridLayout_Input);
            app.Panel_1_1_Loading_data.Title = '1.1] Data loading';
            app.GridLayout_Panel_1_1_Loading_data = uigridlayout(app.Panel_1_1_Loading_data);
            app.HelpButton_1_1_Loading_data = uibutton(app.GridLayout_Panel_1_1_Loading_data, 'push');
            app.HelpButton_1_1_Loading_data.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_1_1_Loading_dataPushed, true);
            app.HelpButton_1_1_Loading_data.Text = 'Help';
            app.Button_See_in_detail_OtherOMICs = uibutton(app.GridLayout_Panel_1_1_Loading_data, 'push');
            app.Button_See_in_detail_OtherOMICs.ButtonPushedFcn = createCallbackFcn(app, @Button_See_in_detail_OtherOMICsPushed, true);
            app.Button_See_in_detail_OtherOMICs.Text = 'See detail';
            app.UITable_Load_OtherOMICsInfo = uitable(app.GridLayout_Panel_1_1_Loading_data);
            app.UITable_Load_OtherOMICsInfo.ColumnName = '';
            app.UITable_Load_OtherOMICsInfo.RowName = {};
            app.UITable_Load_OtherOMICsInfo.ColumnSortable = true;
            app.UITable_Load_OtherOMICsInfo.ColumnEditable = true;
            app.Button_Example_data_OthersOMICs = uibutton(app.GridLayout_Panel_1_1_Loading_data, 'push');
            app.Button_Example_data_OthersOMICs.ButtonPushedFcn = createCallbackFcn(app, @Button_Example_data_OthersOMICsPushed, true);
            app.Button_Example_data_OthersOMICs.Text = 'Example ';
            app.Button_Load_OthersOMICs = uibutton(app.GridLayout_Panel_1_1_Loading_data, 'push');
            app.Button_Load_OthersOMICs.ButtonPushedFcn = createCallbackFcn(app, @Button_Load_OthersOMICsPushed, true);
            app.Button_Load_OthersOMICs.Text = 'Load OtherOMICs';
            app.Button_See_in_detail_PhenotypeInfo = uibutton(app.GridLayout_Panel_1_1_Loading_data, 'push');
            app.Button_See_in_detail_PhenotypeInfo.ButtonPushedFcn = createCallbackFcn(app, @Button_See_in_detail_PhenotypeInfoPushed, true);
            app.Button_See_in_detail_PhenotypeInfo.Text = 'See detail';
            app.UITable_Load_PhenotypeInfo = uitable(app.GridLayout_Panel_1_1_Loading_data);
            app.UITable_Load_PhenotypeInfo.ColumnName = '';
            app.UITable_Load_PhenotypeInfo.RowName = {};
            app.UITable_Load_PhenotypeInfo.ColumnEditable = true;
            app.Button_Example_data_Phenotype = uibutton(app.GridLayout_Panel_1_1_Loading_data, 'push');
            app.Button_Example_data_Phenotype.ButtonPushedFcn = createCallbackFcn(app, @Button_Example_data_PhenotypePushed, true);
            app.Button_Example_data_Phenotype.Text = 'Example ';
            app.Button_Load_Phenotype_Info = uibutton(app.GridLayout_Panel_1_1_Loading_data, 'push');
            app.Button_Load_Phenotype_Info.ButtonPushedFcn = createCallbackFcn(app, @Button_Load_Phenotype_InfoPushed, true);
            app.Button_Load_Phenotype_Info.Text = 'Load Phenotype Info';
            app.Button_See_in_detail_MetabolomicsInfo = uibutton(app.GridLayout_Panel_1_1_Loading_data, 'push');
            app.Button_See_in_detail_MetabolomicsInfo.ButtonPushedFcn = createCallbackFcn(app, @Button_See_in_detail_MetabolomicsInfoPushed, true);
            app.Button_See_in_detail_MetabolomicsInfo.Text = 'See detail';
            app.UITable_Load_MetabolomicsInfo = uitable(app.GridLayout_Panel_1_1_Loading_data);
            app.UITable_Load_MetabolomicsInfo.ColumnName = '';
            app.UITable_Load_MetabolomicsInfo.RowName = {};
            app.UITable_Load_MetabolomicsInfo.ColumnSortable = true;
            app.UITable_Load_MetabolomicsInfo.ColumnEditable = true;
            app.Button_Example_data_Metabolomics = uibutton(app.GridLayout_Panel_1_1_Loading_data, 'push');
            app.Button_Example_data_Metabolomics.ButtonPushedFcn = createCallbackFcn(app, @Button_Example_data_MetabolomicsPushed, true);
            app.Button_Example_data_Metabolomics.Text = 'Example ';
            app.Button_Load_Metabolomics_Info = uibutton(app.GridLayout_Panel_1_1_Loading_data, 'push');
            app.Button_Load_Metabolomics_Info.ButtonPushedFcn = createCallbackFcn(app, @Button_Load_Metabolomics_InfoPushed, true);
            app.Button_Load_Metabolomics_Info.Text = 'Load Metabolomics Info';
            app.Button_See_in_detail_GenomicsInput = uibutton(app.GridLayout_Panel_1_1_Loading_data, 'push');
            app.Button_See_in_detail_GenomicsInput.ButtonPushedFcn = createCallbackFcn(app, @Button_See_in_detail_GenomicsInputPushed, true);
            app.Button_See_in_detail_GenomicsInput.Text = 'See detail';
            app.UITable_Load_GenomicsInfo = uitable(app.GridLayout_Panel_1_1_Loading_data);
            app.UITable_Load_GenomicsInfo.ColumnName = '';
            app.UITable_Load_GenomicsInfo.RowName = {};
            app.UITable_Load_GenomicsInfo.ColumnSortable = true;
            app.UITable_Load_GenomicsInfo.ColumnEditable = true;
            app.Button_Example_data_Genomics = uibutton(app.GridLayout_Panel_1_1_Loading_data, 'push');
            app.Button_Example_data_Genomics.ButtonPushedFcn = createCallbackFcn(app, @Button_Example_data_GenomicsPushed, true);
            app.Button_Example_data_Genomics.Text = 'Example ';
            app.Button_Load_Genomics_Info = uibutton(app.GridLayout_Panel_1_1_Loading_data, 'push');
            app.Button_Load_Genomics_Info.ButtonPushedFcn = createCallbackFcn(app, @Button_Load_Genomics_InfoPushed, true);
            app.Button_Load_Genomics_Info.Text = 'Load Genomics Info';
            app.GenomicsInputTab = uitab(app.TabGroup_Main);
            app.GenomicsInputTab.Title = 'GenomicsInput';
            app.GridLayout_GenomicsInputTab_ZOOM_in = uigridlayout(app.GenomicsInputTab);
            app.Panel_Genomics_input = uipanel(app.GridLayout_GenomicsInputTab_ZOOM_in);
            app.Panel_Genomics_input.Title = 'Genomics input';
            app.GridLayout_Panel_Genomics_input = uigridlayout(app.Panel_Genomics_input);
            app.UITable_Genomics_input_ZOOM_in = uitable(app.GridLayout_Panel_Genomics_input);
            app.UITable_Genomics_input_ZOOM_in.ColumnName = '';
            app.UITable_Genomics_input_ZOOM_in.RowName = {};
            app.Button_Back_to_MAIN_Genomics = uibutton(app.GridLayout_Panel_Genomics_input, 'push');
            app.Button_Back_to_MAIN_Genomics.ButtonPushedFcn = createCallbackFcn(app, @Button_Back_to_MAIN_GenomicsPushed, true);
            app.Button_Back_to_MAIN_Genomics.Text = 'Back to MAIN';
            app.HELPButton_Panel_Genomics_input = uibutton(app.GridLayout_Panel_Genomics_input, 'push');
            app.HELPButton_Panel_Genomics_input.ButtonPushedFcn = createCallbackFcn(app, @HELPButton_Panel_Genomics_inputPushed, true);
            app.HELPButton_Panel_Genomics_input.Text = 'HELP';
            app.MetabolomicsInputTab = uitab(app.TabGroup_Main);
            app.MetabolomicsInputTab.Title = 'MetabolomicsInput';
            app.GridLayout_MetabolomicsInputTab_ZOOM_in = uigridlayout(app.MetabolomicsInputTab);
            app.Panel_Metabolomics_input = uipanel(app.GridLayout_MetabolomicsInputTab_ZOOM_in);
            app.Panel_Metabolomics_input.Title = 'Metabolomics input';
            app.GridLayout_Panel_Metabolomics_input = uigridlayout(app.Panel_Metabolomics_input);
            app.UITable_Metabolomics_input_ZOOM_in = uitable(app.GridLayout_Panel_Metabolomics_input);
            app.UITable_Metabolomics_input_ZOOM_in.ColumnName = '';
            app.UITable_Metabolomics_input_ZOOM_in.RowName = {};
            app.Button_Back_to_MAIN_Metabolomics = uibutton(app.GridLayout_Panel_Metabolomics_input, 'push');
            app.Button_Back_to_MAIN_Metabolomics.ButtonPushedFcn = createCallbackFcn(app, @Button_Back_to_MAIN_MetabolomicsPushed, true);
            app.Button_Back_to_MAIN_Metabolomics.Text = 'Back to MAIN';
            app.HELPButton_Panel_Metabolomics_input = uibutton(app.GridLayout_Panel_Metabolomics_input, 'push');
            app.HELPButton_Panel_Metabolomics_input.ButtonPushedFcn = createCallbackFcn(app, @HELPButton_Panel_Metabolomics_inputPushed, true);
            app.HELPButton_Panel_Metabolomics_input.Text = 'HELP';
            app.PhenotypeInputTab = uitab(app.TabGroup_Main);
            app.PhenotypeInputTab.Title = 'PhenotypeInput';
            app.GridLayout_PhenotypeInputTab_ZOOM_in = uigridlayout(app.PhenotypeInputTab);
            app.Panel_Phenotype_input = uipanel(app.GridLayout_PhenotypeInputTab_ZOOM_in);
            app.Panel_Phenotype_input.Title = 'Phenotype input';
            app.GridLayout_Panel_Phenotype_input = uigridlayout(app.Panel_Phenotype_input);
            app.UITable_Phenotype_input_ZOOM_in = uitable(app.GridLayout_Panel_Phenotype_input);
            app.UITable_Phenotype_input_ZOOM_in.ColumnName = '';
            app.UITable_Phenotype_input_ZOOM_in.RowName = {};
            app.Button_Back_to_MAIN_Phenotype = uibutton(app.GridLayout_Panel_Phenotype_input, 'push');
            app.Button_Back_to_MAIN_Phenotype.ButtonPushedFcn = createCallbackFcn(app, @Button_Back_to_MAIN_PhenotypePushed, true);
            app.Button_Back_to_MAIN_Phenotype.Text = 'Back to MAIN';
            app.HELPButton_Panel_Phenotype_input = uibutton(app.GridLayout_Panel_Phenotype_input, 'push');
            app.HELPButton_Panel_Phenotype_input.ButtonPushedFcn = createCallbackFcn(app, @HELPButton_Panel_Phenotype_inputPushed, true);
            app.HELPButton_Panel_Phenotype_input.Text = 'HELP';
            app.OtherOMICsInputTab = uitab(app.TabGroup_Main);
            app.OtherOMICsInputTab.Title = 'OtherOMICsInput';
            app.GridLayout_OtherOMICsInputTab_ZOOM_in = uigridlayout(app.OtherOMICsInputTab);
            app.Panel_OtherOMICs_input = uipanel(app.GridLayout_OtherOMICsInputTab_ZOOM_in);
            app.Panel_OtherOMICs_input.Title = 'OtherOMICs input';
            app.GridLayout_Panel_OtherOMICs_input = uigridlayout(app.Panel_OtherOMICs_input);
            app.UITable_OtherOMICs_input_ZOOM_in = uitable(app.GridLayout_Panel_OtherOMICs_input);
            app.UITable_OtherOMICs_input_ZOOM_in.ColumnName = '';
            app.UITable_OtherOMICs_input_ZOOM_in.RowName = {};
            app.Button_Back_to_MAIN_OtherOMICs = uibutton(app.GridLayout_Panel_OtherOMICs_input, 'push');
            app.Button_Back_to_MAIN_OtherOMICs.ButtonPushedFcn = createCallbackFcn(app, @Button_Back_to_MAIN_OtherOMICsPushed, true);
            app.Button_Back_to_MAIN_OtherOMICs.Text = 'Back to MAIN';
            app.HELPButton_Panel_OtherOMICs_input = uibutton(app.GridLayout_Panel_OtherOMICs_input, 'push');
            app.HELPButton_Panel_OtherOMICs_input.ButtonPushedFcn = createCallbackFcn(app, @HELPButton_Panel_OtherOMICs_inputPushed, true);
            app.HELPButton_Panel_OtherOMICs_input.Text = 'HELP';
            app.GenomicsInputTab_preprocessed = uitab(app.TabGroup_Main);
            app.GenomicsInputTab_preprocessed.Title = 'preprocessedGenomicsInput';
            app.GridLayout_GenomicsInputTab_ZOOM_in_preprocessed = uigridlayout(app.GenomicsInputTab_preprocessed);
            app.Panel_Genomics_input_preprocessed = uipanel(app.GridLayout_GenomicsInputTab_ZOOM_in_preprocessed);
            app.Panel_Genomics_input_preprocessed.Title = 'Genomics pre-processed data input';
            app.GridLayout_Panel_Genomics_input_preprocessed = uigridlayout(app.Panel_Genomics_input_preprocessed);
            app.UITable_Genomics_input_ZOOM_in_preprocessed = uitable(app.GridLayout_Panel_Genomics_input_preprocessed);
            app.UITable_Genomics_input_ZOOM_in_preprocessed.ColumnName = '';
            app.UITable_Genomics_input_ZOOM_in_preprocessed.RowName = {};
            app.Button_Back_to_MAIN_Genomics_preprocessed = uibutton(app.GridLayout_Panel_Genomics_input_preprocessed, 'push');
            app.Button_Back_to_MAIN_Genomics_preprocessed.ButtonPushedFcn = createCallbackFcn(app, @Button_Back_to_MAIN_Genomics_preprocessedPushed, true);
            app.Button_Back_to_MAIN_Genomics_preprocessed.Text = 'Back to MAIN';
            app.HELPButton_Panel_Genomics_input_preprocessed = uibutton(app.GridLayout_Panel_Genomics_input_preprocessed, 'push');
            app.HELPButton_Panel_Genomics_input_preprocessed.ButtonPushedFcn = createCallbackFcn(app, @HELPButton_Panel_Genomics_input_preprocessedPushed, true);
            app.HELPButton_Panel_Genomics_input_preprocessed.Text = 'HELP';
            app.MetabolomicsInputTab_preprocessed = uitab(app.TabGroup_Main);
            app.MetabolomicsInputTab_preprocessed.Title = 'preprocessedMetabolomicsInput';
            app.GridLayout_MetabolomicsInputTab_ZOOM_in_preprocessed = uigridlayout(app.MetabolomicsInputTab_preprocessed);
            app.Panel_Metabolomics_input_preprocessed = uipanel(app.GridLayout_MetabolomicsInputTab_ZOOM_in_preprocessed);
            app.Panel_Metabolomics_input_preprocessed.Title = 'Metabolomics pre-processed data input';
            app.GridLayout_Panel_Metabolomics_input_preprocessed = uigridlayout(app.Panel_Metabolomics_input_preprocessed);
            app.UITable_Metabolomics_input_ZOOM_in_preprocessed = uitable(app.GridLayout_Panel_Metabolomics_input_preprocessed);
            app.UITable_Metabolomics_input_ZOOM_in_preprocessed.ColumnName = '';
            app.UITable_Metabolomics_input_ZOOM_in_preprocessed.RowName = {};
            app.Button_Back_to_MAIN_Metabolomics_preprocessed = uibutton(app.GridLayout_Panel_Metabolomics_input_preprocessed, 'push');
            app.Button_Back_to_MAIN_Metabolomics_preprocessed.ButtonPushedFcn = createCallbackFcn(app, @Button_Back_to_MAIN_Metabolomics_preprocessedPushed, true);
            app.Button_Back_to_MAIN_Metabolomics_preprocessed.Text = 'Back to MAIN';
            app.HELPButton_Panel_Metabolomics_input_preprocessed = uibutton(app.GridLayout_Panel_Metabolomics_input_preprocessed, 'push');
            app.HELPButton_Panel_Metabolomics_input_preprocessed.ButtonPushedFcn = createCallbackFcn(app, @HELPButton_Panel_Metabolomics_input_preprocessedPushed, true);
            app.HELPButton_Panel_Metabolomics_input_preprocessed.Text = 'HELP';
            app.PhenotypeInputTab_preprocessed = uitab(app.TabGroup_Main);
            app.PhenotypeInputTab_preprocessed.Title = 'preprocessedPhenotypeInput';
            app.GridLayout_PhenotypeInputTab_ZOOM_in_preprocessed = uigridlayout(app.PhenotypeInputTab_preprocessed);
            app.Panel_Phenotype_input_preprocessed = uipanel(app.GridLayout_PhenotypeInputTab_ZOOM_in_preprocessed);
            app.Panel_Phenotype_input_preprocessed.Title = 'Phenotype pre-processed data input';
            app.GridLayout_Panel_Phenotype_input_preprocessed = uigridlayout(app.Panel_Phenotype_input_preprocessed);
            app.UITable_Phenotype_input_ZOOM_in_preprocessed = uitable(app.GridLayout_Panel_Phenotype_input_preprocessed);
            app.UITable_Phenotype_input_ZOOM_in_preprocessed.ColumnName = '';
            app.UITable_Phenotype_input_ZOOM_in_preprocessed.RowName = {};
            app.Button_Back_to_MAIN_Phenotype_preprocessed = uibutton(app.GridLayout_Panel_Phenotype_input_preprocessed, 'push');
            app.Button_Back_to_MAIN_Phenotype_preprocessed.ButtonPushedFcn = createCallbackFcn(app, @Button_Back_to_MAIN_Phenotype_preprocessedPushed, true);
            app.Button_Back_to_MAIN_Phenotype_preprocessed.Text = 'Back to MAIN';
            app.HELPButton_Panel_Phenotype_input_preprocessed = uibutton(app.GridLayout_Panel_Phenotype_input_preprocessed, 'push');
            app.HELPButton_Panel_Phenotype_input_preprocessed.ButtonPushedFcn = createCallbackFcn(app, @HELPButton_Panel_Phenotype_input_preprocessedPushed, true);
            app.HELPButton_Panel_Phenotype_input_preprocessed.Text = 'HELP';
            app.OtherOMICsInputTab_preprocessed = uitab(app.TabGroup_Main);
            app.OtherOMICsInputTab_preprocessed.Title = 'preprocessedOtherOMICsInput';
            app.GridLayout_OtherOMICsInputTab_ZOOM_in_preprocessed = uigridlayout(app.OtherOMICsInputTab_preprocessed);
            app.Panel_OtherOMICs_input_preprocessed = uipanel(app.GridLayout_OtherOMICsInputTab_ZOOM_in_preprocessed);
            app.Panel_OtherOMICs_input_preprocessed.Title = 'OtherOMICs pre-processed data input';
            app.GridLayout_Panel_OtherOMICs_input_preprocessed = uigridlayout(app.Panel_OtherOMICs_input_preprocessed);
            app.UITable_OtherOMICs_input_ZOOM_in_preprocessed = uitable(app.GridLayout_Panel_OtherOMICs_input_preprocessed);
            app.UITable_OtherOMICs_input_ZOOM_in_preprocessed.ColumnName = '';
            app.UITable_OtherOMICs_input_ZOOM_in_preprocessed.RowName = {};
            app.Button_Back_to_MAIN_OtherOMICs_preprocessed = uibutton(app.GridLayout_Panel_OtherOMICs_input_preprocessed, 'push');
            app.Button_Back_to_MAIN_OtherOMICs_preprocessed.ButtonPushedFcn = createCallbackFcn(app, @Button_Back_to_MAIN_OtherOMICs_preprocessedPushed, true);
            app.Button_Back_to_MAIN_OtherOMICs_preprocessed.Text = 'Back to MAIN';
            app.HELPButton_Panel_OtherOMICs_input_preprocessed = uibutton(app.GridLayout_Panel_OtherOMICs_input_preprocessed, 'push');
            app.HELPButton_Panel_OtherOMICs_input_preprocessed.ButtonPushedFcn = createCallbackFcn(app, @HELPButton_Panel_OtherOMICs_input_preprocessedPushed, true);
            app.HELPButton_Panel_OtherOMICs_input_preprocessed.Text = 'HELP';
            app.GWASTab = uitab(app.TabGroup_Main);
            app.GWASTab.Title = 'GWAS';
            app.GridLayout_GWAS = uigridlayout(app.GWASTab);
            app.Panel_GWAS = uipanel(app.GridLayout_GWAS);
            app.Panel_GWAS.Title = 'GWAS (Genome Wide Association Studies)';
            app.GridLayout_Panel_GWAS = uigridlayout(app.Panel_GWAS);
            app.UIAxes_GWAS = uiaxes(app.GridLayout_Panel_GWAS);
            title(app.UIAxes_GWAS, 'GWAS')
            app.UIAxes_GWAS.XTick = [];
            app.UIAxes_GWAS.YTick = [];
            app.HELPButton_Panel_GWAS = uibutton(app.GridLayout_Panel_GWAS, 'push');
            app.HELPButton_Panel_GWAS.ButtonPushedFcn = createCallbackFcn(app, @HELPButton_Panel_GWASPushed, true);
            app.HELPButton_Panel_GWAS.Text = 'Help';
            app.SaveResultsascsvButton = uibutton(app.GridLayout_Panel_GWAS, 'push');
            app.SaveResultsascsvButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsascsvButtonPushed, true);
            app.SaveResultsascsvButton.Text = 'Save Results as .csv';
            app.UITable_Results_GWAS = uitable(app.GridLayout_Panel_GWAS);
            app.UITable_Results_GWAS.ColumnName = '';
            app.UITable_Results_GWAS.RowName = {};
            app.Button_Calculate = uibutton(app.GridLayout_Panel_GWAS, 'push');
            app.Button_Calculate.ButtonPushedFcn = createCallbackFcn(app, @Button_CalculatePushed, true);
            app.Button_Calculate.Text = 'Calulate';
            app.Button_Load_SNPs_data = uibutton(app.GridLayout_Panel_GWAS, 'push');
            app.Button_Load_SNPs_data.ButtonPushedFcn = createCallbackFcn(app, @Button_Load_SNPs_dataPushed, true);
            app.Button_Load_SNPs_data.Text = 'Load SNPs data';
            app.Button_Example_data_GWAS = uibutton(app.GridLayout_Panel_GWAS, 'push');
            app.Button_Example_data_GWAS.ButtonPushedFcn = createCallbackFcn(app, @Button_Example_data_GWASPushed, true);
            app.Button_Example_data_GWAS.Text = 'Example data';
            app.UITable_Input_SNPsINFO_data = uitable(app.GridLayout_Panel_GWAS);
            app.UITable_Input_SNPsINFO_data.ColumnName = '';
            app.UITable_Input_SNPsINFO_data.RowName = {};
            app.Button_Backto_to_MAIN_GWAS = uibutton(app.GridLayout_Panel_GWAS, 'push');
            app.Button_Backto_to_MAIN_GWAS.ButtonPushedFcn = createCallbackFcn(app, @Button_Backto_to_MAIN_GWASPushed, true);
            app.Button_Backto_to_MAIN_GWAS.Text = 'Back to MAIN';
            app.SectionVisualization_HistogramTab = uitab(app.TabGroup_Main);
            app.SectionVisualization_HistogramTab.Title = 'SectionVisualization_Histogram';
            app.GridLayout_HistogramTab = uigridlayout(app.SectionVisualization_HistogramTab);
            app.Panel_HistogramTab = uipanel(app.GridLayout_HistogramTab);
            app.Panel_HistogramTab.Title = 'Histograms';
            app.GridLayout_Panel_HistogramTab = uigridlayout(app.Panel_HistogramTab);
            app.UIAxes_HistogramTab = uiaxes(app.GridLayout_Panel_HistogramTab);
            xlabel(app.UIAxes_HistogramTab, 'Accessions [–]')
            app.UIAxes_HistogramTab.XTick = [];
            app.UIAxes_HistogramTab.YTick = [];
            app.Button_Back_to_MAIN_HistogramTab = uibutton(app.GridLayout_Panel_HistogramTab, 'push');
            app.Button_Back_to_MAIN_HistogramTab.ButtonPushedFcn = createCallbackFcn(app, @Button_Back_to_MAIN_HistogramTabPushed, true);
            app.Button_Back_to_MAIN_HistogramTab.Text = 'Back to MAIN';
            app.HELPButton_Panel_HistogramTab = uibutton(app.GridLayout_Panel_HistogramTab, 'push');
            app.HELPButton_Panel_HistogramTab.ButtonPushedFcn = createCallbackFcn(app, @HELPButton_Panel_HistogramTabPushed, true);
            app.HELPButton_Panel_HistogramTab.Text = 'Help';
            app.SectionVisualization_PCATab = uitab(app.TabGroup_Main);
            app.SectionVisualization_PCATab.Title = 'SectionVisualization_PCA';
            app.GridLayout_PCATab = uigridlayout(app.SectionVisualization_PCATab);
            app.Panel_PCATab = uipanel(app.GridLayout_PCATab);
            app.Panel_PCATab.Title = 'Principal Component Analysis (PCA)';
            app.GridLayout_Panel_PCATab = uigridlayout(app.Panel_PCATab);
            app.UIAxes_PCATab = uiaxes(app.GridLayout_Panel_PCATab);
            xlabel(app.UIAxes_PCATab, 'PC1')
            ylabel(app.UIAxes_PCATab, 'PC2')
            app.UIAxes_PCATab.XTick = [];
            app.UIAxes_PCATab.YTick = [];
            app.HELPButton_Panel_PCATab = uibutton(app.GridLayout_Panel_PCATab, 'push');
            app.HELPButton_Panel_PCATab.ButtonPushedFcn = createCallbackFcn(app, @HELPButton_Panel_PCATabPushed, true);
            app.HELPButton_Panel_PCATab.Text = 'Help';
            app.Button_Back_to_MAIN_PCATab = uibutton(app.GridLayout_Panel_PCATab, 'push');
            app.Button_Back_to_MAIN_PCATab.ButtonPushedFcn = createCallbackFcn(app, @Button_Back_to_MAIN_PCATabPushed, true);
            app.Button_Back_to_MAIN_PCATab.Text = 'Back to MAIN';
            app.SectionVisualization_BoxPlotTab = uitab(app.TabGroup_Main);
            app.SectionVisualization_BoxPlotTab.Title = 'SectionVisualization_BoxPlot';
            app.GridLayout_BoxPlotTab = uigridlayout(app.SectionVisualization_BoxPlotTab);
            app.Panel_BoxPlotTab = uipanel(app.GridLayout_BoxPlotTab);
            app.Panel_BoxPlotTab.Title = 'Box plot/s';
            app.GridLayout_Panel_BoxPlotTab = uigridlayout(app.Panel_BoxPlotTab);
            app.UIAxes_BoxPlotTab = uiaxes(app.GridLayout_Panel_BoxPlotTab);
            xlabel(app.UIAxes_BoxPlotTab, 'PC1')
            ylabel(app.UIAxes_BoxPlotTab, 'PC2')
            app.UIAxes_BoxPlotTab.XTick = [];
            app.UIAxes_BoxPlotTab.YTick = [];
            app.HELPButton_Panel_BoxPlotTab = uibutton(app.GridLayout_Panel_BoxPlotTab, 'push');
            app.HELPButton_Panel_BoxPlotTab.ButtonPushedFcn = createCallbackFcn(app, @HELPButton_Panel_BoxPlotTabPushed, true);
            app.HELPButton_Panel_BoxPlotTab.Text = 'Help';
            app.Button_Back_to_MAIN_BoxPlotTab = uibutton(app.GridLayout_Panel_BoxPlotTab, 'push');
            app.Button_Back_to_MAIN_BoxPlotTab.ButtonPushedFcn = createCallbackFcn(app, @Button_Back_to_MAIN_BoxPlotTabPushed, true);
            app.Button_Back_to_MAIN_BoxPlotTab.Text = 'Back to MAIN';
            app.PolygenicRiskScoreTab = uitab(app.TabGroup_Main);
            app.PolygenicRiskScoreTab.Title = 'PolygenicRiskScore';
            app.GridLayout_PRS = uigridlayout(app.PolygenicRiskScoreTab);
            app.PRSPolygenicRiskScorePanel = uipanel(app.GridLayout_PRS);
            app.PRSPolygenicRiskScorePanel.Title = 'PRS (Polygenic Risk Score)';
            app.GridLayout_Panel_PRS = uigridlayout(app.PRSPolygenicRiskScorePanel);
            app.UIAxes_PRS = uiaxes(app.GridLayout_Panel_PRS);
            app.UIAxes_PRS.XTick = [];
            app.UIAxes_PRS.YTick = [];
            app.Button_Back_to_MAIN_PRS = uibutton(app.GridLayout_Panel_PRS, 'push');
            app.Button_Back_to_MAIN_PRS.ButtonPushedFcn = createCallbackFcn(app, @Button_Back_to_MAIN_PRSPushed, true);
            app.Button_Back_to_MAIN_PRS.Text = 'Back to MAIN';
            app.HelpButton = uibutton(app.GridLayout_Panel_PRS, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.Button_Downloadresultscasegroup = uibutton(app.GridLayout_Panel_PRS, 'push');
            app.Button_Downloadresultscasegroup.ButtonPushedFcn = createCallbackFcn(app, @Button_DownloadresultscasegroupPushed, true);
            app.Button_Downloadresultscasegroup.Text = 'Download results case group';
            app.UITable_Results_Case_group = uitable(app.GridLayout_Panel_PRS);
            app.UITable_Results_Case_group.ColumnName = '';
            app.UITable_Results_Case_group.RowName = {};
            app.Button_Downloandresultscontrolgroup = uibutton(app.GridLayout_Panel_PRS, 'push');
            app.Button_Downloandresultscontrolgroup.ButtonPushedFcn = createCallbackFcn(app, @Button_DownloandresultscontrolgroupPushed, true);
            app.Button_Downloandresultscontrolgroup.Text = 'Downloand results control group';
            app.UITable_Results_Control_group = uitable(app.GridLayout_Panel_PRS);
            app.UITable_Results_Control_group.ColumnName = '';
            app.UITable_Results_Control_group.RowName = {};
            app.CalculatePRSButton = uibutton(app.GridLayout_Panel_PRS, 'push');
            app.CalculatePRSButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePRSButtonPushed, true);
            app.CalculatePRSButton.Text = 'Calculate PRS';
            app.Button_Example_data_case_group = uibutton(app.GridLayout_Panel_PRS, 'push');
            app.Button_Example_data_case_group.ButtonPushedFcn = createCallbackFcn(app, @Button_Example_data_case_groupPushed, true);
            app.Button_Example_data_case_group.Text = 'Example data';
            app.UITable_Input_case_group = uitable(app.GridLayout_Panel_PRS);
            app.UITable_Input_case_group.ColumnName = '';
            app.UITable_Input_case_group.RowName = {};
            app.Button_Load_case_group = uibutton(app.GridLayout_Panel_PRS, 'push');
            app.Button_Load_case_group.ButtonPushedFcn = createCallbackFcn(app, @Button_Load_case_groupPushed, true);
            app.Button_Load_case_group.Text = 'Load case group';
            app.Button_Example_data_control_group = uibutton(app.GridLayout_Panel_PRS, 'push');
            app.Button_Example_data_control_group.ButtonPushedFcn = createCallbackFcn(app, @Button_Example_data_control_groupPushed, true);
            app.Button_Example_data_control_group.Text = 'Example data';
            app.UITable_Input_control_group = uitable(app.GridLayout_Panel_PRS);
            app.UITable_Input_control_group.ColumnName = '';
            app.UITable_Input_control_group.RowName = {};
            app.Button_Load_control_group = uibutton(app.GridLayout_Panel_PRS, 'push');
            app.Button_Load_control_group.ButtonPushedFcn = createCallbackFcn(app, @Button_Load_control_groupPushed, true);
            app.Button_Load_control_group.Text = 'Load control group';
            app.Parameters_LinearTab = uitab(app.TabGroup_Main);
            app.Parameters_LinearTab.Title = 'Parameters_Linear';
            app.GridLayout_Parameters_Linear = uigridlayout(app.Parameters_LinearTab);
            app.ParametersLinearPanel = uipanel(app.GridLayout_Parameters_Linear);
            app.ParametersLinearPanel.Title = 'Parameters customization options for linear models';
            app.GridLayout_Panel_ParametersLinear = uigridlayout(app.ParametersLinearPanel);
            app.LASSOPanel = uipanel(app.GridLayout_Panel_ParametersLinear);
            app.LASSOPanel.Title = 'LASSO';
            app.GridLayout_Panel_Parameters_LASSO = uigridlayout(app.LASSOPanel);
            app.HelpButton_LASSOparameters = uibutton(app.GridLayout_Panel_Parameters_LASSO, 'push');
            app.HelpButton_LASSOparameters.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_LASSOparametersPushed, true);
            app.HelpButton_LASSOparameters.Text = 'Help';
            app.ResetparametersButton_LASSOparameters = uibutton(app.GridLayout_Panel_Parameters_LASSO, 'push');
            app.ResetparametersButton_LASSOparameters.ButtonPushedFcn = createCallbackFcn(app, @ResetparametersButton_LASSOparametersPushed, true);
            app.ResetparametersButton_LASSOparameters.Text = 'Reset parameters';
            app.AlfaEditFieldLabel = uilabel(app.GridLayout_Panel_Parameters_LASSO);
            app.AlfaEditFieldLabel.Text = 'Alfa:';
            app.AlfaEditField_LASSOalfa = uieditfield(app.GridLayout_Panel_Parameters_LASSO, 'numeric');
            app.AlfaEditField_LASSOalfa.ValueChangedFcn = createCallbackFcn(app, @AlfaEditField_LASSOalfaValueChanged, true);
            app.AlfaEditField_LASSOalfa.Value = 1;
            app.CrossvalidationEditFieldLabel = uilabel(app.GridLayout_Panel_Parameters_LASSO);
            app.CrossvalidationEditFieldLabel.Text = 'Cross-validation:';
            app.CrossvalidationEditField_LASSOcv = uieditfield(app.GridLayout_Panel_Parameters_LASSO, 'numeric');
            app.CrossvalidationEditField_LASSOcv.ValueChangedFcn = createCallbackFcn(app, @CrossvalidationEditField_LASSOcvValueChanged, true);
            app.CrossvalidationEditField_LASSOcv.Value = 5;
            app.RRPanel = uipanel(app.GridLayout_Panel_ParametersLinear);
            app.RRPanel.Title = 'RR';
            app.GridLayout_Panel_Parameters_RR = uigridlayout(app.RRPanel);
            app.HelpButton_RRparameters = uibutton(app.GridLayout_Panel_Parameters_RR, 'push');
            app.HelpButton_RRparameters.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_RRparametersPushed, true);
            app.HelpButton_RRparameters.Text = 'Help';
            app.ResetparametersButton_RRparameters = uibutton(app.GridLayout_Panel_Parameters_RR, 'push');
            app.ResetparametersButton_RRparameters.ButtonPushedFcn = createCallbackFcn(app, @ResetparametersButton_RRparametersPushed, true);
            app.ResetparametersButton_RRparameters.Text = 'Reset parameters';
            app.CrossvalidationEditField_2Label = uilabel(app.GridLayout_Panel_Parameters_RR);
            app.CrossvalidationEditField_2Label.Text = 'Cross-validation:';
            app.CrossvalidationEditField_RRcv = uieditfield(app.GridLayout_Panel_Parameters_RR, 'numeric');
            app.CrossvalidationEditField_RRcv.ValueChangedFcn = createCallbackFcn(app, @CrossvalidationEditField_RRcvValueChanged, true);
            app.CrossvalidationEditField_RRcv.Value = 5;
            app.AlfaEditField_2Label = uilabel(app.GridLayout_Panel_Parameters_RR);
            app.AlfaEditField_2Label.Text = 'Alfa:';
            app.AlfaEditField_RRalfa = uieditfield(app.GridLayout_Panel_Parameters_RR, 'numeric');
            app.AlfaEditField_RRalfa.ValueChangedFcn = createCallbackFcn(app, @AlfaEditField_RRalfaValueChanged, true);
            app.AlfaEditField_RRalfa.Value = 1e-06;
            app.ENRPanel = uipanel(app.GridLayout_Panel_ParametersLinear);
            app.ENRPanel.Title = 'ENR';
            app.GridLayout_Panel_Parameters_ENR = uigridlayout(app.ENRPanel);
            app.HelpButton_ENRparameters = uibutton(app.GridLayout_Panel_Parameters_ENR, 'push');
            app.HelpButton_ENRparameters.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_ENRparametersPushed, true);
            app.HelpButton_ENRparameters.Text = 'Help';
            app.ResetparametersButton_ENRparameters = uibutton(app.GridLayout_Panel_Parameters_ENR, 'push');
            app.ResetparametersButton_ENRparameters.ButtonPushedFcn = createCallbackFcn(app, @ResetparametersButton_ENRparametersPushed, true);
            app.ResetparametersButton_ENRparameters.Text = 'Reset parameters';
            app.AlfaEditField_3Label = uilabel(app.GridLayout_Panel_Parameters_ENR);
            app.AlfaEditField_3Label.Text = 'Alfa:';
            app.AlfaEditField_ENRalfa = uieditfield(app.GridLayout_Panel_Parameters_ENR, 'numeric');
            app.AlfaEditField_ENRalfa.ValueChangedFcn = createCallbackFcn(app, @AlfaEditField_ENRalfaValueChanged, true);
            app.AlfaEditField_ENRalfa.Value = 0.75;
            app.CrossvalidationEditField_3Label = uilabel(app.GridLayout_Panel_Parameters_ENR);
            app.CrossvalidationEditField_3Label.Text = 'Cross-validation:';
            app.CrossvalidationEditField_ENRcv = uieditfield(app.GridLayout_Panel_Parameters_ENR, 'numeric');
            app.CrossvalidationEditField_ENRcv.ValueChangedFcn = createCallbackFcn(app, @CrossvalidationEditField_ENRcvValueChanged, true);
            app.CrossvalidationEditField_ENRcv.Value = 5;
            app.GPRPanel = uipanel(app.GridLayout_Panel_ParametersLinear);
            app.GPRPanel.Title = 'GPR';
            app.GridLayout_Panel_Parameters_GPR = uigridlayout(app.GPRPanel);
            app.Label_2 = uilabel(app.GridLayout_Panel_Parameters_GPR);
            app.Label_2.Text = {'We use a general Gaussian Process Regression (GPR) following the MATLAB documentation'; ' (https://www.mathworks.com/help/stats/gaussian-process-regression-models.html). '; ''; 'The model is created simply as:'; ''; 'gprMdl1 = fitrgp(x_observed, y_observed1);'; '[ypred1,~,yint1] = predict(gprMdl1,x);'; ''; 'No specific input parameters such as kernel, weights, or standardization are set, '; 'allowing the user to freely modify the GPR settings as needed.'};
            app.SVRPanel = uipanel(app.GridLayout_Panel_ParametersLinear);
            app.SVRPanel.Title = 'SVR';
            app.GridLayout_Panel_Parameters_SVR = uigridlayout(app.SVRPanel);
            app.Label_3 = uilabel(app.GridLayout_Panel_Parameters_SVR);
            app.Label_3.Text = {'We use a general Support Vector Machine (SVM) regression following the MATLAB documentation'; '(https://www.mathworks.com/help/stats/fitrsvm.html).'; ''; 'The model is created simply as:'; ''; 'MdlLin = fitrsvm(X, mean(Y,2));'; 'ypred1 = predict(MdlLin, testG);'; ''; 'No specific input parameters such as kernel function, box constraint, or standardization are set,'; 'allowing the user to freely modify the SVM settings as needed.'};
            app.PLSPanel = uipanel(app.GridLayout_Panel_ParametersLinear);
            app.PLSPanel.Title = 'PLS';
            app.GridLayout_Panel_Parameters_PLS = uigridlayout(app.PLSPanel);
            app.Label_4 = uilabel(app.GridLayout_Panel_Parameters_PLS);
            app.Label_4.Text = {'We use a general Partial Least Squares (PLS) regression following the MATLAB documentation'; '(https://www.mathworks.com/help/stats/plsregress.html).'; ''; 'The model is created simply as:'; ''; '[XL,yl,XS,YS,beta,PCTVAR,MSE,stats] = plsregress(trainG, trainM);'; 'yfit = [ones(size(testG,1),1) testG] * beta;'; 'ypred11 = [ypred11; yfit];'; 'testM1 = [testM1; testM];'; ''; 'No specific input parameters such as the number of components or scaling options are set,'; 'allowing the user to freely modify the PLS settings as needed.'};
            app.BacktoMAINButton = uibutton(app.GridLayout_Panel_ParametersLinear, 'push');
            app.BacktoMAINButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoMAINButtonPushed, true);
            app.BacktoMAINButton.Text = 'Back to MAIN';
            app.Parameters_NonlinearTab = uitab(app.TabGroup_Main);
            app.Parameters_NonlinearTab.Title = 'Parameters_Non-linear';
            app.GridLayout_Parameters_nonLinear = uigridlayout(app.Parameters_NonlinearTab);
            app.Parameters_nonLinearPanel = uipanel(app.GridLayout_Parameters_nonLinear);
            app.Parameters_nonLinearPanel.Title = 'Parameters customization options for non-linear models';
            app.GridLayout_Panel_Parameters_nonLinear = uigridlayout(app.Parameters_nonLinearPanel);
            app.BacktoMAINButton_nonLinearParameters = uibutton(app.GridLayout_Panel_Parameters_nonLinear, 'push');
            app.BacktoMAINButton_nonLinearParameters.ButtonPushedFcn = createCallbackFcn(app, @BacktoMAINButton_nonLinearParametersPushed, true);
            app.BacktoMAINButton_nonLinearParameters.Text = 'Back to MAIN';
            app.RandomForerstPanel = uipanel(app.GridLayout_Panel_Parameters_nonLinear);
            app.RandomForerstPanel.Title = 'Random Forerst';
            app.GridLayout2 = uigridlayout(app.RandomForerstPanel);
            app.HelpButton_2 = uibutton(app.GridLayout2, 'push');
            app.HelpButton_2.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_2Pushed, true);
            app.HelpButton_2.Text = 'Help';
            app.NumberofdecisiontreesLabel = uilabel(app.GridLayout2);
            app.NumberofdecisiontreesLabel.Text = 'Number of decision trees:';
            app.NumberofdecisiontreesEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NumberofdecisiontreesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofdecisiontreesEditFieldValueChanged, true);
            app.NumberofdecisiontreesEditField.Value = 250;
            app.Label_5 = uilabel(app.GridLayout2);
            app.Label_5.Text = {'We use a general Random Forest (RF) regression following the MATLAB documentation'; '(https://www.mathworks.com/help/stats/treebagger.html).'; ''; 'The model is created simply as:'; ''; 'RF_GM_1 = TreeBagger(app.number_of_decisionsTrees_input, X, y, ''Method'', ''regression'', ''OOBPrediction'', ''on'');'; 'app.Pred_Val = oobQuantilePredict(RF_GM_1);'; 'app.Orig_Val = y;'; ''; 'Only the number of decision trees (see above) is explicitly set,'; 'while all other parameters such as minimum leaf size, predictor selection, or sampling options are left at their defaults,'; 'allowing the user to freely modify the Random Forest settings as needed.'};
            app.Parameters_DeepLearnTab = uitab(app.TabGroup_Main);
            app.Parameters_DeepLearnTab.Title = 'Parameters_DeepLearn';
            app.GridLayout_Parameters_DeepLearn = uigridlayout(app.Parameters_DeepLearnTab);
            app.Panel_Parameters_DeepLearn = uipanel(app.GridLayout_Parameters_DeepLearn);
            app.Panel_Parameters_DeepLearn.Title = 'Parameters customization options for deep learning modeling';
            app.GridLayout_Panel_Parameters_DeepLearn = uigridlayout(app.Panel_Parameters_DeepLearn);
            app.BacktoMAINButton_DeepLearn = uibutton(app.GridLayout_Panel_Parameters_DeepLearn, 'push');
            app.BacktoMAINButton_DeepLearn.ButtonPushedFcn = createCallbackFcn(app, @BacktoMAINButton_DeepLearnPushed, true);
            app.BacktoMAINButton_DeepLearn.Text = 'Back to MAIN';
            app.CNNPanel = uipanel(app.GridLayout_Panel_Parameters_DeepLearn);
            app.CNNPanel.Title = 'CNN';
            app.GridLayout_Panel_CNN = uigridlayout(app.CNNPanel);
            app.HelpButton_CNN = uibutton(app.GridLayout_Panel_CNN, 'push');
            app.HelpButton_CNN.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_CNNPushed, true);
            app.HelpButton_CNN.Text = 'Help';
            app.MinibatchsizeEditField_3Label = uilabel(app.GridLayout_Panel_CNN);
            app.MinibatchsizeEditField_3Label.Text = 'Mini batch size';
            app.EditField_Minibatchsize_CNN = uieditfield(app.GridLayout_Panel_CNN, 'numeric');
            app.EditField_Minibatchsize_CNN.ValueChangedFcn = createCallbackFcn(app, @EditField_Minibatchsize_CNNValueChanged, true);
            app.EditField_Minibatchsize_CNN.Value = 25;
            app.EditField_fullyconnected_CNN_10 = uieditfield(app.GridLayout_Panel_CNN, 'text');
            app.EditField_fullyconnected_CNN_10.Editable = 'off';
            app.EditField_fullyconnected_CNN_10.Value = 'number of responses';
            app.InputlayerEditField_3Label = uilabel(app.GridLayout_Panel_CNN);
            app.InputlayerEditField_3Label.Text = 'Input layer';
            app.fullyconnectedlayernumberofhiddenunitsEditField_5Label = uilabel(app.GridLayout_Panel_CNN);
            app.fullyconnectedlayernumberofhiddenunitsEditField_5Label.Text = 'fully-connected layer – number of hidden units';
            app.convolutionlayerwidthofthefiltersLabel = uilabel(app.GridLayout_Panel_CNN);
            app.convolutionlayerwidthofthefiltersLabel.Text = 'convolution layer – width of the filters';
            app.relulayerEditField_3Label = uilabel(app.GridLayout_Panel_CNN);
            app.relulayerEditField_3Label.Text = 'relu layer';
            app.convolutionlayerwidthofthefiltersLabel_2 = uilabel(app.GridLayout_Panel_CNN);
            app.convolutionlayerwidthofthefiltersLabel_2.Text = 'convolution layer – width of the filters';
            app.relulayerEditField_4Label = uilabel(app.GridLayout_Panel_CNN);
            app.relulayerEditField_4Label.Text = 'relu layer';
            app.convolutionlayerwidthofthefiltersLabel_3 = uilabel(app.GridLayout_Panel_CNN);
            app.convolutionlayerwidthofthefiltersLabel_3.Text = 'convolution layer – width of the filters';
            app.relulayerEditField_5Label = uilabel(app.GridLayout_Panel_CNN);
            app.relulayerEditField_5Label.Text = 'relu layer';
            app.fullyconnectedlayernumberofhiddenunitsEditField_8Label = uilabel(app.GridLayout_Panel_CNN);
            app.fullyconnectedlayernumberofhiddenunitsEditField_8Label.Text = 'fully-connected layer – number of hidden units';
            app.fullyconnectedlayernumberofhiddenunitsEditField_9Label = uilabel(app.GridLayout_Panel_CNN);
            app.fullyconnectedlayernumberofhiddenunitsEditField_9Label.Text = 'fully-connected layer – number of hidden units';
            app.EditField_fullyconnected_CNN_9 = uieditfield(app.GridLayout_Panel_CNN, 'numeric');
            app.EditField_fullyconnected_CNN_9.ValueChangedFcn = createCallbackFcn(app, @EditField_fullyconnected_CNN_9ValueChanged, true);
            app.EditField_fullyconnected_CNN_9.Value = 50;
            app.EditField_fullyconnected_CNN_8 = uieditfield(app.GridLayout_Panel_CNN, 'numeric');
            app.EditField_fullyconnected_CNN_8.ValueChangedFcn = createCallbackFcn(app, @EditField_fullyconnected_CNN_8ValueChanged, true);
            app.EditField_fullyconnected_CNN_8.Value = 250;
            app.EditField_relulayer_CNN_7 = uieditfield(app.GridLayout_Panel_CNN, 'text');
            app.EditField_relulayer_CNN_7.Editable = 'off';
            app.EditField_relulayer_CNN_7.Value = '–';
            app.EditField_convolutionlayer_CNN_6 = uieditfield(app.GridLayout_Panel_CNN, 'numeric');
            app.EditField_convolutionlayer_CNN_6.ValueChangedFcn = createCallbackFcn(app, @EditField_convolutionlayer_CNN_6ValueChanged, true);
            app.EditField_convolutionlayer_CNN_6.Value = 3;
            app.EditField_relulayer_CNN_5 = uieditfield(app.GridLayout_Panel_CNN, 'text');
            app.EditField_relulayer_CNN_5.Editable = 'off';
            app.EditField_relulayer_CNN_5.Value = '–';
            app.EditField_convolutionlayer_CNN_4 = uieditfield(app.GridLayout_Panel_CNN, 'numeric');
            app.EditField_convolutionlayer_CNN_4.ValueChangedFcn = createCallbackFcn(app, @EditField_convolutionlayer_CNN_4ValueChanged, true);
            app.EditField_convolutionlayer_CNN_4.Value = 3;
            app.EditField_relulayer_CNN_3 = uieditfield(app.GridLayout_Panel_CNN, 'text');
            app.EditField_relulayer_CNN_3.Editable = 'off';
            app.EditField_relulayer_CNN_3.Value = '–';
            app.EditField_convolutionlayer_CNN_2 = uieditfield(app.GridLayout_Panel_CNN, 'numeric');
            app.EditField_convolutionlayer_CNN_2.ValueChangedFcn = createCallbackFcn(app, @EditField_convolutionlayer_CNN_2ValueChanged, true);
            app.EditField_convolutionlayer_CNN_2.Value = 3;
            app.EditField_Inputlayer_CNN_1 = uieditfield(app.GridLayout_Panel_CNN, 'text');
            app.EditField_Inputlayer_CNN_1.Editable = 'off';
            app.EditField_Inputlayer_CNN_1.Value = 'number of features ';
            app.LSTMPanel = uipanel(app.GridLayout_Panel_Parameters_DeepLearn);
            app.LSTMPanel.Title = 'LSTM';
            app.GridLayout_Panel_LSTM = uigridlayout(app.LSTMPanel);
            app.HelpButton_LSTM = uibutton(app.GridLayout_Panel_LSTM, 'push');
            app.HelpButton_LSTM.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_LSTMPushed, true);
            app.HelpButton_LSTM.Text = 'Help';
            app.MinibatchsizeEditField_2Label = uilabel(app.GridLayout_Panel_LSTM);
            app.MinibatchsizeEditField_2Label.Text = 'Mini batch size';
            app.EditField_Minibatchsize_LSTM = uieditfield(app.GridLayout_Panel_LSTM, 'numeric');
            app.EditField_Minibatchsize_LSTM.ValueChangedFcn = createCallbackFcn(app, @EditField_Minibatchsize_LSTMValueChanged, true);
            app.EditField_Minibatchsize_LSTM.Value = 25;
            app.regressionlayerEditFieldLabel = uilabel(app.GridLayout_Panel_LSTM);
            app.regressionlayerEditFieldLabel.Text = 'regression layer';
            app.EditField_regressionlayer_LSTM_6 = uieditfield(app.GridLayout_Panel_LSTM, 'text');
            app.EditField_regressionlayer_LSTM_6.Editable = 'off';
            app.EditField_regressionlayer_LSTM_6.Value = '–';
            app.fullyconnectedlayernumberofhiddenunitsEditField_3Label = uilabel(app.GridLayout_Panel_LSTM);
            app.fullyconnectedlayernumberofhiddenunitsEditField_3Label.Text = 'fully-connected layer – number of hidden units';
            app.EditField_fullyconnected_LSTM_5 = uieditfield(app.GridLayout_Panel_LSTM, 'text');
            app.EditField_fullyconnected_LSTM_5.Editable = 'off';
            app.EditField_fullyconnected_LSTM_5.Value = 'number of responses';
            app.dropoutlayerEditFieldLabel = uilabel(app.GridLayout_Panel_LSTM);
            app.dropoutlayerEditFieldLabel.Text = 'dropout layer – probability for dropping out input elements';
            app.EditField_dropout_LSTM_4 = uieditfield(app.GridLayout_Panel_LSTM, 'numeric');
            app.EditField_dropout_LSTM_4.ValueChangedFcn = createCallbackFcn(app, @EditField_dropout_LSTM_4ValueChanged, true);
            app.EditField_dropout_LSTM_4.Value = 0.5;
            app.fullyconnectedlayernumberofhiddenunitsEditFieldLabel = uilabel(app.GridLayout_Panel_LSTM);
            app.fullyconnectedlayernumberofhiddenunitsEditFieldLabel.Text = 'fully-connected layer - number of hidden units';
            app.EditField_fullyconnected_LSTM_3 = uieditfield(app.GridLayout_Panel_LSTM, 'numeric');
            app.EditField_fullyconnected_LSTM_3.ValueChangedFcn = createCallbackFcn(app, @EditField_fullyconnected_LSTM_3ValueChanged, true);
            app.EditField_fullyconnected_LSTM_3.Value = 50;
            app.LSTMlayernumberofhiddenunitsEditFieldLabel = uilabel(app.GridLayout_Panel_LSTM);

            app.LSTMlayernumberofhiddenunitsEditFieldLabel.Text = 'LSTM layer - number of hidden units';
            app.EditField_LSTMlayer_LSTM_2 = uieditfield(app.GridLayout_Panel_LSTM, 'numeric');
            app.EditField_LSTMlayer_LSTM_2.ValueChangedFcn = createCallbackFcn(app, @EditField_LSTMlayer_LSTM_2ValueChanged, true);
            app.EditField_LSTMlayer_LSTM_2.Value = 200;
            app.InputlayerEditFieldLabel = uilabel(app.GridLayout_Panel_LSTM);
            app.InputlayerEditFieldLabel.Text = 'Input layer';
            app.EditField_Inputlayer_LSTM_1 = uieditfield(app.GridLayout_Panel_LSTM, 'text');
            app.EditField_Inputlayer_LSTM_1.Editable = 'off';
            app.EditField_Inputlayer_LSTM_1.Value = 'number of features ';
            app.FullyconnectedNNPanel = uipanel(app.GridLayout_Panel_Parameters_DeepLearn);
            app.FullyconnectedNNPanel.Title = 'Fully-connected NN';
            app.GridLayout_Panel_FullyconnctedNN = uigridlayout(app.FullyconnectedNNPanel);
            app.HelpButton_FullyconnectedNN = uibutton(app.GridLayout_Panel_FullyconnctedNN, 'push');
            app.HelpButton_FullyconnectedNN.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_FullyconnectedNNPushed, true);
            app.HelpButton_FullyconnectedNN.Text = 'Help';
            app.MinibatchsizeEditFieldLabel = uilabel(app.GridLayout_Panel_FullyconnctedNN);
            app.MinibatchsizeEditFieldLabel.Text = 'Mini batch size';
            app.EditField_Minibatchsize_FullyconnectedNN = uieditfield(app.GridLayout_Panel_FullyconnctedNN, 'numeric');
            app.EditField_Minibatchsize_FullyconnectedNN.ValueChangedFcn = createCallbackFcn(app, @EditField_Minibatchsize_FullyconnectedNNValueChanged, true);
            app.EditField_Minibatchsize_FullyconnectedNN.Value = 25;
            app.fullyconnectedlayernumberofhiddenunitsEditField_4Label = uilabel(app.GridLayout_Panel_FullyconnctedNN);
            app.fullyconnectedlayernumberofhiddenunitsEditField_4Label.Text = 'fully-connected layer – number of hidden units';
            app.EditField_fullyconnected_FullyconnectedNN_7 = uieditfield(app.GridLayout_Panel_FullyconnctedNN, 'text');
            app.EditField_fullyconnected_FullyconnectedNN_7.Editable = 'off';
            app.EditField_fullyconnected_FullyconnectedNN_7.Value = 'number of responses';
            app.EditFiefullyconnectedlayernumberofhiddenunitsld3EditFieldLabel = uilabel(app.GridLayout_Panel_FullyconnctedNN);
            app.EditFiefullyconnectedlayernumberofhiddenunitsld3EditFieldLabel.Text = 'Edit Fiefully-connected layer - number of hidden unitsld3';
            app.EditField_fullyconnected_FullyconnectedNN_6 = uieditfield(app.GridLayout_Panel_FullyconnctedNN, 'numeric');
            app.EditField_fullyconnected_FullyconnectedNN_6.ValueChangedFcn = createCallbackFcn(app, @EditField_fullyconnected_FullyconnectedNN_6ValueChanged, true);
            app.EditField_fullyconnected_FullyconnectedNN_6.Value = 32;
            app.fullyconnectedlayernumberofhiddenunitsEditField_7Label = uilabel(app.GridLayout_Panel_FullyconnctedNN);
            app.fullyconnectedlayernumberofhiddenunitsEditField_7Label.Text = 'fully-connected layer - number of hidden units';
            app.fullyconnectedlayernumberofhiddenunitsEditField_6Label = uilabel(app.GridLayout_Panel_FullyconnctedNN);
            app.fullyconnectedlayernumberofhiddenunitsEditField_6Label.Text = 'fully-connected layer - number of hidden units';
            app.InputlayerEditField_2Label = uilabel(app.GridLayout_Panel_FullyconnctedNN);
            app.InputlayerEditField_2Label.Text = 'Input layer';
            app.relulayerEditFieldLabel = uilabel(app.GridLayout_Panel_FullyconnctedNN);
            app.relulayerEditFieldLabel.Text = 'relu layer';
            app.relulayerEditField_2Label = uilabel(app.GridLayout_Panel_FullyconnctedNN);
            app.relulayerEditField_2Label.Text = 'relu layer';
            app.EditField_relulayer_FullyconnectedNN_5 = uieditfield(app.GridLayout_Panel_FullyconnctedNN, 'text');
            app.EditField_relulayer_FullyconnectedNN_5.Editable = 'off';
            app.EditField_relulayer_FullyconnectedNN_5.Value = '–';
            app.EditField_fullyconnected_FullyconnectedNN_4 = uieditfield(app.GridLayout_Panel_FullyconnctedNN, 'numeric');
            app.EditField_fullyconnected_FullyconnectedNN_4.ValueChangedFcn = createCallbackFcn(app, @EditField_fullyconnected_FullyconnectedNN_4ValueChanged, true);
            app.EditField_fullyconnected_FullyconnectedNN_4.Value = 64;
            app.EditField_relulayer_FullyconnectedNN_3 = uieditfield(app.GridLayout_Panel_FullyconnctedNN, 'text');
            app.EditField_relulayer_FullyconnectedNN_3.Editable = 'off';
            app.EditField_relulayer_FullyconnectedNN_3.Value = '–';
            app.EditField_fullyconnected_FullyconnectedNN_2 = uieditfield(app.GridLayout_Panel_FullyconnctedNN, 'numeric');
            app.EditField_fullyconnected_FullyconnectedNN_2.ValueChangedFcn = createCallbackFcn(app, @EditField_fullyconnected_FullyconnectedNN_2ValueChanged, true);
            app.EditField_fullyconnected_FullyconnectedNN_2.Value = 128;
            app.EditField_Inputlayer_FullyconnectedNN_1 = uieditfield(app.GridLayout_Panel_FullyconnctedNN, 'text');
            app.EditField_Inputlayer_FullyconnectedNN_1.Editable = 'off';
            app.EditField_Inputlayer_FullyconnectedNN_1.Value = 'number of features ';
            % Show the figure after all components are created
            app.UIFigure_Main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PanOMICs_app_web_Matlab2023b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure_Main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure_Main)
        end
    end
end